classdef DialogAppSetLimits < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SliderlimitsPanel  matlab.ui.container.Panel
        MaxSpinner         matlab.ui.control.Spinner
        MaxSpinnerLabel    matlab.ui.control.Label
        MinSpinner         matlab.ui.control.Spinner
        MinSpinnerLabel    matlab.ui.control.Label
        MaxSlider          matlab.ui.control.Slider
        MaxSliderLabel     matlab.ui.control.Label
        MinSlider          matlab.ui.control.Slider
        MinSliderLabel     matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)

        CallingApp % Main app object

    end

    properties (Access = public)


    end

    methods (Access = private)


        function [] = generateHistogram(app)
            histogram(app.UIAxes,app.CallingApp.current)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;


        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            value = app.MinSlider.Value;
            
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            value = app.MaxSlider.Value;

        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            % adjust the limits of the sliders
            app.MinSlider.Limits = [app.MinSpinner.Value app.MaxSpinner.Value];
            app.MaxSlider.Limits = [app.MinSpinner.Value app.MaxSpinner.Value];
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            % adjust the limits of the sliders
            app.MinSlider.Limits = [app.MinSpinner.Value app.MaxSpinner.Value];
            app.MaxSlider.Limits = [app.MinSpinner.Value app.MaxSpinner.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MinSliderLabel = uilabel(app.UIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.UIFigure);
            app.MinSlider.Limits = [0 4095];
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MaxSliderLabel = uilabel(app.UIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.UIFigure);
            app.MaxSlider.Limits = [0 4095];
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.Value = 4095;
            app.SliderlimitsPanel = uipanel(app.UIFigure);
            app.SliderlimitsPanel.Title = 'Slider limits';
            app.MinSpinnerLabel = uilabel(app.SliderlimitsPanel);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.SliderlimitsPanel);
            app.MinSpinner.Limits = [0 4095];
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MaxSpinnerLabel = uilabel(app.SliderlimitsPanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.SliderlimitsPanel);
            app.MaxSpinner.Limits = [0 4095];
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MaxSpinner.Value = 4095;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppSetLimits(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end