classdef POLCAM_SR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        POLCAMSRMenu                  matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        FiguresettingsMenu            matlab.ui.container.Menu
        ThemeMenu                     matlab.ui.container.Menu
        LightMenu                     matlab.ui.container.Menu
        DarkMenu                      matlab.ui.container.Menu
        FontsizeMenu                  matlab.ui.container.Menu
        FileMenu                      matlab.ui.container.Menu
        OpenstackctrlOMenu            matlab.ui.container.Menu
        OpenstacksequenceMenu         matlab.ui.container.Menu
        OpenStackSequenceMicroManagerMenu  matlab.ui.container.Menu
        OpenStackSequenceThorCamMenu  matlab.ui.container.Menu
        OpensamplesMenu               matlab.ui.container.Menu
        OpenSampleFibrilsMenu         matlab.ui.container.Menu
        LoadlocalisationsMenu         matlab.ui.container.Menu
        ExportlocalisationsMenu       matlab.ui.container.Menu
        ExportLocalisationsTextdocumentstxtMenu  matlab.ui.container.Menu
        ProcessMenu                   matlab.ui.container.Menu
        SinglemoleculeMenu            matlab.ui.container.Menu
        LocalisectrlLMenu             matlab.ui.container.Menu
        FilterctrlFMenu               matlab.ui.container.Menu
        CorrectdriftMenu              matlab.ui.container.Menu
        TemporalgroupingMenu          matlab.ui.container.Menu
        ClusteringMenu                matlab.ui.container.Menu
        dbscanMenu                    matlab.ui.container.Menu
        DiffractionlimitedMenu        matlab.ui.container.Menu
        ConvertstackMenu              matlab.ui.container.Menu
        ConvertstackaverageMenu       matlab.ui.container.Menu
        ProjectstackMenu              matlab.ui.container.Menu
        AverageStackMenu              matlab.ui.container.Menu
        MinimumStackMenu              matlab.ui.container.Menu
        MaximumStackMenu              matlab.ui.container.Menu
        SumStackMenu                  matlab.ui.container.Menu
        ProjectsequenceMenu           matlab.ui.container.Menu
        AverageSequenceMenu           matlab.ui.container.Menu
        MinimumSequenceMenu           matlab.ui.container.Menu
        MaximumSequenceMenu           matlab.ui.container.Menu
        SumSequenceMenu               matlab.ui.container.Menu
        AnalyseMenu                   matlab.ui.container.Menu
        PlotlocalisationsMenu         matlab.ui.container.Menu
        ScatterplotsMenu              matlab.ui.container.Menu
        SummaryhistogramsMenu         matlab.ui.container.Menu
        LocalisationratevstimeMenu    matlab.ui.container.Menu
        PluginsMenu                   matlab.ui.container.Menu
        BatchprocessingMenu           matlab.ui.container.Menu
        BatchProc_SinglemoleculeMenu  matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        WebsiteMenu                   matlab.ui.container.Menu
        DocumentationMenu             matlab.ui.container.Menu
        ReportabugMenu                matlab.ui.container.Menu
        CheckforupdatesMenu           matlab.ui.container.Menu
        StatusLogTextArea             matlab.ui.control.TextArea
        ErrorLogTextArea              matlab.ui.control.TextArea
        LogTextArea                   matlab.ui.control.TextArea
        ContextMenu                   matlab.ui.container.ContextMenu
        ClearlogMenu                  matlab.ui.container.Menu
        DisplaypathtoloadedfilesMenu  matlab.ui.container.Menu
        ClearloadedlocalisationsMenu  matlab.ui.container.Menu
        ClearloadedimagesMenu         matlab.ui.container.Menu
    end


    properties (Access = public)
        
        % dialog applications
        DialogImgDataSM % dialog app to display image data single molecule menu option
        DialogImgDataDiffr % dialog app to display image data diffraction-limited menu option
        DialogLocalisation % dialog app for localisation
        DialogFiltering % dialog app for filtering
        DialogDriftCorrection % dialog app for drift correction
        DialogSettingsDiffr % dialog app for getting settings diffraction limited processing
        
        % dialog applications for figures
        DialogFigScatter % dialog app for plotting and writing scatter plots

        % plugin applications 
        Plugin_batchProc % plugin for batch processing
        
        rootDir % full path to the root folder of the app
        lastVisitedDirectories % struct containing last visited directories
        defaultSettings % struct containing collection of default settings of dialog aps
        figureParams % struct, figure parameters

        numFiles % number of files found for processing
        filelist % list of files to process
        numFrames % number of frames in the first stack
        imgSize % dimensions of the first stack
        outputDir % path to data folder (to write results to)
        
        locData % localisations results
        settingsData % settings stored with localisation results
        folderLocData % folder where localisation results was saved (for writing away filtered version etc)
        filenameLocData % filename without extension of the localisation results currently loaded
        localisationAlgorithm % algorithm that was used for localisation

        settingsDiffr % struct, contains parameters for diffraction limited processing
        settingsDiffrLoaded % bool, to check if settingsDiffr was correctly loaded
    end

    methods (Access = private)
        
        function [] = initialiseFigureSettings(app)
            % INITIALISEFIGURESETTINGS Initialise the settings used by the
            % whole app when rendering figures.
            app.figureParams.FontSize = 9;
            app.figureParams.Theme = 'dark';
        end

    end
    
    methods (Access = public)
        
        function [] = clearLog(app)
            % CLEARLOG Clears all text in the main log window.
            app.LogTextArea.Value = '';
        end

        function [] = writeToLog(app,newMessage)
            % WRITETOLOG Writes new text (newMessage, str) to the main log
            % window.
            newMessage = [app.LogTextArea.Value(:)', newMessage];
            app.LogTextArea.Value = newMessage;
            scroll(app.LogTextArea,'bottom') % automatically scroll down when text fills window
        end

        function [] = writeToErrorLog(app,newMessage)
            % WRITETOERRORLOG Writes new text (newMessage, str) to the error
            % log window.
            app.ErrorLogTextArea.Value = newMessage;
        end

        function [] = writeToStatusLog(app,newMessage)
            % WRITETOSTATUSLOG Writes new text (newMessage, str) to the
            % status log window.
            app.StatusLogTextArea.Value = newMessage;
        end

        function [] = updateStatusLog(app)
            % UPDATESTATUSLOG
            if checkIfImagesAreLoaded(app)
                if checkIfLocalisationsAreLoaded(app)
                    writeToStatusLog(app,'Status: Images and localisations loaded.');
                else
                    writeToStatusLog(app,'Status: Images loaded.');
                end
            else
                if checkIfLocalisationsAreLoaded(app)
                    writeToStatusLog(app,'Status: Localisations loaded.');
                else
                    writeToStatusLog(app,'Status: No images or localisations loaded.')
                end
            end
        end

        function [] = appendToLastLineLog(app,newMessage)
            % APPENDTOLASTLINELOG Append text (newMessage, str) to the text
            % in the main log window.
            app.LogTextArea.Value{end} = strcat(app.LogTextArea.Value{end},newMessage);
            newMessage = app.LogTextArea.Value(:)';
            app.LogTextArea.Value = newMessage;
        end

        function [] = writeHeaderToLog(app)
            % WRITEHEADERTOLOG Write a header text to the main log of.
            clearLog(app);
            %app.LogTextArea.Value = sprintf('+++++  POLCAM-SR  +++++\n\n');
            %writeToLog(app,sprintf('%s',datetime));
            %writeToLog(app,sprintf('\nCite as: Bruggeman et al., Single-molecule orientation localisation microscopy using a polarisation camera. Journal, Vol(Num):pp-pp, 2022.\n'));
            %writeToLog(app,sprintf('\nCopyright 2022, Ezra Bruggeman, Lee Lab, Dept. of Chemistry, University of Cambridge, UK'));
            %writeToLog(app,sprintf('---------------------------------------------------------------'));
        end

        function [] = getImageDimensions(app)
            % GETIMAGEDIMENSIONS Get the image dimensions and number of
            % frames of a stack currently in the property filelist. The
            % dimensions are saved in the properties imgSize and numFrames.
            imgInfo = imfinfo(app.filelist(1).filepath);
            app.imgSize = [imgInfo(1).Height imgInfo(1).Width];
            app.numFrames = length(imgInfo);
        end

        function value = checkIfImagesAreLoaded(app)
            % CHECKIFIMAGESARELOADED Will return 1 if images could be loaded,
            % 0 otherwise.
            try % try if you can load an image
                [~] = imread(app.filelist(1).filepath);
                value = 1;
            catch % if no images are loaded
                value = 0;
            end
        end

        function value = checkIfLocalisationsAreLoaded(app)
            % CHECKIFLOCASLIATIONSARELOADED Will return 1 if locasliations
            % are loaded, 0 otherwise.
            value = ~isempty(app.locData); % 1 if localisations are loaded, 0 otherwise
        end

        function [] = writeLastVisitedDirectories(app)
            % WRITELASTVISITEDDIRECTORIES Updates the configuration file
            % that contains the last visited directories by the user. This
            % directory will be used as a starting point next time that a
            % ui is opened for the user to navigate to a file or folder.
            filepath = fullfile(app.rootDir,'docs','default settings','lastVisitedDirectories.json');
            Utils.writeJsonFile(app.lastVisitedDirectories,filepath);
        end

        function [] = readLastVisitedDirectories(app)
            % READLASTVISITEDDIRECTORIES Reads the content of the
            % configuration file that contains the last visited directories
            % by the user.
            try
                filepath = fullfile(app.rootDir,'docs','default settings','lastVisitedDirectories.json');
                app.lastVisitedDirectories = Utils.readJsonFile(filepath);
            catch
                app.lastVisitedDirectories.pathLastImg = '';
                app.lastVisitedDirectories.pathLastLocs = '';
            end
        end

        function [] = writeDefaultSettings(app)
            % WRITEDEFAULTSETTINGS Reads the content of the configuration
            % file that contains the default settings of the dialog apps
            % (which can be customised by the user).
            filepath = fullfile(app.rootDir,'docs','default settings','defaultSettings.json');
            Utils.writeJsonFile(app.defaultSettings,filepath);
        end

        function [] = readDefaultSettings(app)
            % READDEFAULTSETTINGS Writes the content of the configuration
            % file that contains the default settings of the dialog apps
            % (which can be customised by the user).
            try
                filepath = fullfile(app.rootDir,'docs','default settings','defaultSettings.json');
                app.defaultSettings = Utils.readJsonFile(filepath);
            catch
                newMessage = sprintf('\nCould not find the custom default settings file: docs/default settings/defaultSettings.json. Using standard default settings instead.');
                app.writeToLog(newMessage);
            end
        end

        function [] = guessLocalisationAlgorithm(app)
            % GUESSLOCALISATIONALGORITHM Uses the variablenames in the
            % header of the localisation table locData to determine which
            % localisation algorithm was used.
            if app.checkIfColumnExistsInTable(app.locData,'rot')
                app.localisationAlgorithm = 'rotated asymmetric gaussian';
            elseif app.checkIfColumnExistsInTable(app.locData,'sigmax')
                app.localisationAlgorithm = 'asymmetric gaussian';
            elseif app.checkIfColumnExistsInTable(app.locData,'sigma')
                app.localisationAlgorithm = 'symmetric gaussian';
            else
                app.localisationAlgorithm = 'centroid';
            end
        end

        function [] = openLocalisationWindow(app)
            % OPENLOCALISATIONWINDOW Used by batch processing plugin to
            % trigger callback in main app.
            LocalisectrlLMenuSelected(app,true);
            app.DialogLocalisation.UIFigure.Visible = 'off';
        end

        function [] = openFilteringWindow(app)
            % OPENFILTERINGWINDOW Used by batch processing plugin to
            % trigger callback in main app.
            FilterctrlFMenuSelected(app,true);
            app.DialogFiltering.UIFigure.Visible = 'off';
        end

    end

    methods (Static)

        function value = checkIfColumnExistsInTable(myTable,columnTitle)
            % CHECKIFCOLUMNEXISTINTABLE Check whether one of the column
            % headers of myTable (table) match columnTitle (str).
            value = any(ismember(myTable.Properties.VariableNames,columnTitle));
        end

        function [] = bringWindowToForeground(handle)
            % BRINGWINDOWTOFOREGROUND Use handle of an app window to bring
            % it to the foreground.
            handle.UIFigure.Visible = 'off';
            handle.UIFigure.Visible = 'on';
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            warning('off','all') % turned back on when closing the app

            [app.rootDir,~,~] = fileparts(mfilename('fullpath'));
            addpath(fullfile(app.rootDir,'dialog apps'))
            addpath(fullfile(app.rootDir,'dialog apps','figures'))
            addpath(fullfile(app.rootDir,'lib'))
            addpath(fullfile(app.rootDir,'docs'))
            addpath(fullfile(app.rootDir,'plugins'))

            app.locData = []; % initialise localisations as empty
            app.settingsData = {}; % initialise localisations as struct
            app.folderLocData = 0; % initialise path as 0

            writeHeaderToLog(app);
            initialiseFigureSettings(app);
            
            readLastVisitedDirectories(app);
            writeLastVisitedDirectories(app);

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            web('https://theleelab.github.io/POLCAM-site/software_polcam_sr')
        end

        % Menu selected function: ClearlogMenu
        function ClearlogMenuSelected(app, event)
            writeHeaderToLog(app); % clear log and rewrite header
        end

        % Menu selected function: DisplaypathtoloadedfilesMenu
        function DisplaypathtoloadedfilesMenuSelected(app, event)
            % image file(s)
            writeToLog(app,sprintf('\nImage file(s) loaded:'))
            if checkIfImagesAreLoaded(app)
                for i=1:length(app.filelist)
                    writeToLog(app,sprintf('%s',app.filelist(i).filepath))
                end
            else
                writeToLog(app,sprintf('None'))
            end
            % localisation file
            writeToLog(app,sprintf('\nLocalisation file loaded:'))
            if checkIfLocalisationsAreLoaded(app)
                writeToLog(app,sprintf('%s.mat',fullfile(app.folderLocData,app.filenameLocData)))
            else
                writeToLog(app,sprintf('None'))
            end
            writeToErrorLog(app,sprintf('...'))
        end

        % Menu selected function: LightMenu
        function LightMenuSelected(app, event)
            app.figureParams.Theme = 'light';
            writeToErrorLog(app,sprintf("Figures will be displayed in '%s mode'.",'light'));
        end

        % Menu selected function: DarkMenu
        function DarkMenuSelected(app, event)
            app.figureParams.Theme = 'dark';
            writeToErrorLog(app,sprintf("Figures will be displayed in '%s mode'.",'dark'));
        end

        % Menu selected function: FontsizeMenu
        function FontsizeMenuSelected(app, event)
            prompt = 'Enter fontsize:';
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'12'};
            try
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                answerInt = round(str2double(answer));
                app.figureParams.FontSize = answerInt;
                writeToErrorLog(app,sprintf('Fontsize updated to %d.',answerInt));
            catch
                writeToErrorLog(app,'ERROR: Fontsize needs to be an integer.')
            end
        end

        % Menu selected function: OpenstackctrlOMenu
        function OpenstackMenuSelected(app, event)
            % open a window, asking user to navigate to single stack
            app.readLastVisitedDirectories;
            lastPath = app.lastVisitedDirectories.pathLastImg;
            [filename,filepath] = uigetfile(fullfile(lastPath,'*.tif;*.tiff'),'Select image stack');
            if ~filename % user clicked cancel (uigetfile returned 0)
                return
            end
            % update the lastPath in the default settings folder
            app.lastVisitedDirectories.pathLastImg = char(filepath);
            app.writeLastVisitedDirectories;
            % save path to file and some parameters in appropriate format
            fileList(1).filepath = fullfile(filepath,filename);
            fileList(1).baseName = filename;
            fileList(1).id = 0;
            app.filelist = fileList;
            app.numFiles = 1;
            % write message to the log window
            writeHeaderToLog(app); % clear log and rewrite header
            writeToErrorLog(app,sprintf('Selected single stack.'));
            newMessage = sprintf('\n%d stack(s) were found:',app.numFiles);
            writeToLog(app,newMessage);
            for i=1:app.numFiles; writeToLog(app,sprintf('     %s',app.filelist(i).baseName)); end
            % get image dimensions (needed by DialogAppImgDataSM) and open
            % the dialog app displaying first frame
            app.getImageDimensions;
            app.DialogImgDataSM = DialogAppImgDataSingleMolecule(app);
            % get path to folder to write results to
            [app.outputDir,~,~] = fileparts(app.filelist(1).filepath);
            writeToErrorLog(app,'...');
            updateStatusLog(app);
        end

        % Menu selected function: OpenStackSequenceMicroManagerMenu
        function OpenStackSequenceMicroManagerMenuSelected(app, event)
            % open a window, asking user to navigate to folder
            app.readLastVisitedDirectories;
            lastPath = app.lastVisitedDirectories.pathLastImg;
            directory = uigetdir(lastPath,'Select folder containing image stack sequence');
            if ~directory % user clicked cancel (uigetdir returned 0)
                return
            else
                % update the lastPath in the default settings folder
                app.lastVisitedDirectories.pathLastImg = char(directory);
                app.writeLastVisitedDirectories;
                
                % try generating the ordered list of files in the sequence that
                % need processing
                app.filelist = Utils.getOrderedImageFilelist_MicroManager(directory);
                % depending on whether files were found and linked...
                if ~isstruct(app.filelist) % if files were not found
                    newMessage = sprintf("ERROR: No image files could be found in '%s'.",directory);
                    writeToErrorLog(app,newMessage);
                else % if files were found
                    app.numFiles = length(app.filelist);
                    writeHeaderToLog(app); % clear log and rewrite header
                    writeToErrorLog(app,sprintf('Selected sequence of stacks.'));
                    newMessage = sprintf('\n%d stack(s) were found:',app.numFiles);
                    writeToLog(app,newMessage);
                    for i=1:app.numFiles; writeToLog(app,sprintf('     %s',app.filelist(i).baseName)); end
                    app.getImageDimensions;
                    app.DialogImgDataSM = DialogAppImgDataSingleMolecule(app);
                    % get path to folder to write results to
                    [app.outputDir,~,~] = fileparts(app.filelist(1).filepath);
                    updateStatusLog(app);
                end
            end
        end

        % Menu selected function: OpenStackSequenceThorCamMenu
        function OpenStackSequenceThorCamMenuSelected(app, event)
            % open a window, asking user to navigate to folder
            app.readLastVisitedDirectories;
            lastPath = app.lastVisitedDirectories.pathLastImg;
            directory = uigetdir(lastPath,'Select folder containing image stack sequence');
            if ~directory % user clicked cancel (uigetdir returned 0)
                return
            else
                % update the lastPath in the default settings folder
                app.lastVisitedDirectories.pathLastImg = char(directory);
                app.writeLastVisitedDirectories;
                
                % try generating the ordered list of files in the sequence that
                % need processing
                app.filelist = Utils.getOrderedImageFilelist_ThorCam(directory);
                % depending on whether files were found and linked...
                if ~isstruct(app.filelist) % if files were not found
                    newMessage = sprintf("ERROR: No image files could be found in '%s'.",directory);
                    writeToErrorLog(app,newMessage);
                else % if files were found
                    app.numFiles = length(app.filelist);
                    writeHeaderToLog(app); % clear log and rewrite header
                    writeToErrorLog(app,sprintf('Selected sequence of stacks.'));
                    newMessage = sprintf('\n%d stack(s) were found:',app.numFiles);
                    writeToLog(app,newMessage);
                    for i=1:app.numFiles; writeToLog(app,sprintf('     %s',app.filelist(i).baseName)); end
                    app.getImageDimensions;
                    app.DialogImgDataSM = DialogAppImgDataSingleMolecule(app);
                    % get path to folder to write results to
                    [app.outputDir,~,~] = fileparts(app.filelist(1).filepath);
                    updateStatusLog(app);
                end
            end
        end

        % Menu selected function: OpenSampleFibrilsMenu
        function OpenSampleFibrilsMenuSelected(app, event)
            directory = fullfile(app.rootDir,'docs','samples','fibrils');
            imageBaseName = 'fibrils_NileRed_50ms_';
            app.filelist = Utils.getOrderedImageFilelist_ThorCam(directory);            
            if ~isstruct(app.filelist)
                newMessage = sprintf("ERROR: No image files with basename '%s' could be found in '%s'.",imageBaseName,directory);
                writeToErrorLog(app,newMessage);
            else
                app.numFiles = length(app.filelist);
                newMessage = sprintf("Loaded sample dataset 'fibrils'.");
                writeToErrorLog(app,newMessage);
                newMessage = sprintf('\n%d stack(s) were found:',app.numFiles);
                writeToLog(app,newMessage);
                for i=1:app.numFiles; writeToLog(app,sprintf('     %s',app.filelist(i).baseName)); end
                app.getImageDimensions;
                app.DialogImgDataSM = DialogAppImgDataSingleMolecule(app);
                % get path to folder to write results to
                [app.outputDir,~,~] = fileparts(app.filelist(1).filepath);
                updateStatusLog(app);
            end
        end

        % Menu selected function: LoadlocalisationsMenu
        function LoadlocalisationsMenuSelected(app, event)
            % open a window, asking user to navigate to single stack
            app.readLastVisitedDirectories;
            lastPath = app.lastVisitedDirectories.pathLastLocs;
            [filename,directory] = uigetfile(fullfile(lastPath,'*.mat'),'Select results .mat file');
            if ~filename % user clicked cancel (uigetfile returned 0)
                return
            end
            % update the lastPath in the default settings folder
            app.lastVisitedDirectories.pathLastLocs = directory;
            app.writeLastVisitedDirectories;
            
            [~,filenameWithoutExtension,~] = fileparts(filename);
            try
                data = load(fullfile(directory,filename));
                app.locData = data.results.locData;
                app.settingsData = data.results.settings;
                writeToErrorLog(app,sprintf("Localisations were loaded successfully."));
                app.folderLocData = directory;
                app.filenameLocData = filenameWithoutExtension;
                updateStatusLog(app);
            catch
                writeToErrorLog(app,sprintf("ERROR: Loading localisations failed."));
            end
        end

        % Menu selected function: ExportLocalisationsTextdocumentstxtMenu
        function ExportLocalisationsTextdocumentstxtMenuSelected(app, event)
            if checkIfLocalisationsAreLoaded(app) % if localisations are loaded
                % write away localisations as txt file
                [directory,filename,~] = fileparts(fullfile(app.folderLocData,app.filenameLocData));
                filepath = fullfile(directory,strcat(filename,'_localisations.txt'));
                writetable(app.locData,filepath,'Delimiter',',')
                newMessage = sprintf("\nLocalisation exported to: '%s'\n",filepath);
                writeToLog(app,newMessage); pause(0.0001)
                writeToErrorLog(app,'...');
            else % if no localisations are loaded
                writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
            end
        end

        % Menu selected function: LocalisectrlLMenu
        function LocalisectrlLMenuSelected(app, event)
            if (isempty(app.DialogLocalisation) || ~isvalid(app.DialogLocalisation)) % if no localisation window is open currently
                if checkIfImagesAreLoaded(app) % if images are loaded
                    app.DialogLocalisation = DialogAppLocalisation(app);
                    writeToErrorLog(app,'...');
                else % if no images are loaded
                    writeToErrorLog(app,sprintf("ERROR: No images are loaded."));
                end
            else % if a localisation window is already open, bring it to the foreground instead of opening a new one
                app.bringWindowToForeground(app.DialogLocalisation);
                writeToErrorLog(app,'...');
            end
        end

        % Menu selected function: FilterctrlFMenu
        function FilterctrlFMenuSelected(app, event)
            if (isempty(app.DialogFiltering) || ~isvalid(app.DialogFiltering)) % if no filtering window is open currently
                if checkIfLocalisationsAreLoaded(app) % if localisations are loaded
                    app.DialogFiltering = DialogAppFiltering(app);
                    writeToErrorLog(app,'...');
                else % if no localisations are loaded
                    writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
                end
            else % if a filtering window is already open, bring it to the foreground instead of opening a new one
                app.bringWindowToForeground(app.DialogFiltering);
                writeToErrorLog(app,'...');
            end
        end

        % Menu selected function: CorrectdriftMenu
        function CorrectdriftMenuSelected(app, event)
            if (isempty(app.DialogDriftCorrection) || ~isvalid(app.DialogDriftCorrection)) % if no drift correction window is open currently
                if checkIfLocalisationsAreLoaded(app) % if localisations are loaded
                    app.DialogDriftCorrection = DialogAppDriftCorrection(app);
                    writeToErrorLog(app,'...');
                else % if no localisations are loaded
                    writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
                end
            else % if a drift correction window is already open, bring it to the foreground instead of opening a new one
                app.bringWindowToForeground(app.DialogDriftCorrection);
                writeToErrorLog(app,'...');
            end
        end

        % Menu selected function: TemporalgroupingMenu
        function TemporalgroupingMenuSelected(app, event)
            if checkIfLocalisationsAreLoaded(app) % if localisations are loaded
                if ismember('len',app.locData.Properties.VariableNames)
                    writeToErrorLog(app,'ERROR: Localisation are already temporally grouped.');
                else % perfom temporal grouping
                    % ask for the maximum distance between localisations
                    % that are considered grouped
                    prompt = 'Enter maximum allowed distance between grouped localisations (nm):';
                    dlgtitle = 'Input';
                    dims = [1 40];
                    definput = {'100'};
                    try
                        answer = inputdlg(prompt,dlgtitle,dims,definput);
                        maxDist = str2double(answer);
                        
                        if ismember('cluster_id',app.locData.Properties.VariableNames)
                            app.locData.cluster_id = [];
                            writeToLog(app,sprintf("\nWARNING: The cluster_id column was deleted from the localisation table because the cluster id of multiple localisations can not be grouped. Please perform clustering after temporal grouping."));
                        end

                        newMessage = sprintf('\nTemporal grouping started...'); t1 = toc;
                        writeToLog(app,newMessage); pause(0.0001)
                                            
                        app.locData = Postprocessing.Temporal.temporalGrouping(app.locData,maxDist);
                        
                        t2 = toc;
                        newMessage = sprintf(' done! (%.4f seconds)\n',t2-t1);
                        appendToLastLineLog(app,newMessage); pause(0.0001)
                        
                        Plot.FilteringApp.plotScatterPlot(app.locData,'Frames bound',app.figureParams);
                        Plot.FilteringApp.plot1DHistogram(app.locData,'Frames bound',app.figureParams);
                        
                        writeToErrorLog(app,'...');
                        writeToLog(app,sprintf('All localisations that appeared <%d nm from each other in consecutive frames have been grouped.',maxDist));

                    catch
                       writeToErrorLog(app,'ERROR: Input needs to be a number.')
                    end
                end
            else % if no localisations are loaded
                writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
            end
        end

        % Menu selected function: dbscanMenu
        function dbscanMenuSelected(app, event)
            if checkIfLocalisationsAreLoaded(app) % if localisations are loaded
                % get user input
                prompt = {'Maximum cluster radius (nm)',...
                          'Minimum number of points in cluster'};
                dlgtitle = 'Input';
                dims = [1 36];
                definput = {'100','10'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                maxRadius = str2double(answer{1});
                minPoints = str2double(answer{2});
                
                newMessage = sprintf('\nClustering started..........'); t1 = toc;
                writeToLog(app,newMessage); pause(0.0001)
                
                % perform clustering
                app.locData = Postprocessing.Clustering.cluster_dbscan(app.locData,maxRadius,minPoints);
                
                t2 = toc;
                newMessage = sprintf(' done! (%.4f seconds)\n',t2-t1);
                appendToLastLineLog(app,newMessage); pause(0.0001)
                
                writeToErrorLog(app,'...');
            else % if no localisations are loaded
                writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
            end
        end

        % Menu selected function: SummaryhistogramsMenu
        function SummaryhistogramsMenuSelected(app, event)
            if checkIfLocalisationsAreLoaded(app) % if localisations are loaded
                guessLocalisationAlgorithm(app);
                Plot.AnalyseMenu.plotSummaryHistograms(app.locData,app.localisationAlgorithm,app.figureParams);
                writeToErrorLog(app,'...');
            else % is no localisations are loaded
                writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
            end
        end

        % Menu selected function: LocalisationratevstimeMenu
        function LocalisationratevstimeMenuSelected(app, event)
            if checkIfLocalisationsAreLoaded(app) % if localisations are loaded
                % ask user input
                prompt = 'Number of frames per rolling average window:';
                dlgtitle = 'Input';
                dims = [1 30];
                definput = {'100'};
                try
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    windowAverage= round(str2double(answer));
                catch
                    writeToErrorLog(app,'ERROR: Input needs to be an integer.')
                end
                % generate plot
                Plot.AnalyseMenu.plotLocalisationRateVsTime(app.locData,app.figureParams,windowAverage);
                writeToErrorLog(app,'...');
            else % is no localisations are loaded
                writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
            end
        end

        % Callback function
        function SetparametersMenuSelected(app, event)
            app.DialogSettingsDiffr = DialogAppSettingsDiffractionLimited(app);
            writeToErrorLog(app,'...');
        end

        % Menu selected function: AverageStackMenu
        function AverageStackMenuSelected(app, event)
            % get average intensity projection of stack (or first stack in a sequence)
            projection = Utils.getAverageIntensityProjectionFromPath(app.filelist(1).filepath);
            % display image in new figure window
            figure('Name','Average intensity projection','Position',[100 100 500 500]);
            ax = gca; imagesc(ax,projection); xlabel(ax,'x (pix)'); ylabel(ax,'y (pix)');
            c = colorbar(ax); c.Label.String = 'Counts (ADU)'; c.TickDirection = 'out'; colormap(ax,"gray")
            ax.XLimitMethod = 'tight'; ax.YLimitMethod = 'tight'; ax.DataAspectRatio = [1 1 1];
        end

        % Menu selected function: MinimumStackMenu
        function MinimumStackMenuSelected(app, event)
            % get minimum intensity projection of stack (or first stack in a sequence)
            projection = Utils.getMinimumIntensityProjectionFromPath(app.filelist(1).filepath);
            % display image in new figure window
            figure('Name','Minimum intensity projection','Position',[100 100 500 500]);
            ax = gca; imagesc(ax,projection); xlabel(ax,'x (pix)'); ylabel(ax,'y (pix)');
            c = colorbar(ax); c.Label.String = 'Counts (ADU)'; c.TickDirection = 'out'; colormap(ax,"gray")
            ax.XLimitMethod = 'tight'; ax.YLimitMethod = 'tight'; ax.DataAspectRatio = [1 1 1];
        end

        % Menu selected function: MaximumStackMenu
        function MaximumStackMenuSelected(app, event)
            % get maximum intensity projection of stack (or first stack in a sequence)
            projection = Utils.getMaximumIntensityProjectionFromPath(app.filelist(1).filepath);
            % display image in new figure window
            figure('Name','Maximum intensity projection','Position',[100 100 500 500]);
            ax = gca; imagesc(ax,projection); xlabel(ax,'x (pix)'); ylabel(ax,'y (pix)');
            c = colorbar(ax); c.Label.String = 'Counts (ADU)'; c.TickDirection = 'out'; colormap(ax,"gray")
            ax.XLimitMethod = 'tight'; ax.YLimitMethod = 'tight'; ax.DataAspectRatio = [1 1 1];
        end

        % Menu selected function: SumStackMenu
        function SumStackMenuSelected(app, event)
            % get sum intensity projection of stack (or first stack in a sequence)
            projection = Utils.getSumIntensityProjectionFromPath(app.filelist(1).filepath);
            % display image in new figure window
            figure('Name','Sum intensity projection','Position',[100 100 500 500]);
            ax = gca; imagesc(ax,projection); xlabel(ax,'x (pix)'); ylabel(ax,'y (pix)');
            c = colorbar(ax); c.Label.String = 'Counts (ADU)'; c.TickDirection = 'out'; colormap(ax,"gray")
            ax.XLimitMethod = 'tight'; ax.YLimitMethod = 'tight'; ax.DataAspectRatio = [1 1 1];
        end

        % Menu selected function: AverageSequenceMenu
        function AverageSequenceMenuSelected(app, event)
            % get average intensity projection of all stacks in a sequence
            for i=1:app.numFiles
                projection_i = Utils.getAverageIntensityProjectionFromPath(app.filelist(i).filepath);
                if i == 1
                    projection = nan(size(projection_i,1),size(projection_i,2),app.numFiles);
                end
                projection(:,:,i) = projection_i;
            end
            projection = mean(projection,3);
            % display image in new figure window
            figure('Name','Average intensity projection','Position',[100 100 500 500]);
            ax = gca; imagesc(ax,projection); xlabel(ax,'x (pix)'); ylabel(ax,'y (pix)');
            c = colorbar(ax); c.Label.String = 'Counts (ADU)'; c.TickDirection = 'out'; colormap(ax,"gray")
            ax.XLimitMethod = 'tight'; ax.YLimitMethod = 'tight'; ax.DataAspectRatio = [1 1 1];
        end

        % Menu selected function: MinimumSequenceMenu
        function MinimumSequenceMenuSelected(app, event)
            % get minimum intensity projection of all stacks in a sequence
            for i=1:app.numFiles
                projection_i = Utils.getMinimumIntensityProjectionFromPath(app.filelist(i).filepath);
                if i == 1
                    projection = nan(size(projection_i,1),size(projection_i,2),app.numFiles);
                end
                projection(:,:,i) = projection_i;
            end
            projection = min(projection,[],3);
            % display image in new figure window
            figure('Name','Minimum intensity projection','Position',[100 100 500 500]);
            ax = gca; imagesc(ax,projection); xlabel(ax,'x (pix)'); ylabel(ax,'y (pix)');
            c = colorbar(ax); c.Label.String = 'Counts (ADU)'; c.TickDirection = 'out'; colormap(ax,"gray")
            ax.XLimitMethod = 'tight'; ax.YLimitMethod = 'tight'; ax.DataAspectRatio = [1 1 1];
        end

        % Menu selected function: MaximumSequenceMenu
        function MaximumSequenceMenuSelected(app, event)
            % get maximum intensity projection of all stacks in a sequence
            for i=1:app.numFiles
                projection_i = Utils.getMaximumIntensityProjectionFromPath(app.filelist(i).filepath);
                if i == 1
                    projection = nan(size(projection_i,1),size(projection_i,2),app.numFiles);
                end
                projection(:,:,i) = projection_i;
            end
            projection = max(projection,[],3);
            % display image in new figure window
            figure('Name','Maximum intensity projection','Position',[100 100 500 500]);
            ax = gca; imagesc(ax,projection); xlabel(ax,'x (pix)'); ylabel(ax,'y (pix)');
            c = colorbar(ax); c.Label.String = 'Counts (ADU)'; c.TickDirection = 'out'; colormap(ax,"gray")
            ax.XLimitMethod = 'tight'; ax.YLimitMethod = 'tight'; ax.DataAspectRatio = [1 1 1];
        end

        % Menu selected function: SumSequenceMenu
        function SumSequenceMenuSelected(app, event)
            % get sum intensity projection of all stacks in a sequence
            for i=1:app.numFiles
                projection_i = Utils.getSumIntensityProjectionFromPath(app.filelist(i).filepath);
                if i == 1
                    projection = nan(size(projection_i,1),size(projection_i,2),app.numFiles);
                end
                projection(:,:,i) = projection_i;
            end
            projection = sum(projection,3);
            % display image in new figure window
            figure('Name','Sum intensity projection','Position',[100 100 500 500]);
            ax = gca; imagesc(ax,projection); xlabel(ax,'x (pix)'); ylabel(ax,'y (pix)');
            c = colorbar(ax); c.Label.String = 'Counts (ADU)'; c.TickDirection = 'out'; colormap(ax,"gray")
            ax.XLimitMethod = 'tight'; ax.YLimitMethod = 'tight'; ax.DataAspectRatio = [1 1 1];
        end

        % Menu selected function: ScatterplotsMenu
        function ScatterplotsMenuSelected(app, event)
            if isempty(app.locData) % if no localisations are loaded
                writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
            else % plot summary histograms
                guessLocalisationAlgorithm(app);
                app.DialogFigScatter = DialogAppScatter(app);
                writeToErrorLog(app,'...');
            end
        end

        % Menu selected function: ClearloadedlocalisationsMenu
        function ClearloadedlocalisationsMenuSelected(app, event)
            app.locData = [];
            app.settingsData = {};
            writeToErrorLog(app,sprintf('Loaded localisations were cleared.'))
            updateStatusLog(app);
        end

        % Menu selected function: ClearloadedimagesMenu
        function ClearloadedimagesMenuSelected(app, event)
            app.filelist = [];
            writeToErrorLog(app,sprintf('Loaded images were cleared.'))
            updateStatusLog(app);
        end

        % Menu selected function: ConvertstackMenu
        function ConvertstackMenuSelected(app, event)
            app.DialogImgDataDiffr.StackChannels = DialogAppImgDataDiffractionLimited(app);
        end

        % Menu selected function: WebsiteMenu
        function WebsiteMenuSelected(app, event)
            web('https://theleelab.github.io/POLCAM-site/index.html');
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            web('https://theleelab.github.io/POLCAM-site/software_polcam_sr.html')
        end

        % Menu selected function: ReportabugMenu
        function ReportabugMenuSelected(app, event)
            web('https://github.com/ezrabru/POLCAM-SR/issues')
        end

        % Menu selected function: BatchProc_SinglemoleculeMenu
        function BatchProc_SinglemoleculeMenuSelected(app, event)
            app.Plugin_batchProc = Plugin_batchProcessing(app);
        end

        % Menu selected function: CheckforupdatesMenu
        function CheckforupdatesMenuSelected(app, event)
            web('https://github.com/ezrabru/POLCAM-SR')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % close main app and all dialog apps
%             delete(app.DialogImgDataSM);
%             delete(app.DialogImgDataDiffr);
%             delete(app.DialogSettingsDiffr);
%             
%             delete(app.DialogLocalisation);
%             delete(app.DialogFiltering);
%             delete(app.DialogDriftCorrection);
%             
%             delete(app.DialogFigScatter);
% 
%             delete(app.Plugin_batchProc);

            delete(app);
            warning('on','all')
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            %UIFIGUREWINDOWKEYPRESS
            %event
            try
                if strcmp(event.Modifier,'control')
                    switch event.Key
                        case 'o'
                            % open stack
                            OpenstackMenuSelected(app,true);
                        case 'l'
                            % open localisation window
                            LocalisectrlLMenuSelected(app,true);
                        case 'f'
                            % open filter window
                            FilterctrlFMenuSelected(app,true);
                    end
                %elseif strcmp(event.Modifier,'shift')
                %    % do nothing
                %elseif strcmp(event.Modifier,'command')
                %    % do nothing
                elseif strcmp(event.Modifier{1},'shift') && strcmp(event.Modifier{2},'control')
                    switch event.Key
                        case 'f'
                            % load fibrils sample data
                            OpenSampleFibrilsMenuSelected(app,true);
                    end
                else
                    % do nothing
                end
            catch
                % do nothing
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.POLCAMSRMenu = uimenu(app.UIFigure);
            app.POLCAMSRMenu.Text = 'POLCAM-SR';
            app.AboutMenu = uimenu(app.POLCAMSRMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.SettingsMenu = uimenu(app.POLCAMSRMenu);
            app.SettingsMenu.Text = 'Settings';
            app.FiguresettingsMenu = uimenu(app.SettingsMenu);
            app.FiguresettingsMenu.Text = 'Figure settings...';
            app.ThemeMenu = uimenu(app.FiguresettingsMenu);
            app.ThemeMenu.Text = 'Theme';
            app.LightMenu = uimenu(app.ThemeMenu);
            app.LightMenu.MenuSelectedFcn = createCallbackFcn(app, @LightMenuSelected, true);
            app.LightMenu.Text = 'Light';
            app.DarkMenu = uimenu(app.ThemeMenu);
            app.DarkMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkMenuSelected, true);
            app.DarkMenu.Text = 'Dark';
            app.FontsizeMenu = uimenu(app.FiguresettingsMenu);
            app.FontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontsizeMenuSelected, true);
            app.FontsizeMenu.Text = 'Fontsize...';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenstackctrlOMenu = uimenu(app.FileMenu);
            app.OpenstackctrlOMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenstackMenuSelected, true);
            app.OpenstackctrlOMenu.Text = 'Open stack...         (ctrl + O)';
            app.OpenstacksequenceMenu = uimenu(app.FileMenu);
            app.OpenstacksequenceMenu.Text = 'Open stack sequence...';
            app.OpenStackSequenceMicroManagerMenu = uimenu(app.OpenstacksequenceMenu);
            app.OpenStackSequenceMicroManagerMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenStackSequenceMicroManagerMenuSelected, true);
            app.OpenStackSequenceMicroManagerMenu.Text = 'Micro-Manager';
            app.OpenStackSequenceThorCamMenu = uimenu(app.OpenstacksequenceMenu);
            app.OpenStackSequenceThorCamMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenStackSequenceThorCamMenuSelected, true);
            app.OpenStackSequenceThorCamMenu.Text = 'ThorCam';
            app.OpensamplesMenu = uimenu(app.FileMenu);
            app.OpensamplesMenu.Text = 'Open samples...';
            app.OpenSampleFibrilsMenu = uimenu(app.OpensamplesMenu);
            app.OpenSampleFibrilsMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSampleFibrilsMenuSelected, true);
            app.OpenSampleFibrilsMenu.Text = 'Fibrils    (ctrl + shift + F)';
            app.LoadlocalisationsMenu = uimenu(app.FileMenu);
            app.LoadlocalisationsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadlocalisationsMenuSelected, true);
            app.LoadlocalisationsMenu.Separator = 'on';
            app.LoadlocalisationsMenu.Text = 'Load localisations...';
            app.ExportlocalisationsMenu = uimenu(app.FileMenu);
            app.ExportlocalisationsMenu.Text = 'Export localisations...';
            app.ExportLocalisationsTextdocumentstxtMenu = uimenu(app.ExportlocalisationsMenu);
            app.ExportLocalisationsTextdocumentstxtMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportLocalisationsTextdocumentstxtMenuSelected, true);
            app.ExportLocalisationsTextdocumentstxtMenu.Text = 'Text documents (*.txt)';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.SinglemoleculeMenu = uimenu(app.ProcessMenu);
            app.SinglemoleculeMenu.Text = 'Single-molecule...';
            app.LocalisectrlLMenu = uimenu(app.SinglemoleculeMenu);
            app.LocalisectrlLMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalisectrlLMenuSelected, true);
            app.LocalisectrlLMenu.Text = 'Localise             (ctrl + L)';
            app.FilterctrlFMenu = uimenu(app.SinglemoleculeMenu);
            app.FilterctrlFMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterctrlFMenuSelected, true);
            app.FilterctrlFMenu.Text = 'Filter                  (ctrl + F)';
            app.CorrectdriftMenu = uimenu(app.SinglemoleculeMenu);
            app.CorrectdriftMenu.MenuSelectedFcn = createCallbackFcn(app, @CorrectdriftMenuSelected, true);
            app.CorrectdriftMenu.Text = 'Correct drift';
            app.TemporalgroupingMenu = uimenu(app.SinglemoleculeMenu);
            app.TemporalgroupingMenu.MenuSelectedFcn = createCallbackFcn(app, @TemporalgroupingMenuSelected, true);
            app.TemporalgroupingMenu.Text = 'Temporal grouping';
            app.ClusteringMenu = uimenu(app.SinglemoleculeMenu);
            app.ClusteringMenu.Separator = 'on';
            app.ClusteringMenu.Text = 'Clustering';
            app.dbscanMenu = uimenu(app.ClusteringMenu);
            app.dbscanMenu.MenuSelectedFcn = createCallbackFcn(app, @dbscanMenuSelected, true);
            app.dbscanMenu.Text = 'dbscan';
            app.DiffractionlimitedMenu = uimenu(app.ProcessMenu);
            app.DiffractionlimitedMenu.Text = 'Diffraction-limited...';
            app.ConvertstackMenu = uimenu(app.DiffractionlimitedMenu);
            app.ConvertstackMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertstackMenuSelected, true);
            app.ConvertstackMenu.Text = 'Convert stack...';
            app.ConvertstackaverageMenu = uimenu(app.DiffractionlimitedMenu);
            app.ConvertstackaverageMenu.Text = 'Convert stack average';
            app.ProjectstackMenu = uimenu(app.DiffractionlimitedMenu);
            app.ProjectstackMenu.Separator = 'on';
            app.ProjectstackMenu.Text = 'Project stack...';
            app.AverageStackMenu = uimenu(app.ProjectstackMenu);
            app.AverageStackMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageStackMenuSelected, true);
            app.AverageStackMenu.Text = 'Average';
            app.MinimumStackMenu = uimenu(app.ProjectstackMenu);
            app.MinimumStackMenu.MenuSelectedFcn = createCallbackFcn(app, @MinimumStackMenuSelected, true);
            app.MinimumStackMenu.Text = 'Minimum';
            app.MaximumStackMenu = uimenu(app.ProjectstackMenu);
            app.MaximumStackMenu.MenuSelectedFcn = createCallbackFcn(app, @MaximumStackMenuSelected, true);
            app.MaximumStackMenu.Text = 'Maximum';
            app.SumStackMenu = uimenu(app.ProjectstackMenu);
            app.SumStackMenu.MenuSelectedFcn = createCallbackFcn(app, @SumStackMenuSelected, true);
            app.SumStackMenu.Text = 'Sum';
            app.ProjectsequenceMenu = uimenu(app.DiffractionlimitedMenu);
            app.ProjectsequenceMenu.Text = 'Project sequence...';
            app.AverageSequenceMenu = uimenu(app.ProjectsequenceMenu);
            app.AverageSequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageSequenceMenuSelected, true);
            app.AverageSequenceMenu.Text = 'Average';
            app.MinimumSequenceMenu = uimenu(app.ProjectsequenceMenu);
            app.MinimumSequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @MinimumSequenceMenuSelected, true);
            app.MinimumSequenceMenu.Text = 'Minimum';
            app.MaximumSequenceMenu = uimenu(app.ProjectsequenceMenu);
            app.MaximumSequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @MaximumSequenceMenuSelected, true);
            app.MaximumSequenceMenu.Text = 'Maximum';
            app.SumSequenceMenu = uimenu(app.ProjectsequenceMenu);
            app.SumSequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @SumSequenceMenuSelected, true);
            app.SumSequenceMenu.Text = 'Sum';
            app.AnalyseMenu = uimenu(app.UIFigure);
            app.AnalyseMenu.Text = 'Analyse';
            app.PlotlocalisationsMenu = uimenu(app.AnalyseMenu);
            app.PlotlocalisationsMenu.Text = 'Plot localisations...';
            app.ScatterplotsMenu = uimenu(app.PlotlocalisationsMenu);
            app.ScatterplotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ScatterplotsMenuSelected, true);
            app.ScatterplotsMenu.Text = 'Scatter plots';
            app.SummaryhistogramsMenu = uimenu(app.PlotlocalisationsMenu);
            app.SummaryhistogramsMenu.MenuSelectedFcn = createCallbackFcn(app, @SummaryhistogramsMenuSelected, true);
            app.SummaryhistogramsMenu.Text = 'Summary histograms';
            app.LocalisationratevstimeMenu = uimenu(app.PlotlocalisationsMenu);
            app.LocalisationratevstimeMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalisationratevstimeMenuSelected, true);
            app.LocalisationratevstimeMenu.Text = 'Localisation rate vs time';
            app.PluginsMenu = uimenu(app.UIFigure);
            app.PluginsMenu.Text = 'Plugins';
            app.BatchprocessingMenu = uimenu(app.PluginsMenu);
            app.BatchprocessingMenu.Text = 'Batch processing';
            app.BatchProc_SinglemoleculeMenu = uimenu(app.BatchprocessingMenu);
            app.BatchProc_SinglemoleculeMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchProc_SinglemoleculeMenuSelected, true);
            app.BatchProc_SinglemoleculeMenu.Text = 'Single-molecule...';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.WebsiteMenu = uimenu(app.HelpMenu);
            app.WebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @WebsiteMenuSelected, true);
            app.WebsiteMenu.Text = 'Website...';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.ReportabugMenu = uimenu(app.HelpMenu);
            app.ReportabugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportabugMenuSelected, true);
            app.ReportabugMenu.Text = 'Report a bug';
            app.CheckforupdatesMenu = uimenu(app.HelpMenu);
            app.CheckforupdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforupdatesMenuSelected, true);
            app.CheckforupdatesMenu.Text = 'Check for updates...';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Editable = 'off';
            app.ErrorLogTextArea = uitextarea(app.UIFigure);
            app.ErrorLogTextArea.Editable = 'off';
            app.ErrorLogTextArea.Value = {'Hello!'};
            app.StatusLogTextArea = uitextarea(app.UIFigure);
            app.StatusLogTextArea.Editable = 'off';
            app.StatusLogTextArea.Value = {'Status: No images or localisations loaded.'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ClearlogMenu = uimenu(app.ContextMenu);
            app.ClearlogMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearlogMenuSelected, true);
            app.ClearlogMenu.Text = 'Clear log';
            app.DisplaypathtoloadedfilesMenu = uimenu(app.ContextMenu);
            app.DisplaypathtoloadedfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaypathtoloadedfilesMenuSelected, true);
            app.DisplaypathtoloadedfilesMenu.Separator = 'on';
            app.DisplaypathtoloadedfilesMenu.Text = 'Display path to loaded files';
            app.ClearloadedlocalisationsMenu = uimenu(app.ContextMenu);
            app.ClearloadedlocalisationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearloadedlocalisationsMenuSelected, true);
            app.ClearloadedlocalisationsMenu.Text = 'Clear loaded localisations';
            app.ClearloadedimagesMenu = uimenu(app.ContextMenu);
            app.ClearloadedimagesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearloadedimagesMenuSelected, true);
            app.ClearloadedimagesMenu.Text = 'Clear loaded images';
            % Assign app.ContextMenu
            app.LogTextArea.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = POLCAM_SR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end