classdef DialogAppDriftCorrection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplydriftcorrectionButton      matlab.ui.control.Button
        SavedriftcorrectedlocalisationsButton  matlab.ui.control.Button
        vLabel_2                        matlab.ui.control.Label
        vLabel                          matlab.ui.control.Label
        UndodriftcorrectionButton       matlab.ui.control.Button
        CalculatedriftButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RCCTab                          matlab.ui.container.Tab
        RCCSpatialbinsizeEditField      matlab.ui.control.NumericEditField
        SpatialbinsizenmEditFieldLabel  matlab.ui.control.Label
        RCCFramespersegmentEditField    matlab.ui.control.NumericEditField
        FramespersegmentEditFieldLabel  matlab.ui.control.Label
        MMCTab                          matlab.ui.container.Tab
        MMCSampleEveryNFramesEditField  matlab.ui.control.NumericEditField
        Samplingrateframe1Label         matlab.ui.control.Label
        MMCFramesmovingmeanEditField    matlab.ui.control.NumericEditField
        FramesmovingmeanEditFieldLabel  matlab.ui.control.Label
        SwitchBeforeAfterCorrection     matlab.ui.control.Switch
        UIAxesTotalDriftTrace           matlab.ui.control.UIAxes
        UIAxesDriftTraces               matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        SaveasMenu                      matlab.ui.container.Menu
        pngMenu                         matlab.ui.container.Menu
        epsMenu_2                       matlab.ui.container.Menu
        figMenu                         matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        SaveasMenu_2                    matlab.ui.container.Menu
        pngMenu_2                       matlab.ui.container.Menu
        epsMenu                         matlab.ui.container.Menu
        figMenu_2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp % Main app object
        
        methodDriftCorrection % str, method drift correction used
        paramsDriftCorrection % struct, collection of method-specific parameters

        includeDriftCorrection % bool, show results before or after drift correction in window

        calculatedDriftX % array, calculated drift in x direction
        calculatedDriftY % array, calculated drift in y direction

    end
    
    methods (Access = private)
        
        function [] = updateDriftScatterPlot(app)
            cla(app.UIAxes) % clear plot
            keep = logical(app.CallingApp.locData.filter);
            frame = app.CallingApp.locData.frameGrouped(keep);
            if app.includeDriftCorrection
                x = app.CallingApp.locData.x_driftCorrected(keep);
                y = app.CallingApp.locData.y_driftCorrected(keep);
                title(app.UIAxes,'After drift correction','Fontweight','normal')
            else
                x = app.CallingApp.locData.x(keep);
                y = app.CallingApp.locData.y(keep);
                title(app.UIAxes,'Before drift correction','Fontweight','normal')
            end
            scatter(app.UIAxes,y,x,20,frame,'.')
            c = colorbar(app.UIAxes);
            c.Label.String = 'Frame';
            c.TickDirection = 'out';
            xlabel(app.UIAxes,'x (nm)')
            ylabel(app.UIAxes,'y (nm)')

            grid(app.UIAxes,'on')
            set(app.UIAxes,'Color','k','GridColor','w','YDir','reverse')

            % doing the axis limit formatting in the callback function
            % SwitchBeforeAfterCorrectionValueChanged instead to keep the
            % axis limits fixed when toggling between the before and after
            % drift correction results...
        end

        function [] = enableBeforeAfterCorrectionSwitch(app)
            set(app.SwitchBeforeAfterCorrection,'Enable','on')
        end

        function [] = dissableBeforeAfterCorrectionSwitch(app)
            set(app.SwitchBeforeAfterCorrection,'Enable','off')
        end

        function [] = enableCorrectdriftButton(app)
            set(app.CalculatedriftButton,'Enable','on')
        end

        function [] = dissableCorrectdriftButton(app)
            set(app.CalculatedriftButton,'Enable','off')
        end

        function [] = enableUndodriftcorrectionButton(app)
            set(app.UndodriftcorrectionButton,'Enable','on')
        end

        function [] = dissableUndodriftcorrectionButton(app)
            set(app.UndodriftcorrectionButton,'Enable','off')
        end

        function [] = enableApplydriftcorrectionButton(app)
            set(app.ApplydriftcorrectionButton,'Enable','on')
        end

        function [] = dissableApplydriftcorrectionButton(app)
            set(app.ApplydriftcorrectionButton,'Enable','off')
        end

        function [] = enableSavedriftcorrectionButton(app)
            set(app.SavedriftcorrectedlocalisationsButton,'Enable','on')
        end

        function [] = dissableSavedriftcorrectionButton(app)
            set(app.SavedriftcorrectedlocalisationsButton,'Enable','off')
        end

        function [] = dissableWhileRunning(app)
            dissableBeforeAfterCorrectionSwitch(app);
            dissableCorrectdriftButton(app);
            dissableUndodriftcorrectionButton(app);
        end

        function [] = enableAfterFinishedRunning(app)
            enableBeforeAfterCorrectionSwitch(app);
            enableCorrectdriftButton(app);
            enableUndodriftcorrectionButton(app);
        end
        
        function [] = updateXYDriftTrace(app)
            lw = 1.5; % linewidth
            cla(app.UIAxesDriftTraces)
            plot(app.UIAxesDriftTraces,app.calculatedDriftX,'-r','LineWidth',lw)
            hold(app.UIAxesDriftTraces,'on')
            plot(app.UIAxesDriftTraces,app.calculatedDriftY,'-b','LineWidth',lw)
            legend(app.UIAxesDriftTraces,{'x','y'},'Location','southwest')
            title(app.UIAxesDriftTraces,'Drift traces','Fontweight','normal')
            xlabel(app.UIAxesDriftTraces,'Frame')
            ylabel(app.UIAxesDriftTraces,'Drift (nm)')
            grid(app.UIAxesDriftTraces,'on')
            set(app.UIAxesTotalDriftTrace,'YDir','reverse')
            xyAbsLim = 1.5*max(abs([max(app.calculatedDriftX),...
                                    min(app.calculatedDriftX),...
                                    max(app.calculatedDriftY),...
                                    min(app.calculatedDriftY)]));
            ylim(app.UIAxesDriftTraces,[-xyAbsLim xyAbsLim])
        end

        function [] = updateTotalDriftTrace(app)
            lw = 1.5; % linewidth
            cla(app.UIAxesTotalDriftTrace)
            plot(app.UIAxesTotalDriftTrace,app.calculatedDriftX,app.calculatedDriftY,'Color',0.5*[1,1,1],'LineWidth',lw);
            hold(app.UIAxesTotalDriftTrace,'on')
            scatter(app.UIAxesTotalDriftTrace,app.calculatedDriftX,app.calculatedDriftY,30,[1:numel(app.UIAxes,app.calculatedDriftX)]','filled')
            xlabel(app.UIAxesTotalDriftTrace,'x (nm)')
            ylabel(app.UIAxesTotalDriftTrace,'y (nm)')
            title(app.UIAxesTotalDriftTrace,'Drift trajectory','Fontweight','normal')
            grid(app.UIAxesTotalDriftTrace,'on')
            set(app.UIAxesTotalDriftTrace,'YDir','reverse')
            set(app.UIAxesTotalDriftTrace,'DataAspectRatio',[1 1 1],'PlotBoxAspectRatio',[1 1 1])
            xyAbsLim = 1.5*max(abs([max(app.calculatedDriftX),...
                                    min(app.calculatedDriftX),...
                                    max(app.calculatedDriftY),...
                                    min(app.calculatedDriftY)]));
            xlim(app.UIAxesTotalDriftTrace,[-xyAbsLim xyAbsLim])
            ylim(app.UIAxesTotalDriftTrace,[-xyAbsLim xyAbsLim])
            c = colorbar(app.UIAxesTotalDriftTrace);
            c.Label.String = 'Frame';
            c.TickDirection = 'out';
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            dissableBeforeAfterCorrectionSwitch(app);
            dissableUndodriftcorrectionButton(app);
            dissableApplydriftcorrectionButton(app);
            dissableSavedriftcorrectionButton(app);
            
            app.TabGroup.SelectedTab = app.RCCTab; % show content MMC tab
            app.methodDriftCorrection = 'rcc';
                        
            app.paramsDriftCorrection.numFramesSlidingWindow = app.MMCFramesmovingmeanEditField.Value;
            app.paramsDriftCorrection.frameInterval          = app.MMCSampleEveryNFramesEditField.Value;

            app.paramsDriftCorrection.framespersegment       = app.RCCFramespersegmentEditField.Value;
            app.paramsDriftCorrection.spatialbinsize         = app.RCCSpatialbinsizeEditField.Value;

            app.includeDriftCorrection = 0;
            updateDriftScatterPlot(app);
            set(app.UIAxes,'DataAspectRatio',[1 1 1],'XLimitMethod','tight','YLimitMethod','tight')

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'MMC'
                    app.methodDriftCorrection = 'mmc';
                case 'RCC'
                    app.methodDriftCorrection = 'rcc';
                % case 'Fiducial'
                %     app.methodDriftCorrection = 'fiducial';
                % case 'DME'
                %     app.methodDriftCorrection = 'dme';
            end
        end

        % Value changed function: SwitchBeforeAfterCorrection
        function SwitchBeforeAfterCorrectionValueChanged(app, event)
            switch app.SwitchBeforeAfterCorrection.Value
                case 'Before'
                    app.includeDriftCorrection = 0;
                    updateDriftScatterPlot(app);
                    set(app.UIAxes,'DataAspectRatio',[1 1 1],'XLimitMethod','tight','YLimitMethod','tight')
                case 'After'
                    app.includeDriftCorrection = 1;
                    % fetch axis limits
                    xl = xlim(app.UIAxes);
                    yl = ylim(app.UIAxes);
                    updateDriftScatterPlot(app);
                    set(app.UIAxes,'DataAspectRatio',[1 1 1])
                    % set the same axis limits again
                    xlim(app.UIAxes,xl)
                    ylim(app.UIAxes,yl)
            end
        end

        % Value changed function: RCCFramespersegmentEditField
        function RCCFramespersegmentEditFieldValueChanged(app, event)
            app.paramsDriftCorrection.framespersegment = app.RCCFramespersegmentEditField.Value;
        end

        % Value changed function: RCCSpatialbinsizeEditField
        function RCCSpatialbinsizeEditFieldValueChanged(app, event)
            app.paramsDriftCorrection.spatialbinsize = app.RCCSpatialbinsizeEditField.Value;
        end

        % Value changed function: MMCFramesmovingmeanEditField
        function MMCFramesmovingmeanEditFieldValueChanged(app, event)
            app.paramsDriftCorrection.numFramesSlidingWindow = app.MMCFramesmovingmeanEditField.Value;
        end

        % Value changed function: MMCSampleEveryNFramesEditField
        function MMCSampleEveryNFramesEditFieldValueChanged(app, event)
            app.paramsDriftCorrection.frameInterval = app.MMCSampleEveryNFramesEditField.Value;
        end

        % Button pushed function: CalculatedriftButton
        function CalculatedriftButtonPushed(app, event)
            
            dissableWhileRunning(app);
            
            tic
            t1 = toc;
            writeToLog(app.CallingApp,sprintf('\nCalculating drift............')); pause(0.0001)

            filter = logical(app.CallingApp.locData.filter);
            frame  = app.CallingApp.locData.frameGrouped;
            x      = app.CallingApp.locData.x;
            y      = app.CallingApp.locData.y;
            
            %%%%%%%% PRINTING %%%%%%%
            head(app.CallingApp.locData)

            % add a copy of the original uncorrected (x,y) coordinates in
            % case user at some point wants to undo drift correction and
            % restore the original localisations
            app.CallingApp.locData.x_beforeDriftCorrection = app.CallingApp.locData.x;
            app.CallingApp.locData.y_beforeDriftCorrection = app.CallingApp.locData.y;

            switch app.methodDriftCorrection
                case 'mmc'
                    [x_corr,y_corr,dx,dy] = Drift.correctDriftMMC(frame,x,y,filter,...
                                                app.paramsDriftCorrection.numFramesSlidingWindow,...
                                                app.paramsDriftCorrection.frameInterval);
                case 'rcc'
                    [x_corr,y_corr,dx,dy,~,~] = Drift.correctDriftRCC(frame,x,y,filter,...
                                                app.paramsDriftCorrection.framespersegment,...
                                                app.paramsDriftCorrection.spatialbinsize);
            end
            app.calculatedDriftX = dx;
            app.calculatedDriftY = dy;
            app.CallingApp.locData.x_driftCorrected = x_corr;
            app.CallingApp.locData.y_driftCorrected = y_corr;

            xl = xlim(app.UIAxes); % fetch axis limits to restore later
            yl = ylim(app.UIAxes); % fetch axis limits to restore later
            app.SwitchBeforeAfterCorrection.Value = 'After';
            app.includeDriftCorrection = 1;
            enableBeforeAfterCorrectionSwitch(app);

            enableUndodriftcorrectionButton(app);
            enableApplydriftcorrectionButton(app);

            updateXYDriftTrace(app);
            updateTotalDriftTrace(app);
            updateDriftScatterPlot(app);
            xlim(app.UIAxes,xl) % restore axis limits
            ylim(app.UIAxes,yl) % restore axis limits

            t2 = toc;
            appendToLastLineLog(app.CallingApp,sprintf(' done! (%.4f seconds)',t2-t1))
            
            enableAfterFinishedRunning(app);

                        %%%%%%%% PRINTING %%%%%%%
            head(app.CallingApp.locData)
        end

        % Button pushed function: UndodriftcorrectionButton
        function UndodriftcorrectionButtonPushed(app, event)
            app.CallingApp.locData.x = app.CallingApp.locData.x_beforeDriftCorrection;
            app.CallingApp.locData.y = app.CallingApp.locData.y_beforeDriftCorrection;
            app.CallingApp.locData.x_beforeDriftCorrection = [];
            app.CallingApp.locData.y_beforeDriftCorrection = [];
            if app.CallingApp.checkIfColumnExistsInTable(app.CallingApp.locData,'x_driftCorrected')
                app.CallingApp.locData.x_driftCorrected = [];
            end
            if app.CallingApp.checkIfColumnExistsInTable(app.CallingApp.locData,'y_driftCorrected')
                app.CallingApp.locData.y_driftCorrected = [];
            end
            app.calculatedDriftX = 0;
            app.calculatedDriftY = 0;
            cla(app.UIAxesDriftTraces) % clear drift trace plot
            cla(app.UIAxesTotalDriftTrace) % clear drift trajectory plot
            
            % set the switch to Before and dissable it
            app.SwitchBeforeAfterCorrection.Value = 'Before';
            app.includeDriftCorrection = 0;
            dissableBeforeAfterCorrectionSwitch(app);
            updateDriftScatterPlot(app);

            dissableUndodriftcorrectionButton(app);
            dissableApplydriftcorrectionButton(app);
            dissableSavedriftcorrectionButton(app);

            newMessage = sprintf('\nAll drift correction that was applied (but not saved) has been undone.');
            writeToLog(app.CallingApp,newMessage);
        end

        % Button pushed function: ApplydriftcorrectionButton
        function ApplydriftcorrectionButtonPushed(app, event)
            % replace the x and y columns by the drift corrected versions
            app.CallingApp.locData.x = app.CallingApp.locData.x_driftCorrected;
            app.CallingApp.locData.y = app.CallingApp.locData.y_driftCorrected;
            % remove the drift correction columns
            app.CallingApp.locData.x_driftCorrected = [];
            app.CallingApp.locData.y_driftCorrected = [];
            writeToLog(app.CallingApp,sprintf("\nDrift correction was applied. Loaded localisations have been updated in the software. Press 'Save drift-corrected localisations' to write away an updated localisation file, or apply a new round of drift-correction using the updated localisations as the new starting point."));
            % switch the before/after button back to 'Before', dissable it
            % and make sure the updated results are showing
            app.SwitchBeforeAfterCorrection.Value = 'Before';
            dissableBeforeAfterCorrectionSwitch(app);
            app.includeDriftCorrection = 0;
            updateDriftScatterPlot(app);

            enableSavedriftcorrectionButton(app);
        end

        % Button pushed function: SavedriftcorrectedlocalisationsButton
        function SavedriftcorrectedlocalisationsButtonPushed(app, event)
            if app.CallingApp.folderLocData
                results.locData = app.CallingApp.locData;
                newFilename = strcat(app.CallingApp.filenameLocData,'_driftcorrected');
                app.CallingApp.filenameLocData = newFilename;
                filepathResults = fullfile(app.CallingApp.folderLocData,strcat(newFilename,'.mat'));
                save(filepathResults,'results');
                writeToLog(app.CallingApp,sprintf("\nDrift-corrected localisations were save in:\n'%s'",filepathResults));
                % switch the before/after button back to 'Before', dissable it
                % and make sure the updated results are showing
                app.SwitchBeforeAfterCorrection.Value = 'Before';
                dissableBeforeAfterCorrectionSwitch(app);
                app.includeDriftCorrection = 0;
                updateDriftScatterPlot(app);
                
                dissableApplydriftcorrectionButton(app);
                dissableSavedriftcorrectionButton(app);
            else
                writeToLog(app.CallingApp,sprintf("\nFailed to save drift-corrected localisations."));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesDriftTraces = uiaxes(app.UIFigure);
            xlabel(app.UIAxesDriftTraces, 'X')
            ylabel(app.UIAxesDriftTraces, 'Y')
            zlabel(app.UIAxesDriftTraces, 'Z')
            app.UIAxesTotalDriftTrace = uiaxes(app.UIFigure);
            xlabel(app.UIAxesTotalDriftTrace, 'X')
            ylabel(app.UIAxesTotalDriftTrace, 'Y')
            zlabel(app.UIAxesTotalDriftTrace, 'Z')
            app.SwitchBeforeAfterCorrection = uiswitch(app.UIFigure, 'slider');
            app.SwitchBeforeAfterCorrection.Items = {'Before', 'After'};
            app.SwitchBeforeAfterCorrection.ValueChangedFcn = createCallbackFcn(app, @SwitchBeforeAfterCorrectionValueChanged, true);
            app.SwitchBeforeAfterCorrection.Value = 'Before';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RCCTab = uitab(app.TabGroup);
            app.RCCTab.Title = 'RCC';
            app.FramespersegmentEditFieldLabel = uilabel(app.RCCTab);
            app.FramespersegmentEditFieldLabel.Text = 'Frames per segment:';
            app.RCCFramespersegmentEditField = uieditfield(app.RCCTab, 'numeric');
            app.RCCFramespersegmentEditField.ValueChangedFcn = createCallbackFcn(app, @RCCFramespersegmentEditFieldValueChanged, true);
            app.RCCFramespersegmentEditField.Value = 1000;
            app.SpatialbinsizenmEditFieldLabel = uilabel(app.RCCTab);
            app.SpatialbinsizenmEditFieldLabel.Text = 'Spatial binsize (nm):';
            app.RCCSpatialbinsizeEditField = uieditfield(app.RCCTab, 'numeric');
            app.RCCSpatialbinsizeEditField.ValueChangedFcn = createCallbackFcn(app, @RCCSpatialbinsizeEditFieldValueChanged, true);
            app.RCCSpatialbinsizeEditField.Value = 20;
            app.MMCTab = uitab(app.TabGroup);
            app.MMCTab.Title = 'MMC';
            app.FramesmovingmeanEditFieldLabel = uilabel(app.MMCTab);
            app.FramesmovingmeanEditFieldLabel.Text = 'Frames moving mean:';
            app.MMCFramesmovingmeanEditField = uieditfield(app.MMCTab, 'numeric');
            app.MMCFramesmovingmeanEditField.ValueChangedFcn = createCallbackFcn(app, @MMCFramesmovingmeanEditFieldValueChanged, true);
            app.MMCFramesmovingmeanEditField.Value = 1000;
            app.Samplingrateframe1Label = uilabel(app.MMCTab);
            app.Samplingrateframe1Label.Text = 'Sample every ... frames:';
            app.MMCSampleEveryNFramesEditField = uieditfield(app.MMCTab, 'numeric');
            app.MMCSampleEveryNFramesEditField.ValueChangedFcn = createCallbackFcn(app, @MMCSampleEveryNFramesEditFieldValueChanged, true);
            app.MMCSampleEveryNFramesEditField.Value = 50;
            app.CalculatedriftButton = uibutton(app.UIFigure, 'push');
            app.CalculatedriftButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatedriftButtonPushed, true);
            app.CalculatedriftButton.Text = 'Calculate drift';
            app.UndodriftcorrectionButton = uibutton(app.UIFigure, 'push');
            app.UndodriftcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @UndodriftcorrectionButtonPushed, true);
            app.UndodriftcorrectionButton.Text = 'Undo drift correction';
            app.vLabel = uilabel(app.UIFigure);
            app.vLabel.Text = 'v';
            app.vLabel_2 = uilabel(app.UIFigure);
            app.vLabel_2.Text = 'v';
            app.SavedriftcorrectedlocalisationsButton = uibutton(app.UIFigure, 'push');
            app.SavedriftcorrectedlocalisationsButton.ButtonPushedFcn = createCallbackFcn(app, @SavedriftcorrectedlocalisationsButtonPushed, true);
            app.SavedriftcorrectedlocalisationsButton.Text = {'Save drift-'; 'corrected'; 'localisations'};
            app.ApplydriftcorrectionButton = uibutton(app.UIFigure, 'push');
            app.ApplydriftcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplydriftcorrectionButtonPushed, true);
            app.ApplydriftcorrectionButton.Text = {'Apply drift'; 'correction'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SaveasMenu = uimenu(app.ContextMenu);
            app.SaveasMenu.Text = 'Save as...';
            app.pngMenu = uimenu(app.SaveasMenu);
            app.pngMenu.Text = '.png';
            app.epsMenu_2 = uimenu(app.SaveasMenu);
            app.epsMenu_2.Text = '.eps';
            app.figMenu = uimenu(app.SaveasMenu);
            app.figMenu.Text = '.fig';
            % Assign app.ContextMenu
            app.UIAxesDriftTraces.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.SaveasMenu_2 = uimenu(app.ContextMenu2);
            app.SaveasMenu_2.Text = 'Save as...';
            app.pngMenu_2 = uimenu(app.SaveasMenu_2);
            app.pngMenu_2.Text = '.png';
            app.epsMenu = uimenu(app.SaveasMenu_2);
            app.epsMenu.Text = '.eps';
            app.figMenu_2 = uimenu(app.SaveasMenu_2);
            app.figMenu_2.Text = '.fig';
            % Assign app.ContextMenu2
            app.UIAxesTotalDriftTrace.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppDriftCorrection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end