classdef DialogAppImgDataDiffractionLimited < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel_2                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Panel_3                     matlab.ui.container.Panel
        FormatLabel                 matlab.ui.control.Label
        ImagetypeDropDown           matlab.ui.control.DropDown
        Panel                       matlab.ui.container.Panel
        UncheckallButton            matlab.ui.control.Button
        CheckallButton              matlab.ui.control.Button
        WriteButton                 matlab.ui.control.Button
        ImagetypestowriteawayLabel  matlab.ui.control.Label
        DoLPCheckBox                matlab.ui.control.CheckBox
        AoLPCheckBox                matlab.ui.control.CheckBox
        S2CheckBox                  matlab.ui.control.CheckBox
        S1CheckBox                  matlab.ui.control.CheckBox
        S0CheckBox                  matlab.ui.control.CheckBox
        ChannelsCheckBox            matlab.ui.control.CheckBox
        PanelSlider                 matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GotoframeSpinner            matlab.ui.control.Spinner
        GotoframeSpinnerLabel       matlab.ui.control.Label
        MaxFrameLabelEditField      matlab.ui.control.NumericEditField
        MinFrameLabelEditField      matlab.ui.control.NumericEditField
        FrameSlider                 matlab.ui.control.Slider
        ContextMenu3                matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        
        CallingApp % Main app object
        settings % struct, information about dataset and class instances for processing
        tr % Tiff object for reading stack
        currentFrame % index of current frame being displayed
        currentImageUnprocessed % current unprocessed frame
        currentImg % current processed frame being displayed
        Proc % instance of the class Process

    end
    
    methods (Access = private)
        
        function [] = enableSliderPanel(app)
            %ENABLESLIDERPANEL
            children = get(app.PanelSlider,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissableSliderPanel(app)
            %DISSABLESLIDERPANEL
            children = get(app.PanelSlider,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = moveToSpecificFrame(app,frameNumber)
            if (frameNumber > 1) && (frameNumber < app.CallingApp.numFrames)
                app.tr.setDirectory(frameNumber);
                img = double(app.tr.read());
                img = app.Proc.makeDimensionsEven(img);
                img = (img - app.settings.camOffset)./(app.settings.camGain*app.settings.QE);
                app.currentImageUnprocessed = img;
                convertCurrentImg(app);
                imagesc(app.UIAxes,app.currentImg);
            end
        end

        function [] = moveToNextFrame(app)
            if app.GotoframeSpinner.Value < app.CallingApp.numFrames
                newFrame = app.GotoframeSpinner.Value + 1;
                moveToSpecificFrame(app,newFrame);
                app.GotoframeSpinner.Value = newFrame;
                app.FrameSlider.Value      = newFrame;
                pause(0.05)
            end
        end
        
        function [] = moveToPreviousFrame(app)
            if  app.GotoframeSpinner.Value > 1
                newFrame = app.GotoframeSpinner.Value - 1;
                moveToSpecificFrame(app,newFrame);
                app.GotoframeSpinner.Value = newFrame;
                app.FrameSlider.Value      = newFrame;
                pause(0.05)
            end
        end

        function [] = rerenderCurrentFrame(app)
            convertCurrentImg(app);
            imagesc(app.UIAxes,app.currentImg);
        end
        
        function [] = prepareForConversionStack(app)
            %PREPAREFORCONVERSIONSTACK
            % check if an image is loaded
            try testImg = imread(app.CallingApp.filelist(1).filepath);
            catch; writeToErrorLog(app.CallingApp,'ERROR: No images are loaded.'); return; end
            
            % open parameter settings dialog app
            app.CallingApp.DialogSettingsDiffr = DialogAppSettingsDiffractionLimited(app.CallingApp);
            while isvalid(app.CallingApp.DialogSettingsDiffr); pause(0.1); end % wait until window closes
            
            if app.CallingApp.settingsDiffrLoaded % if settings were loaded properly
                
                % update settings with dimensions of images that are currently loaded
                [nx,ny] = size(testImg);
                if mod(nx,2); nx = nx - 1; end % round down to nearest even number (software will force to be even)
                if mod(ny,2); ny = ny - 1; end % round down to nearest even number (software will force to be even)
                app.CallingApp.settingsDiffr.imgSize = [nx,ny];

                % get a copy of the current settings
                app.settings = app.CallingApp.settingsDiffr;

                % create instances of the Camera, Microscope and Process classes
                app.Proc = createInstanceProcessForDiffrProcessing(app);

                % create a Tiff object for reading loaded images and read first frame
                app.tr = Tiff(app.CallingApp.filelist(1).filepath,'r');
                firstFrame = double(app.tr.read());
                firstFrame = (firstFrame - app.settings.camOffset)./(app.settings.camGain*app.settings.QE);
                firstFrame = app.Proc.makeDimensionsEven(firstFrame);
                app.currentImageUnprocessed = firstFrame;
                
%                 % set gui limit field values
%                 app.DoLPmin = 0;
%                 app.DoLPmax = 1;
%                 app.S0min = min(app.currentImageUnprocessed(:));
%                 app.S0max = max(app.currentImageUnprocessed(:));
%                 app.DoLPminEditField.Value = app.DoLPmin;
%                 app.DoLPmaxEditField.Value = app.DoLPmax;
%                 app.S0minEditField.Value   = app.S0min;
%                 app.S0maxEditField.Value   = app.S0max;

                % process first frame
                convertCurrentImg(app);
            else
                writeToErrorLog(app,"ERROR: Settings were not saved. Try again.")
            end
        end
        
        function Proc = createInstanceProcessForDiffrProcessing(app)                
            Cam = Camera(app.settings.camPixSize, app.settings.imgSize,app.settings.mosaicUnitArray,app.settings.camOffset,app.settings.camGain,1,app.settings.QE);
            Scope = Microscope(Cam,0,app.settings.magnification,0);
            locParamsDummy.w.xy = 0; locParamsDummy.w.phi = 0; locParamsDummy.w.theta = 0;
            Proc = Process(Scope,Cam,app.settings.bkgndEstimationParams,app.settings.methodInterpolation,locParamsDummy);
        end

        function [] = convertCurrentImg(app)

            if strcmp(app.ImagetypeDropDown.Value,'Unprocessed')
                app.currentImg = app.currentImageUnprocessed;

                % display image correctly
                app.UIAxes.CLim = [min(app.currentImageUnprocessed(:)) max(app.currentImageUnprocessed(:))];
                app.UIAxes.Colormap = gray;

            else
                [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(app.currentImageUnprocessed);
                I0   = app.removeOnePixelBorder(I0);
                Ip45 = app.removeOnePixelBorder(Ip45);
                I90  = app.removeOnePixelBorder(I90);
                In45 = app.removeOnePixelBorder(In45);
                
                S0 = (I0 + Ip45 + I90 + In45)/2;
                S0_min = min(S0(:));
                S0_max = max(S0(:));
                clear var S0

                switch app.ImagetypeDropDown.Value

                    case 'Channels'
                        app.currentImg = [[I0 Ip45];[I90 In45]];

                        % display image correctly
                        app.UIAxes.CLim = [S0_min S0_max];
                        app.UIAxes.Colormap = gray;

                    case 'S0 (Intensity)'
                        app.currentImg = (I0 + Ip45 + I90 + In45)/2;
                        
                        % display image correctly
                        app.UIAxes.CLim = [S0_min S0_max];
                        app.UIAxes.Colormap = gray;

                    case 'S1'
                        app.currentImg = I0 - I90;
                        
                        % display image correctly
                        app.UIAxes.CLim = [-S0_max S0_max];
                        app.UIAxes.Colormap = Utils.Colormaps.cmocean.cmocean('balance');

                    case 'S2'
                        app.currentImg = Ip45 - In45;

                        % display image correctly
                        app.UIAxes.CLim = [-S0_max S0_max];
                        app.UIAxes.Colormap = Utils.Colormaps.cmocean.cmocean('balance');

                    case 'DoLP'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        app.currentImg = sqrt((S1.^2 + S2.^2)./(S0.^2));
                        
                        % display image correctly
                        app.UIAxes.CLim = [0 1];
                        app.UIAxes.Colormap = parula;

                    case 'AoLP'
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        app.currentImg = (1/2)*atan2(S2,S1);

                        % display image correctly
                        app.UIAxes.CLim = [-pi/2 pi/2];
                        app.UIAxes.Colormap = hsv;

                    case 'AoLP/DoLP/S0'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        AoLP = (1/2)*atan2(S2,S1);
                        DoLP = sqrt((S1.^2 + S2.^2)./(S0.^2));

                        DoLP_min = 0;
                        DoLP_max = 1;

                        S0_min = min(S0(:));
                        S0_max = max(S0(:));

                        H = (AoLP + pi/2)/pi;
                        S = (DoLP - DoLP_min)./(DoLP_max - DoLP_min); S(S<0) = 0; S(S>1) = 1;
                        V = (S0 - S0_min)./(S0_max - S0_min);         V(V<0) = 0; V(V>1) = 1;

                        app.currentImg = hsv2rgb(H,S,V);

                        % display image correctly
                        app.UIAxes.Colormap = hsv;

                    case 'DoLP/S0'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        DoLP = sqrt((S1.^2 + S2.^2)./(S0.^2));

                        DoLP(DoLP < 0) = 0;
                        DoLP(DoLP > 1) = 1;

                        S0_min = min(S0(:));
                        S0_max = max(S0(:));
                        weight = (S0 - S0_min)./(S0_max - S0_min);
                        weight(weight < 0) = 0; weight(weight > 1) = 1;

                        img = uint8(round(DoLP*255));
                        rgb = ind2rgb(img, parula(255));
                        rgb(:,:,1) = rgb(:,:,1).*weight;
                        rgb(:,:,2) = rgb(:,:,2).*weight;
                        rgb(:,:,3) = rgb(:,:,3).*weight;
                        app.currentImg = rgb;
                        
                        % display colorbar
                        app.UIAxes.Colormap = parula;
                end
            end
        end

        
        function [channels,S0,S1,S2,AoLP,DoLP] = getAllImageFormats(app)
            %GETALLIMAGEFORMATS Calculate all image formats from the
            %unprocessed image/stack that is currently loaded.
            [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(app.currentImageUnprocessed);
            I0   = app.removeOnePixelBorder(I0);
            Ip45 = app.removeOnePixelBorder(Ip45);
            I90  = app.removeOnePixelBorder(I90);
            In45 = app.removeOnePixelBorder(In45);
            channels = [[I0 Ip45];[I90 In45]];
            [S0,S1,S2] = app.Proc.getStokesParametersFromIntensities(I0,Ip45,I90,In45);
            AoLP = app.Proc.getAzimuthFromStokes(S1,S2);
            DoLP = app.Proc.getDoLPFromStokes(S0,S1,S2);
        end

        
        function [] = writeS0(app)
            warning('off','all') % Suppress all the tiff warnings
            [folder,basename,~] = fileparts(app.CallingApp.filelist(1).filepath);
            
            % set up tif for writing S0
            filepath = fullfile(folder,strcat(basename,'_S0.tif'));
            t = Tiff(filepath,'w');
            tagstruct.ImageLength = app.CallingApp.imgSize(1); % image height
            tagstruct.ImageWidth  = app.CallingApp.imgSize(2); % image width
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
            tagstruct.Photometric = Tiff.Photometric.LinearRaw;
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.Software = 'MATLAB';

            app.moveToSpecificFrame(1); % move to the first frame
            for i = 1:app.CallingApp.numFrames

                frame = app.currentImageUnprocessed;
                [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(frame);
                [S0,~,~] = app.Proc.getStokesParametersFromIntensities(I0,Ip45,I90,In45);
                
                setTag(t,tagstruct)
                write(t,squeeze(single(S0)));
                writeDirectory(t);

                app.moveToNextFrame();
            end
            close(t);
            warning('on','all')
        end

        function [] = writeS1(app)
            warning('off','all') % Suppress all the tiff warnings
            [folder,basename,~] = fileparts(app.CallingApp.filelist(1).filepath);
            
            % set up tif for writing S1
            filepath = fullfile(folder,strcat(basename,'_S1.tif'));
            t = Tiff(filepath,'w');
            tagstruct.ImageLength = app.CallingApp.imgSize(1); % image height
            tagstruct.ImageWidth  = app.CallingApp.imgSize(2); % image width
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
            tagstruct.Photometric = Tiff.Photometric.LinearRaw;
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.Software = 'MATLAB';

            app.moveToSpecificFrame(1); % move to the first frame
            for i = 1:app.CallingApp.numFrames

                frame = app.currentImageUnprocessed;
                [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(frame);
                [~,S1,~] = app.Proc.getStokesParametersFromIntensities(I0,Ip45,I90,In45);
                
                setTag(t,tagstruct)
                write(t,squeeze(single(S1)));
                writeDirectory(t);

                app.moveToNextFrame();
            end
            close(t);
            warning('on','all')
        end

        function [] = writeS2(app)
            warning('off','all') % Suppress all the tiff warnings
            [folder,basename,~] = fileparts(app.CallingApp.filelist(1).filepath);
            
            % set up tif for writing S2
            filepath = fullfile(folder,strcat(basename,'_S2.tif'));
            t = Tiff(filepath,'w');
            tagstruct.ImageLength = app.CallingApp.imgSize(1); % image height
            tagstruct.ImageWidth  = app.CallingApp.imgSize(2); % image width
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
            tagstruct.Photometric = Tiff.Photometric.LinearRaw;
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.Software = 'MATLAB';

            app.moveToSpecificFrame(1); % move to the first frame
            for i = 1:app.CallingApp.numFrames

                frame = app.currentImageUnprocessed;
                [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(frame);
                [~,~,S2] = app.Proc.getStokesParametersFromIntensities(I0,Ip45,I90,In45);
                
                setTag(t,tagstruct)
                write(t,squeeze(single(S2)));
                writeDirectory(t);

                app.moveToNextFrame();
            end
            close(t);
            warning('on','all')
        end

        function [] = writeAoLP(app)
            warning('off','all') % Suppress all the tiff warnings
            [folder,basename,~] = fileparts(app.CallingApp.filelist(1).filepath);
            
            % set up tif for writing DoLP
            filepath = fullfile(folder,strcat(basename,'_AoLP.tif'));
            t = Tiff(filepath,'w');
            tagstruct.ImageLength = app.CallingApp.imgSize(1); % image height
            tagstruct.ImageWidth  = app.CallingApp.imgSize(2); % image width
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
            tagstruct.Photometric = Tiff.Photometric.LinearRaw;
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.Software = 'MATLAB';

            app.moveToSpecificFrame(1); % move to the first frame
            for i = 1:app.CallingApp.numFrames

                frame = app.currentImageUnprocessed;
                [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(frame);
                [~,S1,S2] = app.Proc.getStokesParametersFromIntensities(I0,Ip45,I90,In45);
                AoLP = app.Proc.getAzimuthFromStokes(S1,S2);
                
                setTag(t,tagstruct)
                write(t,squeeze(single(AoLP)));
                writeDirectory(t);

                app.moveToNextFrame();
            end
            close(t);
            warning('on','all')
        end

        function [] = writeDoLP(app)
            warning('off','all') % Suppress all the tiff warnings
            [folder,basename,~] = fileparts(app.CallingApp.filelist(1).filepath);
            
            % set up tif for writing DoLP
            filepath = fullfile(folder,strcat(basename,'_DoLP.tif'));
            t = Tiff(filepath,'w');
            tagstruct.ImageLength = app.CallingApp.imgSize(1); % image height
            tagstruct.ImageWidth  = app.CallingApp.imgSize(2); % image width
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
            tagstruct.Photometric = Tiff.Photometric.LinearRaw;
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.Software = 'MATLAB';

            app.moveToSpecificFrame(1); % move to the first frame
            for i = 1:app.CallingApp.numFrames

                frame = app.currentImageUnprocessed;
                [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(frame);
                [S0,S1,S2] = app.Proc.getStokesParametersFromIntensities(I0,Ip45,I90,In45);
                DoLP = app.Proc.getDoLPFromStokes(S0,S1,S2);
                
                setTag(t,tagstruct)
                write(t,squeeze(single(DoLP)));
                writeDirectory(t);

                app.moveToNextFrame();
            end
            close(t);
            warning('on','all')
        end

    end

    methods (Static)

        function img = removeOnePixelBorder(img)
            img = img(2:end-1,2:end-1);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp; % Store main app in property for CloseRequestFcn to use
            
            app.currentFrame = 1;
            
            if app.CallingApp.numFrames == 1
                dissableSliderPanel(app);
            else
                app.MaxFrameLabelEditField.Value = app.CallingApp.numFrames;
                app.FrameSlider.Limits      = [1 app.CallingApp.numFrames]; 
                app.GotoframeSpinner.Limits = [1 app.CallingApp.numFrames];
            end

            % get processing settings
            app.settings = struct;

            prepareForConversionStack(app);

            imagesc(app.UIAxes,app.currentImg); 
            xlabel(app.UIAxes,'x (pix)');
            ylabel(app.UIAxes,'y (pix)');
            
            c = colorbar(app.UIAxes);
            c.Label.String = 'Counts (ADU)';
            c.TickDirection = 'out'; 
            colormap(app.UIAxes,"gray")

            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
                moveToPreviousFrame(app);
            end
            if strcmp(key,'rightarrow')
                moveToNextFrame(app);
            end
        end

        % Value changed function: GotoframeSpinner
        function GotoframeSpinnerValueChanged(app, event)
            app.FrameSlider.Value = app.GotoframeSpinner.Value;
            moveToSpecificFrame(app, app.GotoframeSpinner.Value);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.GotoframeSpinner.Value = round(app.FrameSlider.Value);
            moveToSpecificFrame(app,round(app.FrameSlider.Value));
        end

        % Value changed function: ImagetypeDropDown
        function ImagetypeDropDownValueChanged(app, event)
            rerenderCurrentFrame(app);
        end

        % Button pushed function: CheckallButton
        function CheckallButtonPushed(app, event)
            app.ChannelsCheckBox.Value = 1;
            app.S0CheckBox.Value = 1;
            app.S1CheckBox.Value = 1;
            app.S2CheckBox.Value = 1;
            app.AoLPCheckBox.Value = 1;
            app.DoLPCheckBox.Value = 1;
        end

        % Button pushed function: UncheckallButton
        function UncheckallButtonPushed(app, event)
            app.ChannelsCheckBox.Value = 0;
            app.S0CheckBox.Value = 0;
            app.S1CheckBox.Value = 0;
            app.S2CheckBox.Value = 0;
            app.AoLPCheckBox.Value = 0;
            app.DoLPCheckBox.Value = 0;
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            % get the folder and name of the opened file
            [folder,basename,~] = fileparts(app.CallingApp.filelist(1).filepath);

            if app.ChannelsCheckBox.Value % if channels should be written away
                filename = strcat(basename,'_channels.tif');
                Utils.writeTifStack(channels,fullfile(folder,filename))
            end

            if app.S0CheckBox.Value; app.writeS0(); end
            if app.S1CheckBox.Value; app.writeS1(); end
            if app.S2CheckBox.Value; app.writeS2(); end
            if app.AoLPCheckBox.Value; app.writeAoLP(); end
            if app.DoLPCheckBox.Value; app.writeDoLP(); end

            newMessage = sprintf("Files were written away in '%s'",folder);
            app.CallingApp.writeToLog(' ');
            app.CallingApp.writeToLog(newMessage);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PanelSlider = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.PanelSlider);
            app.FrameSlider = uislider(app.GridLayout2);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [];
            app.MinFrameLabelEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinFrameLabelEditField.Editable = 'off';
            app.MinFrameLabelEditField.Value = 1;
            app.MaxFrameLabelEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxFrameLabelEditField.Editable = 'off';
            app.MaxFrameLabelEditField.Value = 100;
            app.GotoframeSpinnerLabel = uilabel(app.GridLayout2);
            app.GotoframeSpinnerLabel.Text = 'Go to frame:';
            app.GotoframeSpinner = uispinner(app.GridLayout2);
            app.GotoframeSpinner.Limits = [1 501];
            app.GotoframeSpinner.ValueChangedFcn = createCallbackFcn(app, @GotoframeSpinnerValueChanged, true);
            app.GotoframeSpinner.Value = 1;
            app.Panel = uipanel(app.GridLayout);
            app.ChannelsCheckBox = uicheckbox(app.Panel);
            app.ChannelsCheckBox.Text = 'Channels';
            app.S0CheckBox = uicheckbox(app.Panel);
            app.S0CheckBox.Text = 'S0';
            app.S1CheckBox = uicheckbox(app.Panel);
            app.S1CheckBox.Text = 'S1';
            app.S2CheckBox = uicheckbox(app.Panel);
            app.S2CheckBox.Text = 'S2';
            app.AoLPCheckBox = uicheckbox(app.Panel);
            app.AoLPCheckBox.Text = 'AoLP';
            app.DoLPCheckBox = uicheckbox(app.Panel);
            app.DoLPCheckBox.Text = 'DoLP';
            app.ImagetypestowriteawayLabel = uilabel(app.Panel);
            app.ImagetypestowriteawayLabel.Text = 'Image type(s) to write away:';
            app.WriteButton = uibutton(app.Panel, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.CheckallButton = uibutton(app.Panel, 'push');
            app.CheckallButton.ButtonPushedFcn = createCallbackFcn(app, @CheckallButtonPushed, true);
            app.CheckallButton.Text = 'Check all';
            app.UncheckallButton = uibutton(app.Panel, 'push');
            app.UncheckallButton.ButtonPushedFcn = createCallbackFcn(app, @UncheckallButtonPushed, true);
            app.UncheckallButton.Text = 'Uncheck all';
            app.Panel_3 = uipanel(app.GridLayout);
            app.ImagetypeDropDown = uidropdown(app.Panel_3);
            app.ImagetypeDropDown.Items = {'Unprocessed', 'Channels', 'S0 (Intensity)', 'S1', 'S2', 'AoLP', 'DoLP', 'AoLP/DoLP/S0', 'DoLP/S0'};
            app.ImagetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagetypeDropDownValueChanged, true);
            app.ImagetypeDropDown.Value = 'Channels';
            app.FormatLabel = uilabel(app.Panel_3);
            app.FormatLabel.Text = 'Image type:  ';
            app.Panel_2 = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel_2);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppImgDataDiffractionLimited(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end