classdef DialogAppImgDataSingleMolecule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ButtonNext      matlab.ui.control.Button
        ButtonPrevious  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
        ContextMenu     matlab.ui.container.ContextMenu
        SetlimitsMenu   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp % Main app object
        
    end
    
    properties (Access = public)

        tr % Tiff object for reading stack
        currentFrame % index of current frame being displayed
        currentImg % current frame being explained (image)

    end
    
    methods (Access = private)
        
        function [] = moveToNextFrame(app)
            %MOVETONEXTFRAME
            if app.currentFrame < app.CallingApp.numFrames
                app.currentFrame = app.currentFrame + 1;
                app.tr.setDirectory(app.currentFrame);
                app.currentImg = double(app.tr.read());
                imagesc(app.UIAxes,app.currentImg);
            end
            if ~(isempty(app.CallingApp.DialogLocalisation) || ~isvalid(app.CallingApp.DialogLocalisation)) % if no localisation window is open currently
                if strcmp(app.CallingApp.DialogLocalisation.LivepreviewSwitch.Value,'On')
                    app.CallingApp.DialogLocalisation.updatePreviewLocalisations;
                end
            end
        end
        
        function [] = moveToPreviousFrame(app)
            %MOVETOPREVIOUSFRAME
            if app.currentFrame > 1
                app.currentFrame = app.currentFrame - 1;
                app.tr.setDirectory(app.currentFrame);
                app.currentImg = double(app.tr.read());
                imagesc(app.UIAxes,app.currentImg);
            end
            if ~(isempty(app.CallingApp.DialogLocalisation) || ~isvalid(app.CallingApp.DialogLocalisation)) % if no localisation window is open currently
                if strcmp(app.CallingApp.DialogLocalisation.LivepreviewSwitch.Value,'On')
                    app.CallingApp.DialogLocalisation.updatePreviewLocalisations;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.currentFrame = 1;
            
            % set up Tiff for fast reading
            app.tr = Tiff(app.CallingApp.filelist(1).filepath,'r');
            app.currentImg = double(app.tr.read());
            
            imagesc(app.UIAxes,app.currentImg); 
            xlabel(app.UIAxes,'x (pix)');
            ylabel(app.UIAxes,'y (pix)');
            
            c = colorbar(app.UIAxes);
            c.Label.String = 'Counts (ADU)';
            c.TickDirection = 'out'; 
            colormap(app.UIAxes,"gray")

            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';

        end

        % Button pushed function: ButtonPrevious
        function ButtonPreviousPushed(app, event)
            moveToPreviousFrame(app);
        end

        % Button pushed function: ButtonNext
        function ButtonNextPushed(app, event)
            moveToNextFrame(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow');  moveToPreviousFrame(app); end
            if strcmp(key,'rightarrow'); moveToNextFrame(app); end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ButtonPrevious = uibutton(app.UIFigure, 'push');
            app.ButtonPrevious.ButtonPushedFcn = createCallbackFcn(app, @ButtonPreviousPushed, true);
            app.ButtonPrevious.Text = '<';
            app.ButtonNext = uibutton(app.UIFigure, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @ButtonNextPushed, true);
            app.ButtonNext.Text = '>';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SetlimitsMenu = uimenu(app.ContextMenu);
            app.SetlimitsMenu.Text = 'Set limits';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppImgDataSingleMolecule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end