classdef DialogAppSettingsDiffractionLimited < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CameracalibrationPanel         matlab.ui.container.Panel
        PixeldependentPanel            matlab.ui.container.Panel
        ButtonLoadGainFile             matlab.ui.control.Button
        ButtonLoadOffsetFile           matlab.ui.control.Button
        GainEditField_2                matlab.ui.control.EditField
        GainEditField_2Label           matlab.ui.control.Label
        OffsetEditField_2              matlab.ui.control.EditField
        OffsetEditField_2Label         matlab.ui.control.Label
        NotpixeldependentPanel         matlab.ui.container.Panel
        GainEditField                  matlab.ui.control.NumericEditField
        GainLabel                      matlab.ui.control.Label
        OffsetEditField                matlab.ui.control.NumericEditField
        OffsetLabel                    matlab.ui.control.Label
        UsepixeldependentcameraoffsetandgainCheckBox  matlab.ui.control.CheckBox
        ChannelinterpolationPanel      matlab.ui.container.Panel
        InterpolationmethodDropDown    matlab.ui.control.DropDown
        InterpolationmethodLabel       matlab.ui.control.Label
        PolarisermosaicunitDropDown    matlab.ui.control.DropDown
        PolarisermosaicunitLabel       matlab.ui.control.Label
        SavesettingsButton             matlab.ui.control.Button
        SetupparametersPanel           matlab.ui.container.Panel
        TotalmagnificationEditField    matlab.ui.control.NumericEditField
        TotalmagnificationEditFieldLabel  matlab.ui.control.Label
        QuantumefficienycameraQEEditField  matlab.ui.control.NumericEditField
        QuantumefficienycameraQELabel  matlab.ui.control.Label
        CamerapixelsizemEditField      matlab.ui.control.NumericEditField
        CamerapixelsizemLabel          matlab.ui.control.Label
        BackgroundestimationPanel      matlab.ui.container.Panel
        KernelsizespatialmedianfilterEditField  matlab.ui.control.NumericEditField
        KernelsizespatialmedianfilterLabel  matlab.ui.control.Label
        PreviewButtonBkgndEst          matlab.ui.control.Button
        DropDownBkgndEst               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        CallingApp % Main app object

        camOffset % camera offset (double or array)
        camGain % camera gain (double or array)
        camOffsetLoaded = 0; % boolean, check if offset file was loaded
        camGainLoaded = 0; % boolean, check if gain file was loaded
        offsetSize % dimensions of the offset image
        gainSize % dimensions of the gain image
        
        methodBkgndEst % str, method used for background estimation
        bkgndEstimationParams % struct containing bkgnd estimation parameters
        
        QE % camera quantunm efficiency
        camPixSize % physical camera pixel size in um
        magnification % total setup magnification (objective and relays)

        mosaicUnit % str, arrangement of polarisers in top left corner of image
        mosaicUnitArray % array, arrangement of polarisers in top left corner of image
        methodInterpolation % channel interpolation method
        
        settingsDiffr % struct containing microscope and camera parameters for diffraction limited image processing
        
    end
    
    methods (Access = private)
        
        function [] = enablePixelDependentCameraCalib(app)
            % enable the pixel-dependent camera calibration panel
            children = get(app.PixeldependentPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
            % dissable the pixel-independent camera calibration panel
            children = get(app.NotpixeldependentPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = dissablePixelDependentCameraCalib(app)
            % disable the pixel-dependent camera calibration panel
            children = get(app.PixeldependentPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
            % enable the pixel-independent camera calibration panel
            children = get(app.NotpixeldependentPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end
        
        function [] = enableAllPanels(app)
            enablePanelCameraCalibration(app);
            enablePanelBkgndEst(app);
            enablePanelSetupParams(app);
            enablePanelChannelInterpolation(app);
        end

        function [] = dissableAllPanels(app)
            dissablePanelCameraCalibration(app);
            dissablePanelBkgndEst(app);
            dissablePanelSetupParams(app);
            dissablePanelChannelInterpolation(app);
        end

        function [] = enablePanelCameraCalibration(app)
            children = get(app.CameracalibrationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelCameraCalibration(app)
            children = get(app.CameracalibrationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enablePanelBkgndEst(app)
            children = get(app.BackgroundestimationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelBkgndEst(app)
            children = get(app.BackgroundestimationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enablePanelSetupParams(app)
            children = get(app.SetupparametersPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelSetupParams(app)
            children = get(app.SetupparametersPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enablePanelChannelInterpolation(app)
            children = get(app.ChannelinterpolationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelChannelInterpolation(app)
            children = get(app.ChannelinterpolationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enableSaveSettingsButton(app)
            set(app.SavesettingsButton,'Enable','on')
        end

        function [] = dissableSaveSettingsButton(app)
            set(app.SavesettingsButton,'Enable','off')
        end

        function [] = enableKernelSizeBkgndEst(app)
            set(app.KernelsizespatialmedianfilterEditField,'Enable','on')
        end

        function [] = dissableKernelSizeBkgndEst(app)
            set(app.KernelsizespatialmedianfilterEditField,'Enable','off')
        end

        function [] = mosaicUnitStringToArray(app)
            switch app.mosaicUnit
                case '[-45 0; 90 45]'
                    app.mosaicUnitArray(1,1) = -45;
                    app.mosaicUnitArray(1,2) =   0;
                    app.mosaicUnitArray(2,1) =  90;
                    app.mosaicUnitArray(2,2) =  45;
                case '[0 -45; 45 90]'
                    app.mosaicUnitArray(1,1) =   0;
                    app.mosaicUnitArray(1,2) = -45;
                    app.mosaicUnitArray(2,1) =  45;
                    app.mosaicUnitArray(2,2) =  90;
                case '[90 45; -45 0]'
                    app.mosaicUnitArray(1,1) =  90;
                    app.mosaicUnitArray(1,2) =  45;
                    app.mosaicUnitArray(2,1) = -45;
                    app.mosaicUnitArray(2,2) =   0;
                case '[45 90; 0 -45]'
                    app.mosaicUnitArray(1,1) =  45;
                    app.mosaicUnitArray(1,2) =  90;
                    app.mosaicUnitArray(2,1) =   0;
                    app.mosaicUnitArray(2,2) = -45;
                otherwise
                    newMessage = sprintf("Value of app.mosaicUnit not recognised by function mosaicUnitStringToArray in DialogAppLocalisation.mlapp..");
                    writeToLog(app.CallingApp,newMessage);
            end
        end
        
        function [] = bkgndEstLabelToCase(app)
            switch app.bkgndEstimationParams.method
                case 'None'
                    app.bkgndEstimationParams.method = 'none';
                case 'Temporal minimum intensity projection + spatial median filter'
                    app.bkgndEstimationParams.method = 'medfilt mip';
                case 'Temporal average intensity projection + spatial median filter'
                    app.bkgndEstimationParams.method = 'medfilt avg';
                case 'Temporal median intensity projection + spatial median filter'
                    app.bkgndEstimationParams.method = 'medfilt med';
                otherwise
                    newMessage = sprintf("Value of app.bkgndEstimationParams.method not recognised by function bkgndEstLabelToCase in DialogAppLocalisation.mlapp.");
                    writeToLog(app.CallingApp,newMessage);
            end
        end
        
        function [] = checkStatusKernelSizeBkgndEst(app)
            if strcmp(app.bkgndEstimationParams.method,'none')
                dissableKernelSizeBkgndEst(app);
            else
                enableKernelSizeBkgndEst(app);
                app.bkgndEstimationParams.params.k = app.KernelsizespatialmedianfilterEditField.Value;
            end
        end
        
        function [] = methodInterpolationLabelToCase(app)
            %METHODINTERPOLATIONLABELTOCASE
            switch app.methodInterpolation
                case 'Interpolation (linear)'
                    app.methodInterpolation = 'interpolation linear';
                case 'Interpolation (cubic)'
                    app.methodInterpolation = 'interpolation cubic';
                case 'Interpolation (cubic spline, recommended)'
                    app.methodInterpolation = 'interpolation spline';
                case 'Fourier (Tyo et al, Opt Lett, 2009)'
                    app.methodInterpolation = 'fourier';
                otherwise
                    newMessage = sprintf("Value of app.methodInterpolation not recognised by function methodInterpolationLabelToCase in DialogAppLocalisation.mlapp.");
                    writeToLog(app.CallingApp,newMessage);
            end
        end
        
        function [] = updateSettingsDiffrStruct(app)
            app.settingsDiffr.camOffset           = app.camOffset;
            app.settingsDiffr.camGain             = app.camGain;
            app.settingsDiffr.methodBkgndEst      = app.methodBkgndEst;
            app.settingsDiffr.bkgndEstimationParams.method = app.bkgndEstimationParams.method;
            app.settingsDiffr.bkgndEstimationParams.k = app.bkgndEstimationParams.params.k;
            app.settingsDiffr.QE                  = app.QE;
            app.settingsDiffr.camPixSize          = app.camPixSize;
            app.settingsDiffr.magnification       = app.magnification;
            app.settingsDiffr.mosaicUnitArray     = app.mosaicUnitArray;
            app.settingsDiffr.methodInterpolation = app.methodInterpolation;
            app.settingsDiffr.imgSize             = [0 0]; % will be filled in later
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            addpath('lib')
            
            % reset indicator for correct settings loading to 0
            app.CallingApp.settingsDiffrLoaded = 0;

            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            % disable the pixel-dependent camera calibration panel
            dissablePixelDependentCameraCalib(app);
            
            % load default values
            app.camOffset = app.OffsetEditField.Value;
            app.camGain   = app.GainEditField.Value;
            
            app.methodBkgndEst = app.DropDownBkgndEst.Value;
            app.bkgndEstimationParams.method = app.methodBkgndEst;
            app.bkgndEstimationParams.params.k = app.KernelsizespatialmedianfilterEditField.Value;
            bkgndEstLabelToCase(app);
            checkStatusKernelSizeBkgndEst(app);
            
            app.QE            = app.QuantumefficienycameraQEEditField.Value;
            app.camPixSize    = app.CamerapixelsizemEditField.Value;
            app.magnification = app.TotalmagnificationEditField.Value;
            
            app.mosaicUnit = app.PolarisermosaicunitDropDown.Value;
            mosaicUnitStringToArray(app);
            app.methodInterpolation = app.InterpolationmethodDropDown.Value;
            methodInterpolationLabelToCase(app);
            
            updateSettingsDiffrStruct(app);

        end

        % Value changed function: 
        % UsepixeldependentcameraoffsetandgainCheckBox
        function UsepixeldependentcameraoffsetandgainCheckBoxValueChanged(app, event)
            value = app.UsepixeldependentcameraoffsetandgainCheckBox.Value;
            if value % use pixel-dependent camera calibration files
                enablePixelDependentCameraCalib(app);
                dissablePanelBkgndEst(app);
                dissablePanelSetupParams(app);
                dissablePanelChannelInterpolation(app);
                dissablePanelLocalisationParams(app);
                dissableSaveSettingsButton(app);
                % turn live preview localisations off if that was on
                if app.CallingApp.livePreviewStatus
                    turnOffPreviewLocalisations(app);
                end
            else % don't use pixel-dependent camera calibration files
                dissablePixelDependentCameraCalib(app);
                app.camOffset = app.OffsetEditField.Value;
                app.camGain   = app.GainEditField.Value;
                enablePanelBkgndEst(app);
                enablePanelSetupParams(app);
                enablePanelChannelInterpolation(app);
                enablePanelLocalisationParams(app);
                checkStatusKernelSizeBkgndEst(app);
                enableSaveSettingsButton(app);
            end
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            app.camOffset = app.OffsetEditField.Value;
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            app.camGain = app.GainEditField.Value;
        end

        % Button pushed function: ButtonLoadOffsetFile
        function ButtonLoadOffsetFilePushed(app, event)
            app.camOffsetLoaded = 0;
            [filenameOffset,folderOffset] = uigetfile('*.tif','Select camera calibration offset file');
            pathOffset = fullfile(folderOffset,filenameOffset);
            try % try loading the offset file
                offset = double(imread(pathOffset));
                app.camOffset = offset(1:app.Cam.imgSizeEven(1),1:app.Cam.imgSizeEven(2));
                app.OffsetEditField_2.Value = pathOffset; % display the paths in the text boxes in the gui
                app.camOffsetLoaded = 1;
            catch
                newMessage = sprintf("Could not read the offset file '%s'",pathOffset);
                writeToLog(app.CallingApp,newMessage);
            end
            % check if both offset and gain files have been loaded
            % successfully and dimensions match the images
            if app.camOffsetLoaded*app.camGainLoaded == 1
                newMessage = sprintf('Camera calibration files were loaded successfully.');
                writeToLog(app.CallingApp,newMessage);
                enablePanelBkgndEst(app);
                enablePanelSetupParams(app);
                enablePanelChannelInterpolation(app);
                enablePanelLocalisationParams(app);
                checkStatusKernelSizeBkgndEst(app);
                enableSaveSettingsButton(app);
            end
        end

        % Button pushed function: ButtonLoadGainFile
        function ButtonLoadGainFilePushed(app, event)
                [filenameGain,folderGain] = uigetfile('*.tif','Select camera calibration gain file');
                pathGain = fullfile(folderGain,filenameGain);
                try % try loading the offset file
                    gain = double(imread(pathGain));
                    app.camGain = gain(1:app.Cam.imgSizeEven(1),1:app.Cam.imgSizeEven(2));
                    app.GainEditField_2.Value = pathGain; % display the paths in the text boxes in the gui
                    app.camGainLoaded = 1;
                catch
                    newMessage = sprintf("Could not read the offset file '%s'",pathGain);
                    writeToLog(app.CallingApp,newMessage);
                end
                % check if both offset and gain files have been loaded
                % successfully and dimensions match the images
                if app.camOffsetLoaded*app.camGainLoaded == 1
                    newMessage = sprintf('Camera calibration files were loaded successfully.');
                    writeToLog(app.CallingApp,newMessage);
                    enablePanelBkgndEst(app);
                    enablePanelSetupParams(app);
                    enablePanelChannelInterpolation(app);
                    enablePanelLocalisationParams(app);
                    checkStatusKernelSizeBkgndEst(app);
                    enableSaveSettingsButton(app);
                end
        end

        % Value changed function: DropDownBkgndEst
        function DropDownBkgndEstValueChanged(app, event)
            app.methodBkgndEst = app.DropDownBkgndEst.Value;
            app.bkgndEstimationParams.method = app.methodBkgndEst;
            bkgndEstLabelToCase(app);
            checkStatusKernelSizeBkgndEst(app);
        end

        % Value changed function: QuantumefficienycameraQEEditField
        function QuantumefficienycameraQEEditFieldValueChanged(app, event)
            app.QE = app.QuantumefficienycameraQEEditField.Value;
        end

        % Value changed function: CamerapixelsizemEditField
        function CamerapixelsizemEditFieldValueChanged(app, event)
            app.camPixSize = app.CamerapixelsizemEditField.Value;
        end

        % Callback function
        function TotalmagnificationEditFieldValueChanged(app, event)
            app.magnification = app.TotalmagnificationEditField.Value;
        end

        % Value changed function: PolarisermosaicunitDropDown
        function PolarisermosaicunitDropDownValueChanged(app, event)
            app.mosaicUnit = app.PolarisermosaicunitDropDown.Value;
            mosaicUnitStringToArray(app);
        end

        % Value changed function: InterpolationmethodDropDown
        function InterpolationmethodDropDownValueChanged(app, event)
            app.methodInterpolation = app.InterpolationmethodDropDown.Value;
            methodInterpolationLabelToCase(app);
        end

        % Value changed function: KernelsizespatialmedianfilterEditField
        function KernelsizespatialmedianfilterEditFieldValueChanged(app, event)
            app.bkgndEstimationParams.params.k = app.KernelsizespatialmedianfilterEditField.Value;
        end

        % Button pushed function: SavesettingsButton
        function SavesettingsButtonPushed(app, event)
            % send the SettingsDiffr struct to the main app
            updateSettingsDiffrStruct(app);
            app.CallingApp.settingsDiffr = app.settingsDiffr;
            app.CallingApp.settingsDiffrLoaded = 1;
            % write message to error log
            writeToErrorLog(app.CallingApp,'Diffraction-limited processing settings have been updated.')
            % close the parameter window
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackgroundestimationPanel = uipanel(app.UIFigure);
            app.BackgroundestimationPanel.Title = '2. Background estimation';
            app.DropDownBkgndEst = uidropdown(app.BackgroundestimationPanel);
            app.DropDownBkgndEst.Items = {'None', 'Temporal minimum intensity projection + spatial median filter'};
            app.DropDownBkgndEst.ValueChangedFcn = createCallbackFcn(app, @DropDownBkgndEstValueChanged, true);
            app.DropDownBkgndEst.Value = 'None';
            app.PreviewButtonBkgndEst = uibutton(app.BackgroundestimationPanel, 'push');
            app.PreviewButtonBkgndEst.Text = 'Preview';
            app.KernelsizespatialmedianfilterLabel = uilabel(app.BackgroundestimationPanel);
            app.KernelsizespatialmedianfilterLabel.Text = 'Kernel size spatial median filter:';
            app.KernelsizespatialmedianfilterEditField = uieditfield(app.BackgroundestimationPanel, 'numeric');
            app.KernelsizespatialmedianfilterEditField.ValueChangedFcn = createCallbackFcn(app, @KernelsizespatialmedianfilterEditFieldValueChanged, true);
            app.KernelsizespatialmedianfilterEditField.Value = 1;
            app.SetupparametersPanel = uipanel(app.UIFigure);
            app.SetupparametersPanel.Title = '3. Setup parameters';
            app.CamerapixelsizemLabel = uilabel(app.SetupparametersPanel);
            app.CamerapixelsizemLabel.Text = 'Camera pixel size (μm):';
            app.CamerapixelsizemEditField = uieditfield(app.SetupparametersPanel, 'numeric');
            app.CamerapixelsizemEditField.ValueChangedFcn = createCallbackFcn(app, @CamerapixelsizemEditFieldValueChanged, true);
            app.CamerapixelsizemEditField.Value = 3.45;
            app.QuantumefficienycameraQELabel = uilabel(app.SetupparametersPanel);
            app.QuantumefficienycameraQELabel.Text = 'Quantum efficieny camera (QE):';
            app.QuantumefficienycameraQEEditField = uieditfield(app.SetupparametersPanel, 'numeric');
            app.QuantumefficienycameraQEEditField.ValueChangedFcn = createCallbackFcn(app, @QuantumefficienycameraQEEditFieldValueChanged, true);
            app.QuantumefficienycameraQEEditField.Value = 0.7;
            app.TotalmagnificationEditFieldLabel = uilabel(app.SetupparametersPanel);
            app.TotalmagnificationEditFieldLabel.Text = 'Total magnification:';
            app.TotalmagnificationEditField = uieditfield(app.SetupparametersPanel, 'numeric');
            app.TotalmagnificationEditField.Value = 60;
            app.SavesettingsButton = uibutton(app.UIFigure, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.ChannelinterpolationPanel = uipanel(app.UIFigure);
            app.ChannelinterpolationPanel.Title = '4. Channel interpolation';
            app.PolarisermosaicunitLabel = uilabel(app.ChannelinterpolationPanel);
            app.PolarisermosaicunitLabel.Text = 'Polariser mosaic unit:';
            app.PolarisermosaicunitDropDown = uidropdown(app.ChannelinterpolationPanel);
            app.PolarisermosaicunitDropDown.Items = {'[-45 0; 90 45]', '[0 -45; 45 90]', '[90 45; -45 0]', '[45 90; 0 -45]'};
            app.PolarisermosaicunitDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarisermosaicunitDropDownValueChanged, true);
            app.PolarisermosaicunitDropDown.Value = '[-45 0; 90 45]';
            app.InterpolationmethodLabel = uilabel(app.ChannelinterpolationPanel);
            app.InterpolationmethodLabel.Text = 'Interpolation method:';
            app.InterpolationmethodDropDown = uidropdown(app.ChannelinterpolationPanel);
            app.InterpolationmethodDropDown.Items = {'Interpolation (cubic spline, recommended)'};
            app.InterpolationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationmethodDropDownValueChanged, true);
            app.InterpolationmethodDropDown.Value = 'Interpolation (cubic spline, recommended)';
            app.CameracalibrationPanel = uipanel(app.UIFigure);
            app.CameracalibrationPanel.Title = '1. Camera calibration';
            app.UsepixeldependentcameraoffsetandgainCheckBox = uicheckbox(app.CameracalibrationPanel);
            app.UsepixeldependentcameraoffsetandgainCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsepixeldependentcameraoffsetandgainCheckBoxValueChanged, true);
            app.UsepixeldependentcameraoffsetandgainCheckBox.Text = 'Use pixel-dependent camera offset and gain';
            app.NotpixeldependentPanel = uipanel(app.CameracalibrationPanel);
            app.NotpixeldependentPanel.Title = 'Not pixel-dependent:';
            app.OffsetLabel = uilabel(app.NotpixeldependentPanel);
            app.OffsetLabel.Text = 'Offset:';
            app.OffsetEditField = uieditfield(app.NotpixeldependentPanel, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Value = 100;
            app.GainLabel = uilabel(app.NotpixeldependentPanel);
            app.GainLabel.Text = 'Gain:';
            app.GainEditField = uieditfield(app.NotpixeldependentPanel, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 0.42;
            app.PixeldependentPanel = uipanel(app.CameracalibrationPanel);
            app.PixeldependentPanel.Title = 'Pixel-dependent:';
            app.OffsetEditField_2Label = uilabel(app.PixeldependentPanel);
            app.OffsetEditField_2Label.Text = 'Offset:';
            app.OffsetEditField_2 = uieditfield(app.PixeldependentPanel, 'text');
            app.OffsetEditField_2.Value = 'Folder/subfolder/offset.tif';
            app.GainEditField_2Label = uilabel(app.PixeldependentPanel);
            app.GainEditField_2Label.Text = 'Gain:';
            app.GainEditField_2 = uieditfield(app.PixeldependentPanel, 'text');
            app.GainEditField_2.Value = 'Folder/subfolder/gain.tif';
            app.ButtonLoadOffsetFile = uibutton(app.PixeldependentPanel, 'push');
            app.ButtonLoadOffsetFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadOffsetFilePushed, true);
            app.ButtonLoadOffsetFile.Text = '...';
            app.ButtonLoadGainFile = uibutton(app.PixeldependentPanel, 'push');
            app.ButtonLoadGainFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadGainFilePushed, true);
            app.ButtonLoadGainFile.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppSettingsDiffractionLimited(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end