classdef Plugin_Tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TrackingTab                   matlab.ui.container.Tab
        FiltertracksPanel             matlab.ui.container.Panel
        SavefilteredtracksButton      matlab.ui.control.Button
        MinimumPhotonRateEditField    matlab.ui.control.NumericEditField
        PhotonRateLabel               matlab.ui.control.Label
        MaximumPhotonRateEditField    matlab.ui.control.NumericEditField
        MinimumTotalPhotonsEditField  matlab.ui.control.NumericEditField
        TracklengthLabel_2            matlab.ui.control.Label
        MaximumTotalPhotonsEditField  matlab.ui.control.NumericEditField
        MaximumLabel                  matlab.ui.control.Label
        MinimumLabel                  matlab.ui.control.Label
        MaximumTracklengthEditField   matlab.ui.control.NumericEditField
        MinimumTracklengthEditField   matlab.ui.control.NumericEditField
        TracklengthLabel              matlab.ui.control.Label
        TrackingparametersPanel       matlab.ui.container.Panel
        TrackButton                   matlab.ui.control.Button
        AlgorithmDropDown             matlab.ui.control.DropDown
        AlgorithmDropDownLabel        matlab.ui.control.Label
        MaxdisplacementnmEditField    matlab.ui.control.NumericEditField
        MaxdisplacementnmEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        InspecttracksTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)

        CallingApp % Main app object
        
        selectedCells % indices of the selected cells

    end

    methods (Access = private)
        
        function [] = initialiseTable(app)
            t = app.UITable;
            t.RowName = 'numbered'; % display row numbers
            t.Data = [{},{},{},{}]; % initialise a row of empty cells
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            
            initialiseTable(app);

        end

        % Callback function
        function UITableCellSelection(app, event)
            %UITABLECELLSELECTION Get the indices of the cells in the table
            %that are selected by the user. Returns an nx2 array, where n
            %is the number of cells, the first column is the row index and
            %the second column is the column index.
            app.selectedCells = event.Indices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.UIAxes = uiaxes(app.TrackingTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TrackingparametersPanel = uipanel(app.TrackingTab);
            app.TrackingparametersPanel.Title = 'Tracking parameters';
            app.MaxdisplacementnmEditFieldLabel = uilabel(app.TrackingparametersPanel);
            app.MaxdisplacementnmEditFieldLabel.Text = 'Max displacement (nm):';
            app.MaxdisplacementnmEditField = uieditfield(app.TrackingparametersPanel, 'numeric');
            app.MaxdisplacementnmEditField.Value = 100;
            app.AlgorithmDropDownLabel = uilabel(app.TrackingparametersPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm:';
            app.AlgorithmDropDown = uidropdown(app.TrackingparametersPanel);
            app.AlgorithmDropDown.Items = {'Nearest neighbour'};
            app.AlgorithmDropDown.Value = 'Nearest neighbour';
            app.TrackButton = uibutton(app.TrackingparametersPanel, 'push');
            app.TrackButton.Text = 'Track!';
            app.FiltertracksPanel = uipanel(app.TrackingTab);
            app.FiltertracksPanel.Title = 'Filter tracks';
            app.TracklengthLabel = uilabel(app.FiltertracksPanel);
            app.TracklengthLabel.Text = 'Track length:';
            app.MinimumTracklengthEditField = uieditfield(app.FiltertracksPanel, 'numeric');
            app.MinimumTracklengthEditField.Value = 1;
            app.MaximumTracklengthEditField = uieditfield(app.FiltertracksPanel, 'numeric');
            app.MaximumTracklengthEditField.Value = Inf;
            app.MinimumLabel = uilabel(app.FiltertracksPanel);
            app.MinimumLabel.Text = 'Minimum';
            app.MaximumLabel = uilabel(app.FiltertracksPanel);
            app.MaximumLabel.Text = 'Maximum';
            app.MaximumTotalPhotonsEditField = uieditfield(app.FiltertracksPanel, 'numeric');
            app.MaximumTotalPhotonsEditField.Value = Inf;
            app.TracklengthLabel_2 = uilabel(app.FiltertracksPanel);
            app.TracklengthLabel_2.Text = 'Total photons:';
            app.MinimumTotalPhotonsEditField = uieditfield(app.FiltertracksPanel, 'numeric');
            app.MaximumPhotonRateEditField = uieditfield(app.FiltertracksPanel, 'numeric');
            app.MaximumPhotonRateEditField.Value = Inf;
            app.PhotonRateLabel = uilabel(app.FiltertracksPanel);
            app.PhotonRateLabel.Text = 'Photon rate:';
            app.MinimumPhotonRateEditField = uieditfield(app.FiltertracksPanel, 'numeric');
            app.SavefilteredtracksButton = uibutton(app.FiltertracksPanel, 'push');
            app.SavefilteredtracksButton.Text = 'Save filtered tracks';
            app.InspecttracksTab = uitab(app.TabGroup);
            app.InspecttracksTab.Title = 'Inspect tracks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plugin_Tracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end