classdef Plugin_batchProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChecktableforerrorsButton   matlab.ui.control.Button
        AddnewsequenceofstackstotableButton  matlab.ui.control.Button
        ImporttableButton           matlab.ui.control.Button
        ExporttableButton           matlab.ui.control.Button
        AddsettingsfiletoselectedrowsButton  matlab.ui.control.Button
        DeleteselectedrowsButton    matlab.ui.control.Button
        AddnewstackstotableButton   matlab.ui.control.Button
        StartbatchprocessingButton  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        Button                      matlab.ui.control.Button
    end

    
    properties (Access = private)

        CallingApp % Main app object
        
        selectedCells % indices of the selected cells
        tablePassedCheck = 0;

    end

    methods (Access = private)
        
        function [] = initialiseTable(app)
            t = app.UITable;
            t.RowName = 'numbered'; % display row numbers
            t.Data = [{},{},{},{}]; % initialise a row of empty cells
        end
        
        function [] = openStack_batch(app,filepath)
            %OPENSTACH_BATCH
            % create filelist property
            [~,baseName,~] = fileparts(filepath);
            fileList(1).filepath = filepath;
            fileList(1).baseName = baseName;
            fileList(1).id = 1;
            app.CallingApp.filelist = fileList;

            % get some other parameters
            app.CallingApp.numFiles = 1;
            app.CallingApp.getImageDimensions;

            % get path to folder to write results to
            [app.CallingApp.outputDir,~,~] = fileparts(app.CallingApp.filelist(1).filepath);

            % write messages to the log windows
            writeToLog(app.CallingApp,sprintf('Selected single stack:'));
            writeToLog(app.CallingApp,sprintf('     %s',app.CallingApp.filelist(1).baseName));
            writeToErrorLog(app.CallingApp,'...');
            writeToStatusLog(app.CallingApp,'Status: Images loaded.');
        end

        function [] = openStackSequence_batch(app,folder,filenames)
            %OPENSTACKSEQUENCE_BATCH
            % try generating the ordered list of files in the sequence
            filenamesSorted = Utils.sortFilesAscending(filenames);
            
            % create filelist property and get some other parameters
            filelist = struct;
            for j=1:numel(filenamesSorted)
                filename_img_j = filenamesSorted{j};
                [~,filename_img,~] = fileparts(filename_img_j);
                filelist(j).id = j;
                filelist(j).filepath = fullfile(folder,filename_img_j);
                filelist(j).baseName = filename_img;
            end
            app.CallingApp.filelist = filelist;           
            
            % get some other parameters
            app.CallingApp.numFiles = length(app.CallingApp.filelist);
            app.CallingApp.getImageDimensions;
            
            % get path to folder to write results to
            app.CallingApp.outputDir = folder;

            % write messages to the log windows
            writeToErrorLog(app.CallingApp,sprintf('Selected sequence of stacks.'));
            newMessage = sprintf('\n%d stack(s) were found:',app.CallingApp.numFiles);
            writeToLog(app.CallingApp,newMessage);
            for k=1:app.CallingApp.numFiles
                fileName_k = sprintf('%s',app.CallingApp.filelist(k).baseName);
                writeToLog(app.CallingApp,sprintf('     %s',fileName_k));
            end
            writeToStatusLog(app.CallingApp,'Status: Images loaded.');
        end

        function value = loadLocalisationSettings_batch(app,filepath)
            %LOADLOCALISATIONSETTINGS_BATCH Reads in a localisationsettings file as a
            %struct and then fills in all the values in the gui
            %accordingly.
            try
                settings = Utils.readJsonFile(filepath);
                localisationSettings = settings.localisationSettings;
                % replace all values displayed in gui with the values from the
                % settings file
                updateLocalisationSettingsFromStruct(app.CallingApp.DialogLocalisation,localisationSettings);
                value = 1;
            catch
                value = 0; % failed
            end
        end

        function value = loadFilteringSettings_batch(app,filepath)
            %LOADFILTERINGSETTINGS_BATCH Reads in a filtering from settings
            %file as a struct and then fill in all the values in the gui
            %accordingly
            try
                settings = Utils.readJsonFile(filepath);
                filteringSettings = settings.filteringSettings;
                filteringSettings = app.CallingApp.DialogFiltering.replaceStringInfinStructByNumInf(filteringSettings);
                % replace all values displayed in gui with the values from the
                % settings file
                updateFilteringSettingsFromStruct(app.CallingApp.DialogFiltering,filteringSettings);
                value = 1;
            catch
                value = 0; % failed
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            
            initialiseTable(app);

        end

        % Button pushed function: AddnewstackstotableButton
        function AddnewstackstotableButtonPushed(app, event)
            % get paths to image data
            % open a window, asking user to navigate to folder
            app.CallingApp.readLastVisitedDirectories;
            lastPath = app.CallingApp.lastVisitedDirectories.pathLastImg;
            [filename,folder] = uigetfile(fullfile(lastPath,'*.tif'),'MultiSelect','on');
            % NOTE: if multiple files are selected using uigetfile,
            % filename and folder will be cell arrays, so curly brace
            % indexing needs to be used. If only one file is selected,
            % uigetfile will return a string for filename and folder.
            if ~folder % user clicked cancel (uigetfile returned 0)
                return
            else
                numNewEntries = numel(filename);
                numRowsTable = height(app.UITable.Data);
                % if multiple 
                if isempty(app.UITable.Data) % if this is the first entry
                    if iscell(filename) % if multiple files were selected by user
                        for i=1:numNewEntries
                            app.UITable.Data(i,1) = {folder}; % tables can't have text, needs to be in a cell
                            app.UITable.Data(i,2) = {filename{i}};
                            app.UITable.Data(i,3) = {' '};
                            app.UITable.Data(i,4) = {' '};
                        end
                    else % if only one file was selected by user
                        app.UITable.Data(1,1) = {folder}; % tables can't have text, needs to be in a cell
                        app.UITable.Data(1,2) = {filename};
                        app.UITable.Data(1,3) = {' '};
                        app.UITable.Data(1,4) = {' '};
                    end
                else % if this is not the first entry in the table
                    if iscell(filename) % if multiple files were selected by user
                        for i=1:numNewEntries
                            app.UITable.Data(numRowsTable+i,1) = {folder}; % tables can't have text, needs to be in a cell
                            app.UITable.Data(numRowsTable+i,2) = {filename{i}};
                            app.UITable.Data(numRowsTable+i,3) = {' '};
                            app.UITable.Data(numRowsTable+i,4) = {' '};
                        end
                    else % if only one file was selected by user
                        app.UITable.Data(numRowsTable+1,1) = {folder}; % tables can't have text, needs to be in a cell
                        app.UITable.Data(numRowsTable+1,2) = {filename};
                        app.UITable.Data(numRowsTable+1,3) = {' '};
                        app.UITable.Data(numRowsTable+1,4) = {' '};  
                    end
                end
            end
        end

        % Button pushed function: AddnewsequenceofstackstotableButton
        function AddnewsequenceofstackstotableButtonPushed(app, event)
            % open a window, asking user to navigate to folder
            app.CallingApp.readLastVisitedDirectories;
            lastPath = app.CallingApp.lastVisitedDirectories.pathLastImg;
            folder = uigetdir(lastPath,'Select folder containing image stack sequence');
            if ~folder % user clicked cancel (uigetdir returned 0)
                return
            else
                % update the lastPath in the default settings folder
                app.CallingApp.lastVisitedDirectories.pathLastImg = char(folder);
                app.CallingApp.writeLastVisitedDirectories;
                
                % check if image sequence was recorded with Micro-Manager
                % or ThorCam
                filelist_MM = dir(fullfile(folder,'*_MMStack_Default.ome.tif'));
                if isempty(filelist_MM) % ThorCam was (probably) used
                    filelist = Utils.getOrderedImageFilelist_ThorCam(folder);
                else % Micro-Manager was used
                    filelist = Utils.getOrderedImageFilelist_MicroManager(folder);
                end
                
                % populate the batch processing table using data from the
                % filelist
                numFiles = length(filelist);
                for i=1:numFiles
                    if i == 1
                        [~,filename,extension] = fileparts(filelist(1).filepath);
                        filenameString = strcat(filename,extension);
                    else
                        [~,filename,extension] = fileparts(filelist(i).filepath);
                        filenameString = strcat(filenameString,';',strcat(filename,extension));
                    end
                end

                % add to table
                numRowsTable = height(app.UITable.Data);
                if isempty(app.UITable) % if this is the first entry
                    app.UITable.Data(1,1) = {folder}; % tables can't have text, needs to be in a cell
                    app.UITable.Data(1,2) = {filenameString};
                    app.UITable.Data(1,3) = {' '};
                    app.UITable.Data(1,4) = {' '};
                else
                    app.UITable.Data(numRowsTable+1,1) = {folder}; % tables can't have text, needs to be in a cell
                    app.UITable.Data(numRowsTable+1,2) = {filenameString};
                    app.UITable.Data(numRowsTable+1,3) = {' '};
                    app.UITable.Data(numRowsTable+1,4) = {' '};
                end

            end
        end

        % Button pushed function: AddsettingsfiletoselectedrowsButton
        function AddsettingsfiletoselectedrowsButtonPushed(app, event)
            % get path to settings file
            [filename,folder] = uigetfile('*.json');
            if ~folder % user clicked cancel (uigetfile returned 0)
                return
            else
                try                    
                    selectedRows = unique(app.selectedCells(:,1));
                    numSelected = numel(selectedRows);
                    for i=1:numSelected
                        app.UITable.Data(selectedRows(i),3) = {folder};
                        app.UITable.Data(selectedRows(i),4) = {filename};
                    end
                catch
                    % do nothing
                end
            end
        end

        % Button pushed function: DeleteselectedrowsButton
        function DeleteselectedrowsButtonPushed(app, event)
            selectedRows = unique(app.selectedCells(:,1));
            numSelected = numel(selectedRows);
            for i=1:numSelected
                app.UITable.Data(selectedRows(i),:) = [];
            end
        end

        % Button pushed function: ExporttableButton
        function ExporttableButtonPushed(app, event)
            directory = uigetdir('Select output folder');
            if ~directory % user clicked cancel (uigetdir returned 0)
                return
            else
                data = app.UITable.Data; % get data
                variableNames = app.UITable.ColumnName; % get variable names
                T = table(data(:,1),data(:,2),data(:,3),data(:,4),'VariableNames',variableNames);
                writetable(T,fullfile(directory,'batchProcessingTable.txt'),'Delimiter',',');
            end
        end

        % Button pushed function: ImporttableButton
        function ImporttableButtonPushed(app, event)
            % get path to settings file
            [filename,folder] = uigetfile('*.txt');
            if ~filename % user clicked cancel (uigetfile returned 0)
                return
            else
                app.UITable.Data(:,:) = []; % clear all entries
                % replace all entries by read file
                app.UITable.Data = readtable(fullfile(folder,filename),'Format','%s%s%s%s','VariableNamingRule','preserve');
            end
        end

        % Button pushed function: ChecktableforerrorsButton
        function ChecktableforerrorsButtonPushed(app, event)
            % get number of rows in table to loop over
            data = app.UITable.Data;
            numRows = height(data);
    
            % loop over rows in table and process
            for id_row=1:numRows
                newMessage = sprintf('\nTesting row %d/%d: ',id_row,numRows);
                app.CallingApp.writeToLog(newMessage);
                  
                try
                    % get path to images and settings for current file
                    folder_img = data{id_row,1};
                    filename_img = data{id_row,2};
                    filepath_settings = fullfile(data{id_row,3},data{id_row,4});
                    % check if filename_img contains one or more filenames
                    filenames = split(filename_img,';'); % return nx1 cell
                    
                    if numel(filenames) == 1 % it's a stack
                        filepath_img = fullfile(folder_img,filenames{1});
                        [~] = imread(filepath_img);
                    else % it's a sequence of stacks
                        filenamesSorted = Utils.sortFilesAscending(filenames);
                        filepath_img = fullfile(folder_img,filenamesSorted{1});
                        [~] = imread(filepath_img);
                    end
                    
                    newMessage = sprintf('      Image data: OK\n');
                    app.CallingApp.writeToLog(newMessage);

                catch
                    newMessage = sprintf('      Image data: FAILED\n');
                    app.CallingApp.writeToLog(newMessage);
                end
                
                try
                    % read settings file
                    settings = Utils.readJsonFile(filepath_settings);
                    
                    % test localisation settings
                    try settings.localisationSettings;
                        newMessage = sprintf('      Localisation settings: OK\n');
                        app.CallingApp.writeToLog(newMessage);
                    catch
                        newMessage = sprintf('      Localisation settings: FAILED\n');
                        app.CallingApp.writeToLog(newMessage);
                    end
                    
                    % % test filtering settings
                    % try settings.filteringSettings;
                    %     newMessage = sprintf('      Filtering settings: OK\n');
                    %     app.CallingApp.writeToLog(newMessage);
                    % catch
                    %     newMessage = sprintf('      Filtering settings: FAILED\n');
                    %     app.CallingApp.writeToLog(newMessage);
                    % end

                catch
                    newMessage = sprintf('      Localisation settings: FAILED\n');
                    app.CallingApp.writeToLog(newMessage);
                    % newMessage = sprintf('      Filtering settings: FAILED\n');
                    % app.CallingApp.writeToLog(newMessage);
                end
            end
        end

        % Button pushed function: StartbatchprocessingButton
        function StartbatchprocessingButtonPushed(app, event)
            %STARTBATCHPROCESSINGBUTTONPUSHED
            
            % get number of rows in table to loop over
            data = app.UITable.Data;
            numRows = height(data);

            % loop over rows in table and process
            for id_row=1:numRows

                % write a spacer to the log window
                newMessage = sprintf('\n\n++++++++++++++++++++ Processing row %d/%d ++++++++++++++++++++\n\n',id_row,numRows);
                app.CallingApp.writeToLog(newMessage);

                % get path to images and settings for current file
                folder_img = data{id_row,1};
                filename_img = data{id_row,2};
                filepath_settings = fullfile(data{id_row,3},data{id_row,4});

                % check if filename_img contains one or more filenames
                filenames = split(filename_img,';'); % return nx1 cell
                
                if numel(filenames) == 1 % it's a stack
                    % get full path to stack
                    filepath_img = fullfile(folder_img,filenames{1});
                    % load image data
                    openStack_batch(app,filepath_img);
                
                else % it's a sequence of stacks
                    % try generating the ordered list of files in the sequence
                    filenamesSorted = Utils.sortFilesAscending(filenames);
                    % load image data
                    openStackSequence_batch(app,folder_img,filenamesSorted);
                end
                
                % start localisation
                openLocalisationWindow(app.CallingApp);
                if loadLocalisationSettings_batch(app,filepath_settings)
                    startLocalisation_batch(app.CallingApp.DialogLocalisation);
                else
                    newMessage = 'ERROR: failed to load localisation settings.';
                    writeToErrorLog(app.CallingApp, newMessage);
                    return
                end
                
                % % start filtering
                % openFilteringWindow(app.CallingApp);
                % if loadFilteringSettings_batch(app,filepath_settings)
                %     startFiltering_batch(app.CallingApp.DialogFiltering);
                %     fig = showResultLocalisations(app.CallingApp.DialogLocalisation);
                %     fig.Name = strcat(app.CallingApp.filelist(1).baseName,' filtered');
                % else
                %     newMessage = 'ERROR: failed to load filtering settings.';
                %     writeToErrorLog(app.CallingApp, newMessage);
                %     return
                % end
            end

            newMessage = sprintf('\nBatch processing is completed.\n');
            writeToLog(app.CallingApp,newMessage);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %UITABLECELLSELECTION Get the indices of the cells in the table
            %that are selected by the user. Returns an nx2 array, where n
            %is the number of cells, the first column is the row index and
            %the second column is the column index.
            app.selectedCells = event.Indices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '?';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Folder img'; 'Filename img'; 'Folder settings'; 'Filename settings'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.StartbatchprocessingButton = uibutton(app.UIFigure, 'push');
            app.StartbatchprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartbatchprocessingButtonPushed, true);
            app.StartbatchprocessingButton.Text = {'Start batch'; 'processing'};
            app.AddnewstackstotableButton = uibutton(app.UIFigure, 'push');
            app.AddnewstackstotableButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewstackstotableButtonPushed, true);
            app.AddnewstackstotableButton.Text = '+ Add new stack(s) to table';
            app.DeleteselectedrowsButton = uibutton(app.UIFigure, 'push');
            app.DeleteselectedrowsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedrowsButtonPushed, true);
            app.DeleteselectedrowsButton.Text = '- Delete selected row(s)';
            app.AddsettingsfiletoselectedrowsButton = uibutton(app.UIFigure, 'push');
            app.AddsettingsfiletoselectedrowsButton.ButtonPushedFcn = createCallbackFcn(app, @AddsettingsfiletoselectedrowsButtonPushed, true);
            app.AddsettingsfiletoselectedrowsButton.Text = '+ Add settings file to selected rows';
            app.ExporttableButton = uibutton(app.UIFigure, 'push');
            app.ExporttableButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttableButtonPushed, true);
            app.ExporttableButton.Text = 'Export table';
            app.ImporttableButton = uibutton(app.UIFigure, 'push');
            app.ImporttableButton.ButtonPushedFcn = createCallbackFcn(app, @ImporttableButtonPushed, true);
            app.ImporttableButton.Text = 'Import table';
            app.AddnewsequenceofstackstotableButton = uibutton(app.UIFigure, 'push');
            app.AddnewsequenceofstackstotableButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewsequenceofstackstotableButtonPushed, true);
            app.AddnewsequenceofstackstotableButton.Text = '+ Add new sequence of stack(s) to table';
            app.ChecktableforerrorsButton = uibutton(app.UIFigure, 'push');
            app.ChecktableforerrorsButton.ButtonPushedFcn = createCallbackFcn(app, @ChecktableforerrorsButtonPushed, true);
            app.ChecktableforerrorsButton.Text = {'Check table'; 'for errors'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plugin_batchProcessing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end