classdef DialogAppDoubleSlider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AdjustcolormapscalingS0IntensityPanel  matlab.ui.container.Panel
        SlidersupperlimitS0EditField    matlab.ui.control.NumericEditField
        SlidersupperlimitEditField_2Label  matlab.ui.control.Label
        AdjustcolormapscalingDoLPPanel  matlab.ui.container.Panel
        SliderslowerlimitS0EditField    matlab.ui.control.NumericEditField
        SliderslowerlimitEditField_2Label  matlab.ui.control.Label
        MaxSliderS0                     matlab.ui.control.Slider
        MaxSlider_2Label                matlab.ui.control.Label
        MinSliderS0                     matlab.ui.control.Slider
        MinSlider_2Label                matlab.ui.control.Label
        SlidersupperlimitDoLPEditField  matlab.ui.control.NumericEditField
        SlidersupperlimitEditFieldLabel  matlab.ui.control.Label
        SliderslowerlimitDoLPEditField  matlab.ui.control.NumericEditField
        SliderslowerlimitEditFieldLabel  matlab.ui.control.Label
        MaxSliderDoLP                   matlab.ui.control.Slider
        MaxLabel                        matlab.ui.control.Label
        MinSliderDoLP                   matlab.ui.control.Slider
        MinLabel                        matlab.ui.control.Label
    end

    
    properties (Access = private)

        CallingApp % Main app object
        DialogImgData % Main app object
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.CallinApp = mainapp;
            app.DialogImgData = app.CallinApp.DialogImgData;
            
            currenImageVectorized = app.DialogImgData.currentImageUnprocessed(:); % unprocessed frame (offset, bkgnd, gain etc converted)
            minCurrentImgValue  = min(currenImageVectorized);
            maxCurrentImgValue  = max(currenImageVectorized);
            pc5CurrentImgValue  = prctile(currenImageVectorized,5);
            pc95CurrentImgValue = prctile(currenImageVectorized,95);

            % initialise edit fields and slider limits DoLP thresholds
            app.SliderslowerlimitS0EditField.Value = minCurrentImgValue;
            app.SlidersupperlimitS0EditField.Value = maxCurrentImgValue;
            app.MinSliderS0.Limits = [minCurrentImgValue maxCurrentImgValue];
            app.MaxSliderS0.Limits = [minCurrentImgValue maxCurrentImgValue];

            % initialise edit fields and slider limits DoLP thresholds
            app.SliderslowerlimitDoLPEditField.Value = 0;
            app.SlidersupperlimitDoLPEditField.Value = 1;
            app.MinSliderDoLP.Limits = [0 1];
            app.MaxSliderDoLP.Limits = [0 1];

            % initialise slider positions
            app.MinSliderS0.Value = pc5CurrentImgValue;
            app.MaxSliderS0.Value = pc95CurrentImgValue;
            app.MinSliderDoLP.Value = 0;
            app.MaxSliderDoLP.Value = 1;


        end

        % Value changed function: SliderslowerlimitDoLPEditField
        function SliderslowerlimitDoLPEditFieldValueChanged(app, event)
            value = app.SliderslowerlimitDoLPEditField.Value;
            app.MinSliderDoLP.Limits(1) = value;
            app.MaxSliderDoLP.Limits(1) = value;
        end

        % Value changed function: SlidersupperlimitDoLPEditField
        function SlidersupperlimitDoLPEditFieldValueChanged(app, event)
            value = app.SlidersupperlimitDoLPEditField.Value;
            app.MinSliderDoLP.Limits(2) = value;
            app.MaxSliderDoLP.Limits(2) = value;
        end

        % Value changed function: SliderslowerlimitS0EditField
        function SliderslowerlimitS0EditFieldValueChanged(app, event)
            value = app.SliderslowerlimitS0EditField.Value;
            app.MinSliderS0.Limits(1) = value;
            app.MaxSliderS0.Limits(1) = value;
        end

        % Value changed function: SlidersupperlimitS0EditField
        function SlidersupperlimitS0EditFieldValueChanged(app, event)
            value = app.SlidersupperlimitS0EditField.Value;
            app.MinSliderS0.Limits(2) = value;
            app.MaxSliderS0.Limits(2) = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AdjustcolormapscalingDoLPPanel = uipanel(app.UIFigure);
            app.AdjustcolormapscalingDoLPPanel.Title = 'Adjust colormap scaling: DoLP';
            app.MinLabel = uilabel(app.AdjustcolormapscalingDoLPPanel);
            app.MinLabel.Text = 'Min:';
            app.MinSliderDoLP = uislider(app.AdjustcolormapscalingDoLPPanel);
            app.MaxLabel = uilabel(app.AdjustcolormapscalingDoLPPanel);
            app.MaxLabel.Text = 'Max:';
            app.MaxSliderDoLP = uislider(app.AdjustcolormapscalingDoLPPanel);
            app.MaxSliderDoLP.Value = 100;
            app.SliderslowerlimitEditFieldLabel = uilabel(app.AdjustcolormapscalingDoLPPanel);
            app.SliderslowerlimitEditFieldLabel.Text = 'Sliders lower limit:';
            app.SliderslowerlimitDoLPEditField = uieditfield(app.AdjustcolormapscalingDoLPPanel, 'numeric');
            app.SliderslowerlimitDoLPEditField.ValueChangedFcn = createCallbackFcn(app, @SliderslowerlimitDoLPEditFieldValueChanged, true);
            app.SlidersupperlimitEditFieldLabel = uilabel(app.AdjustcolormapscalingDoLPPanel);
            app.SlidersupperlimitEditFieldLabel.Text = 'Sliders upper limit:';
            app.SlidersupperlimitDoLPEditField = uieditfield(app.AdjustcolormapscalingDoLPPanel, 'numeric');
            app.SlidersupperlimitDoLPEditField.ValueChangedFcn = createCallbackFcn(app, @SlidersupperlimitDoLPEditFieldValueChanged, true);
            app.SlidersupperlimitDoLPEditField.Value = 100;
            app.AdjustcolormapscalingS0IntensityPanel = uipanel(app.UIFigure);
            app.AdjustcolormapscalingS0IntensityPanel.Title = 'Adjust colormap scaling: S0 (Intensity)';
            app.MinSlider_2Label = uilabel(app.AdjustcolormapscalingS0IntensityPanel);
            app.MinSlider_2Label.Text = 'Min:';
            app.MinSliderS0 = uislider(app.AdjustcolormapscalingS0IntensityPanel);
            app.MaxSlider_2Label = uilabel(app.AdjustcolormapscalingS0IntensityPanel);
            app.MaxSlider_2Label.Text = 'Max:';
            app.MaxSliderS0 = uislider(app.AdjustcolormapscalingS0IntensityPanel);
            app.MaxSliderS0.Value = 100;
            app.SliderslowerlimitEditField_2Label = uilabel(app.AdjustcolormapscalingS0IntensityPanel);
            app.SliderslowerlimitEditField_2Label.Text = 'Sliders lower limit:';
            app.SliderslowerlimitS0EditField = uieditfield(app.AdjustcolormapscalingS0IntensityPanel, 'numeric');
            app.SliderslowerlimitS0EditField.ValueChangedFcn = createCallbackFcn(app, @SliderslowerlimitS0EditFieldValueChanged, true);
            app.SlidersupperlimitEditField_2Label = uilabel(app.AdjustcolormapscalingS0IntensityPanel);
            app.SlidersupperlimitEditField_2Label.Text = 'Sliders upper limit:';
            app.SlidersupperlimitS0EditField = uieditfield(app.AdjustcolormapscalingS0IntensityPanel, 'numeric');
            app.SlidersupperlimitS0EditField.ValueChangedFcn = createCallbackFcn(app, @SlidersupperlimitS0EditFieldValueChanged, true);
            app.SlidersupperlimitS0EditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppDoubleSlider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end