classdef DialogAppImgData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AdjustscalingButton  matlab.ui.control.Button
        FormatDropDown       matlab.ui.control.DropDown
        FormatDropDownLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu3         matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        
        CallingApp % Main app object

    end

    properties (Access = public)
        
        settings % struct, information about dataset and class instances for processing
        Proc % instance of the class Process
        currentImageUnprocessed % current unprocessed frame
        currentImg % current processed frame being displayed


        S0_min % double, lower threshold S0 (intensity) from slider
        S0_max % double, upper threshold S0 (intensity) from slider
        DoLP_min % double, lower threshold DoLP from slider
        DoLP_max % double, upper threshold DoLP from slider

    end
    
    methods (Access = public)

        function [] = rerenderCurrentFrame(app,pathToImg)
            img = double(imread(pathToImg));
            img = (img - app.settings.camOffset - app.settings.bkgnd)./(app.settings.camGain*app.settings.QE);
            img = app.Proc.makeDimensionsEven(img);
            app.currentImageUnprocessed = img;
            convertCurrentImg(app);
            imagesc(app.UIAxes,app.currentImg);
        end
        
        function [] = prepareForConversionStack(app,pathToImg)
            %PREPAREFORCONVERSIONSTACK
            % check if an image is loaded
            try app.currentImageUnprocessed = double(imread(pathToImg));
            catch; writeToErrorLog(app.CallingApp,'ERROR: No images are loaded.'); return; end
            
            if app.CallingApp.settingsDiffrLoaded % if settings were loaded properly
                
                % update settings with dimensions of images that are currently loaded
                [nx,ny] = size(app.currentImageUnprocessed);
                if mod(nx,2); nx = nx - 1; end % round down to nearest even number (software will force to be even)
                if mod(ny,2); ny = ny - 1; end % round down to nearest even number (software will force to be even)
                app.settings.imgSize = [nx,ny];

                % create instances of the Camera, Microscope and Process classes
                createInstanceProcessForDiffrProcessing(app);
                
                % process first frame
                convertCurrentImg(app);
                imagesc(app.UIAxes,app.currentImg);
            else
                writeToErrorLog(app,"ERROR: Settings were not saved. Try again.")
            end
        end
    end

    methods (Access = private)
            
        function [] = createInstanceProcessForDiffrProcessing(app)                
            Cam = Camera(app.settings.camPixSize, app.settings.imgSize,app.settings.mosaicUnitArray,app.settings.camOffset,app.settings.camGain,1,app.settings.QE);
            Scope = Microscope(Cam,0,app.settings.magnification,0);
            locParamsDummy.w.xy = 0; locParamsDummy.w.phi = 0; locParamsDummy.w.theta = 0;
            bkgndEstimation.method = 'none';
            app.Proc = Process(Scope,Cam,bkgndEstimation,app.settings.methodInterpolation,locParamsDummy);
        end

        function [] = convertCurrentImg(app)

            if strcmp(app.FormatDropDown.Value,'Unprocessed')
                app.currentImg = app.currentImageUnprocessed;

            else
                [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(app.currentImageUnprocessed);
                I0   = app.removeOnePixelBorder(I0);
                Ip45 = app.removeOnePixelBorder(Ip45);
                I90  = app.removeOnePixelBorder(I90);
                In45 = app.removeOnePixelBorder(In45);

                switch app.FormatDropDown.Value

                    case 'Channels'
                        app.currentImg = [[I0 Ip45];[I90 In45]];

                    case 'S0 (Intensity)'
                        app.currentImg = (I0 + Ip45 + I90 + In45)/2;

                    case 'S1'
                        app.currentImg = I0 - I90;

                    case 'S2'
                        app.currentImg = Ip45 - In45;

                    case 'DoLP'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        app.currentImg = sqrt((S1.^2 + S2.^2)./(S0.^2));

                    case 'AoLP'
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        app.currentImg = (1/2)*atan2(S2,S1);

                    case 'AoLP/DoLP/S0'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        AoLP = (1/2)*atan2(S2,S1);
                        DoLP = sqrt((S1.^2 + S2.^2)./(S0.^2));

                        H = (AoLP + pi/2)/pi;
                        S = (DoLP - app.DoLP_min)./(app.DoLP_max - app.DoLP_min); S(S < 0) = 0; S(S > 1) = 1;
                        V = (S0 - app.S0_min)./(app.S0_max - app.S0_min); V(V < 0) = 0; V(V > 1) = 1;

                        app.currentImg = hsv2rgb(H,S,V);

                    case 'DoLP/S0'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        DoLP = sqrt((S1.^2 + S2.^2)./(S0.^2));

                        weight = (S0 - app.S0_min)./(app.S0_max - app.S0_min);
                        weight(weight < 0) = 0; weight(weight > 1) = 1;

                        img = (DoLP - app.DoLP_min)./(app.DoLP_max - app.DoLP_min);
                        img(img < 0) = 0;
                        img(img > 1) = 1;
                        img = floor(img*255);

                        rgb = ind2rgb(img, parula(255));
                        rgb(:,:,1) = rgb(:,:,1).*weight;
                        rgb(:,:,2) = rgb(:,:,2).*weight;
                        rgb(:,:,3) = rgb(:,:,3).*weight;
                        app.currentImg = rgb;
                end
            end
        end

    end

    methods (Static)

        function img = removeOnePixelBorder(img)
            img = img(2:end-1,2:end-1);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp; % Store main app in property for CloseRequestFcn to use
                        
            % get processing settings
            app.settings = struct;
            app.settings.camOffset           = app.CallingApp.settingsDiffr.camOffset;
            app.settings.camGain             = app.CallingApp.settingsDiffr.camGain;
            app.settings.bkgnd               = app.CallingApp.settingsDiffr.bkgnd;
            app.settings.QE                  = app.CallingApp.settingsDiffr.QE;
            app.settings.camPixSize          = app.CallingApp.settingsDiffr.camPixSize;
            app.settings.magnification       = app.CallingApp.settingsDiffr.magnification;
            app.settings.mosaicUnitArray     = app.CallingApp.settingsDiffr.mosaicUnitArray;
            app.settings.methodInterpolation = app.CallingApp.settingsDiffr.methodInterpolation;
            app.settings.imgSize             = [0 0]; % will be filled in later

            xlabel(app.UIAxes,'x (pix)');
            ylabel(app.UIAxes,'y (pix)');
            
            c = colorbar(app.UIAxes);
            c.Label.String = 'Counts (ADU)';
            c.TickDirection = 'out'; 
            colormap(app.UIAxes,"gray")

            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            
            % initialise limits
            app.X_min    = 0;
            app.X_max    = 0;
            app.S0_min   = 0;
            app.S0_max   = 1000;
            app.DoLP_min = 0;
            app.DoLP_max = 1;
            
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            writeToErrorLog(app.CallingApp,sprintf('Switched to format: %s',app.FormatDropDown.Value));
        end

        % Button pushed function: AdjustscalingButton
        function AdjustscalingButtonPushed(app, event)
            switch app.FormatDropDown.Value
                case {'Unprocessed','Channels','S0 (Intensity)','S1','S2','DoLP'}
                    app.DialogSingleSlider = DialogAppSingleSlider(app);
                case {'AoLP/DoLP/S0','DoLP/S0'}
                    app.DialogDoubleSlider = DialogAppDoubleSlider(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FormatDropDownLabel = uilabel(app.UIFigure);
            app.FormatDropDownLabel.Text = 'Format:';
            app.FormatDropDown = uidropdown(app.UIFigure);
            app.FormatDropDown.Items = {'Unprocessed', 'Channels', 'S0 (Intensity)', 'S1', 'S2', 'AoLP', 'DoLP', 'AoLP/DoLP/S0', 'DoLP/S0'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = 'Channels';
            app.AdjustscalingButton = uibutton(app.UIFigure, 'push');
            app.AdjustscalingButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustscalingButtonPushed, true);
            app.AdjustscalingButton.Text = 'Adjust scaling';
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppImgData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end