classdef DialogAppSingleSlider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AdjustcolormapscalingPanel  matlab.ui.container.Panel
        SlidersupperlimitEditField  matlab.ui.control.NumericEditField
        SlidersupperlimitEditFieldLabel  matlab.ui.control.Label
        SliderslowerlimitEditField  matlab.ui.control.NumericEditField
        SliderslowerlimitEditFieldLabel  matlab.ui.control.Label
        MaxSlider                   matlab.ui.control.Slider
        MaxLabel                    matlab.ui.control.Label
        MinSlider                   matlab.ui.control.Slider
        MinLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)

        CallingApp % Main app object
        DialogImgData % Main app object

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.CallinApp = mainapp;
            app.DialogImgData = app.CallinApp.DialogImgData;
            
            currenImageVectorized = app.DialogImgData.currentImg(:);
            minCurrentImgValue  = min(currenImageVectorized);
            maxCurrentImgValue  = max(currenImageVectorized);
            pc5CurrentImgValue  = prctile(currenImageVectorized,5);
            pc95CurrentImgValue = prctile(currenImageVectorized,95);

            % initialise edit fields and slider limits
            app.SliderslowerlimitEditField.Value = minCurrentImgValue;
            app.SlidersupperlimitEditField.Value = maxCurrentImgValue;
            app.MinSlider.Limits = [minCurrentImgValue maxCurrentImgValue];
            app.MaxSlider.Limits = [minCurrentImgValue maxCurrentImgValue];

            % initialise slider positions
            app.MinSlider.Value = pc5CurrentImgValue;
            app.MaxSlider.Value = pc95CurrentImgValue;

        end

        % Value changed function: SliderslowerlimitEditField
        function SliderslowerlimitEditFieldValueChanged(app, event)
            value = app.SliderslowerlimitEditField.Value;
            app.MinSlider.Limits(1) = value;
            app.MaxSlider.Limits(1) = value;
        end

        % Value changed function: SlidersupperlimitEditField
        function SlidersupperlimitEditFieldValueChanged(app, event)
            value = app.SlidersupperlimitEditField.Value;
            app.MinSlider.Limits(2) = value;
            app.MaxSlider.Limits(2) = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AdjustcolormapscalingPanel = uipanel(app.UIFigure);
            app.AdjustcolormapscalingPanel.Title = 'Adjust colormap scaling';
            app.MinLabel = uilabel(app.AdjustcolormapscalingPanel);
            app.MinLabel.Text = 'Min:';
            app.MinSlider = uislider(app.AdjustcolormapscalingPanel);
            app.MaxLabel = uilabel(app.AdjustcolormapscalingPanel);
            app.MaxLabel.Text = 'Max:';
            app.MaxSlider = uislider(app.AdjustcolormapscalingPanel);
            app.MaxSlider.Value = 100;
            app.SliderslowerlimitEditFieldLabel = uilabel(app.AdjustcolormapscalingPanel);
            app.SliderslowerlimitEditFieldLabel.Text = 'Sliders lower limit:';
            app.SliderslowerlimitEditField = uieditfield(app.AdjustcolormapscalingPanel, 'numeric');
            app.SliderslowerlimitEditField.ValueChangedFcn = createCallbackFcn(app, @SliderslowerlimitEditFieldValueChanged, true);
            app.SlidersupperlimitEditFieldLabel = uilabel(app.AdjustcolormapscalingPanel);
            app.SlidersupperlimitEditFieldLabel.Text = 'Sliders upper limit:';
            app.SlidersupperlimitEditField = uieditfield(app.AdjustcolormapscalingPanel, 'numeric');
            app.SlidersupperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @SlidersupperlimitEditFieldValueChanged, true);
            app.SlidersupperlimitEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppSingleSlider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end