classdef polCAMLive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        polCAMLiveMenu                  matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        FiguresettingsMenu              matlab.ui.container.Menu
        ThemeMenu                       matlab.ui.container.Menu
        LightMenu                       matlab.ui.container.Menu
        DarkMenu                        matlab.ui.container.Menu
        FontsizeMenu                    matlab.ui.container.Menu
        CustomiseparameterdefaultsMenu  matlab.ui.container.Menu
        CustomiseLocalisationMenu       matlab.ui.container.Menu
        CustomiseFilteringMenu          matlab.ui.container.Menu
        CustomiseDriftcorrectionMenu    matlab.ui.container.Menu
        CustomisefiguredefaultsMenu     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        WebsiteMenu                     matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        ReportabugMenu                  matlab.ui.container.Menu
        UpdateMenu                      matlab.ui.container.Menu
        Lamp                            matlab.ui.control.Lamp
        SetwatchedfolderButton          matlab.ui.control.Button
        StoppauseConversionButton       matlab.ui.control.StateButton
        StartConversionButton           matlab.ui.control.StateButton
        ConversionsettingsButton        matlab.ui.control.Button
        StatusLogTextArea               matlab.ui.control.TextArea
        ErrorLogTextArea                matlab.ui.control.TextArea
        LogTextArea                     matlab.ui.control.TextArea
        ContextMenu                     matlab.ui.container.ContextMenu
        ClearlogMenu                    matlab.ui.container.Menu
        DisplaypathtofolderMenu         matlab.ui.container.Menu
    end


    properties (Access = public)

        DialogImgData % dialog app to display image data
        DialogSettingsDiffr % dialog app for getting settings diffraction limited processing

        rootDir % full path to the root folder of the app
        
        watchedDirectory % path to user-selected folder that app watches for new files
        watchDirectory % bool, 1 while watching folder

        numFiles % number of files found for processing

        filelist % list of files to process
        numFrames % number of frames in the first stack
        imgSize % dimensions of the first stack
        outputDir % path to data folder (to write results to)
        
        settingsDiffr % struct, contains parameters for diffraction limited processing
        settingsDiffrLoaded % bool, to check if settingsDiffr was correctly loaded
        
        watchedFolderSelected % bool, to check if folder was selected

    end

    methods (Access = private)
                
        function [] = getImageDimensions(app)
            imgInfo = imfinfo(app.filelist(1).filepath);
            app.imgSize = [imgInfo(1).Height imgInfo(1).Width];
            app.numFrames = length(imgInfo);
        end
        
        function [] = enableSettingsButton(app)
            set(app.ConversionsettingsButton,'Enable','on')
        end

        function [] = dissableSettingsButton(app)
            set(app.ConversionsettingsButton,'Enable','off')
        end

        function [] = enableSetWatchedFolderButton(app)
            set(app.SetwatchedfolderButton,'Enable','on')
        end

        function [] = dissableSetWatchedFolderButton(app)
            set(app.SetwatchedfolderButton,'Enable','off')
        end

        function [] = enableStartButton(app)
            set(app.StartConversionButton,'Enable','on')
        end

        function [] = dissableStartButton(app)
            set(app.StartConversionButton,'Enable','off')
        end

        function [] = enableStopButton(app)
            set(app.StoppauseConversionButton,'Enable','on')
        end

        function [] = dissableStopButton(app)
            set(app.StoppauseConversionButton,'Enable','off')
        end

    end
    
    methods (Access = public)
        
        function [] = clearLog(app)
            app.LogTextArea.Value = '';
        end

        function [] = clearErrorLog(app)
            app.ErrorLogTextArea.Value = '';
        end

        function [] = writeToLog(app,newMessage)
            newMessage = [app.LogTextArea.Value(:)', newMessage];
            app.LogTextArea.Value = newMessage;
            scroll(app.LogTextArea,'bottom')
        end

        function [] = writeToErrorLog(app,newMessage)
            clearErrorLog(app);
            app.ErrorLogTextArea.Value = newMessage;
        end

        function [] = writeToStatusLog(app,newMessage)
            app.StatusLogTextArea.Value = newMessage;
        end

        function [] = appendToLastLineLog(app,newMessage)
            app.LogTextArea.Value{end} = strcat(app.LogTextArea.Value{end},newMessage);
            newMessage = app.LogTextArea.Value(:)';
            app.LogTextArea.Value = newMessage;
        end

        function [] = writeHeaderToLog(app)
            clearLog(app);
            app.LogTextArea.Value = sprintf('\n+++++  polCAM Live (v1.0)  +++++\n');
            writeToLog(app,sprintf('\n%s',datetime));

            newMessage = sprintf('\npolCAMLive allows processing data live during acquisition. This app assumes you are writing away new frames as single tif files (not stacks). If you are using Micro-Manager for acquisition, you can do this by selecting "Separate image files" (instead of the default "Image stack file") as saving format, under the "Save Images" section at the bottom of the "Multi-Dimensional Acquisision" window. This app will detect new files that are written in the folder and process them.');
            writeToLog(app,newMessage);
            
            newMessage = sprintf('\nTo start live conversion, first specify the desired conversion setting and let the app know what folder it should watch. Once the "Start live conversion" button is pressed, the app will start looking for new tif files in the selected folder. If it finds a tif file, a new window will pop up that shows the converted image. This will be updated when new files appear in the folder. If the app finds multiple new files at one time, it will only process and convert the first one to avoid delays when processing is slower than acquisition (e.g. large field of view at high frame rate).');
            writeToLog(app,newMessage);

            newMessage = sprintf('\n\nCopyright 2022, Ezra Bruggeman\nLee Lab, Yusuf Hamied Dept. of Chemistry, University of Cambridge, UK');
            writeToLog(app,newMessage);
            newMessage = sprintf('\nCite as: Bruggeman et al., Single-molecule orientation localisation microscopy using a polarisation camera. Journal, Vol(Num):pp-pp, 2022.\n');
            writeToLog(app,newMessage);
            newMessage = sprintf('--------------------------------------------------------------');
            writeToLog(app,newMessage);
        end

    end

    methods (Static)

        function [] = bringWindowToForeground(handle)
            handle.UIFigure.Visible = 'off';
            handle.UIFigure.Visible = 'on';
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
            [app.rootDir,~,~] = fileparts(mfilename('fullpath'));
            fprintf('%s\n',app.rootDir);
            addpath(fullfile(app.rootDir,'dialog apps'))
            addpath(fullfile(app.rootDir,'lib'))
            addpath(fullfile(app.rootDir,'docs'))
            % addpath(fullfile(app.rootDir,'docs','icon_file.png'))
            % addpath(fullfile(app.rootDir,'docs','icon_play.png'))
            % addpath(fullfile(app.rootDir,'docs','icon_settings.png'))
            % addpath(fullfile(app.rootDir,'docs','icon_stop.png'))

            enableSettingsButton(app);
            enableSetWatchedFolderButton(app);
            dissableStartButton(app);
            dissableStopButton(app);
            
            % turn LED off
            app.Lamp.Color = [0.65,0.65,0.65];

            app.settingsDiffrLoaded   = 0;
            app.watchedFolderSelected = 0;

            app.watchDirectory = 0;

            warning('off','all')
            writeHeaderToLog(app);

        end

        % Menu selected function: ClearlogMenu
        function ClearlogMenuSelected(app, event)
            clearLog(app); writeHeaderToLog(app);
        end

        % Menu selected function: DisplaypathtofolderMenu
        function DisplaypathtofolderMenuSelected(app, event)
            if ~isempty(app.watchedDirectory)
                writeToLog(app,sprintf('\nSelected folder: %s',app.watchedDirectory));
            else
                writeToLog(app,sprintf('\nNo folder selected.'));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % close main app and all dialog apps
            delete(app);
            warning('on','all')
        end

        % Button pushed function: ConversionsettingsButton
        function ConversionsettingsButtonPushed(app, event)
            app.DialogSettingsDiffr = DialogAppSettingsDiffractionLimited(app);
            app.settingsDiffrLoaded = 1; % BETTER TO DO THIS IN THE DIALOG APP
            writeToErrorLog(app,'...');
            if app.watchedFolderSelected
                enableStartButton(app);
            end
        end

        % Button pushed function: SetwatchedfolderButton
        function SetwatchedfolderButtonPushed(app, event)
            % open a window, asking user to navigate to folder
            app.watchedDirectory = uigetdir('','Select folder containing image stack sequence');
            if ~app.watchedDirectory % user clicked cancel (uigetdir returned 0)
                return
            end 
            app.watchedFolderSelected = 1;
            writeToStatusLog(app,'Status: Folder selected.');
            if app.settingsDiffrLoaded
                enableStartButton(app);
            end
        end

        % Value changed function: StartConversionButton
        function StartConversionButtonValueChanged(app, event)
            
            % block the start button
            dissableStartButton(app);
            
            % turn LED on
            app.Lamp.Color = [0.00,1.00,0.00];

            % unblock the stop button
            app.StoppauseConversionButton.Value = 0;
            enableStopButton(app);
            
            app.watchDirectory = 1;
            writeToStatusLog(app,'Watching folder...');

            % get list of all tif files in the watched folder
            dirContent = dir(fullfile(app.watchedDirectory,'*.tif'));
            filenames = {dirContent.name};
            currentFiles = filenames;
            
            % get first frame to initialise conversion
            while app.watchDirectory
                % get list of files in watched folder
                dirContent = dir(fullfile(app.watchedDirectory,'*.tif'));
                filenames = {dirContent.name};
                % get a list of only the new files
                newFiles = setdiff(filenames,currentFiles);
                if ~isempty(newFiles) % if there are new files
                    % process and display first frame
                    pathToFirstFrame = fullfile(app.watchedDirectory,newFiles{1});
                    app.DialogImgData = DialogAppImgData(app);
                    prepareForConversionStack(app.DialogImgData,pathToFirstFrame);
                    % update currenFiles to include the new files
                    currentFiles = filenames;
                    writeToLog(app,sprintf('\nFirst frame was read. Conversion has been initialised.'))
                    break % exit the while loop
                end
                pause(0.001) % wait 1 millisecond
            end

            % watch the folder for new files until the user selects 'Process > Stop conversion'
            while app.watchDirectory
                % get list of files in watched folder
                dirContent = dir(fullfile(app.watchedDirectory,'*.tif'));
                filenames = {dirContent.name};
                % get a list of only the new files
                newFiles = setdiff(filenames,currentFiles);
                if ~isempty(newFiles) % if there are new files
                    % process and display new frame(s)
                    pathToImg = fullfile(app.watchedDirectory,newFiles{1});
                    rerenderCurrentFrame(app.DialogImgData,pathToImg);
                    % update currenFiles to include the new files
                    currentFiles = filenames;
                    writeToLog(app,sprintf('\n%s',newFiles{1}))
                end
                pause(0.001) % wait 1 millisecond
            end
            writeToStatusLog(app,'Stopped watching folder.');
        end

        % Value changed function: StoppauseConversionButton
        function StoppauseConversionButtonValueChanged(app, event)
            % block the stop button
            dissableStopButton(app);
            
            % turn LED off
            app.Lamp.Color = [0.65,0.65,0.65];

            % unblock the start button
            app.StartConversionButton.Value = 0;
            enableStartButton(app);
            
            app.watchDirectory = 0;
            writeToErrorLog(app,'...')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.polCAMLiveMenu = uimenu(app.UIFigure);
            app.polCAMLiveMenu.Text = 'polCAMLive';
            app.AboutMenu = uimenu(app.polCAMLiveMenu);
            app.AboutMenu.Text = 'About';
            app.SettingsMenu = uimenu(app.polCAMLiveMenu);
            app.SettingsMenu.Text = 'Settings';
            app.FiguresettingsMenu = uimenu(app.SettingsMenu);
            app.FiguresettingsMenu.Text = 'Figure settings...';
            app.ThemeMenu = uimenu(app.FiguresettingsMenu);
            app.ThemeMenu.Text = 'Theme';
            app.LightMenu = uimenu(app.ThemeMenu);
            app.LightMenu.Text = 'Light';
            app.DarkMenu = uimenu(app.ThemeMenu);
            app.DarkMenu.Text = 'Dark';
            app.FontsizeMenu = uimenu(app.FiguresettingsMenu);
            app.FontsizeMenu.Text = 'Fontsize...';
            app.CustomiseparameterdefaultsMenu = uimenu(app.SettingsMenu);
            app.CustomiseparameterdefaultsMenu.Text = 'Customise parameter defaults...';
            app.CustomiseLocalisationMenu = uimenu(app.CustomiseparameterdefaultsMenu);
            app.CustomiseLocalisationMenu.Text = 'Localisation';
            app.CustomiseFilteringMenu = uimenu(app.CustomiseparameterdefaultsMenu);
            app.CustomiseFilteringMenu.Text = 'Filtering';
            app.CustomiseDriftcorrectionMenu = uimenu(app.CustomiseparameterdefaultsMenu);
            app.CustomiseDriftcorrectionMenu.Text = 'Drift correction';
            app.CustomisefiguredefaultsMenu = uimenu(app.SettingsMenu);
            app.CustomisefiguredefaultsMenu.Text = 'Customise figure defaults...';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.WebsiteMenu = uimenu(app.HelpMenu);
            app.WebsiteMenu.Text = 'Website...';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.ReportabugMenu = uimenu(app.HelpMenu);
            app.ReportabugMenu.Text = 'Report a bug';
            app.UpdateMenu = uimenu(app.HelpMenu);
            app.UpdateMenu.Text = 'Update...';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Editable = 'off';
            app.ErrorLogTextArea = uitextarea(app.UIFigure);
            app.ErrorLogTextArea.Editable = 'off';
            app.ErrorLogTextArea.Value = {'Hello!'};
            app.StatusLogTextArea = uitextarea(app.UIFigure);
            app.StatusLogTextArea.Editable = 'off';
            app.StatusLogTextArea.Value = {'Status: No folder selected.'};
            app.ConversionsettingsButton = uibutton(app.UIFigure, 'push');
            app.ConversionsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ConversionsettingsButtonPushed, true);
            app.ConversionsettingsButton.Text = {'Conversion'; 'settings'};
            app.StartConversionButton = uibutton(app.UIFigure, 'state');
            app.StartConversionButton.ValueChangedFcn = createCallbackFcn(app, @StartConversionButtonValueChanged, true);
            app.StartConversionButton.Text = {'Start'; 'Conversion'};
            app.StoppauseConversionButton = uibutton(app.UIFigure, 'state');
            app.StoppauseConversionButton.ValueChangedFcn = createCallbackFcn(app, @StoppauseConversionButtonValueChanged, true);
            app.StoppauseConversionButton.Text = {'Stop/pause'; 'Conversion'};
            app.SetwatchedfolderButton = uibutton(app.UIFigure, 'push');
            app.SetwatchedfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetwatchedfolderButtonPushed, true);
            app.SetwatchedfolderButton.Text = {'Set'; ' watched folder'};
            app.Lamp = uilamp(app.UIFigure);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ClearlogMenu = uimenu(app.ContextMenu);
            app.ClearlogMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearlogMenuSelected, true);
            app.ClearlogMenu.Text = 'Clear log';
            app.DisplaypathtofolderMenu = uimenu(app.ContextMenu);
            app.DisplaypathtofolderMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaypathtofolderMenuSelected, true);
            app.DisplaypathtofolderMenu.Separator = 'on';
            app.DisplaypathtofolderMenu.Text = 'Display path to folder';
            % Assign app.ContextMenu
            app.LogTextArea.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = polCAMLive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end