classdef DialogAppFiltering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Button                        matlab.ui.control.Button
        MakecurrentsettingsdefaultCheckBox  matlab.ui.control.CheckBox
        NumberoflocalisationsafterfilteringEditField  matlab.ui.control.NumericEditField
        NumberoflocalisationsafterfilteringEditFieldLabel  matlab.ui.control.Label
        NumberoflocalisationsbeforefilteringEditField  matlab.ui.control.NumericEditField
        NumberoflocalisationsbeforefilteringEditFieldLabel  matlab.ui.control.Label
        FilteringparametersPanel      matlab.ui.container.Panel
        ExporttoexistingsettingsfileButton  matlab.ui.control.Button
        ExportsettingsButton          matlab.ui.control.Button
        ImportsettingsButton          matlab.ui.control.Button
        RestoredefaultsettingsButton  matlab.ui.control.Button
        ClearallfieldsButton          matlab.ui.control.Button
        ClusterIdMinEditField         matlab.ui.control.NumericEditField
        FramesboundLabel_2            matlab.ui.control.Label
        ClusterIdMaxEditField         matlab.ui.control.NumericEditField
        FramesBoundMinEditField       matlab.ui.control.NumericEditField
        FramesboundLabel              matlab.ui.control.Label
        FramesBoundMaxEditField       matlab.ui.control.NumericEditField
        MaxLabel                      matlab.ui.control.Label
        MinLabel                      matlab.ui.control.Label
        UncertaintyxyMaxEditField     matlab.ui.control.NumericEditField
        avgDoLPMaxEditField           matlab.ui.control.NumericEditField
        SigmayMaxEditField            matlab.ui.control.NumericEditField
        SigmaxMaxEditField            matlab.ui.control.NumericEditField
        BkgndMaxEditField             matlab.ui.control.NumericEditField
        PhotonsMaxEditField           matlab.ui.control.NumericEditField
        netDoLPMaxEditField           matlab.ui.control.NumericEditField
        ThetaMaxEditField             matlab.ui.control.NumericEditField
        FrameMaxEditField             matlab.ui.control.NumericEditField
        PhiMaxEditField               matlab.ui.control.NumericEditField
        yMaxEditField                 matlab.ui.control.NumericEditField
        xMaxEditField                 matlab.ui.control.NumericEditField
        UncertaintyxyMinEditField     matlab.ui.control.NumericEditField
        UncertaintyxyEditFieldLabel   matlab.ui.control.Label
        SigmayMinEditField            matlab.ui.control.NumericEditField
        SigmayEditFieldLabel          matlab.ui.control.Label
        SigmaxMinEditField            matlab.ui.control.NumericEditField
        SigmaxEditFieldLabel          matlab.ui.control.Label
        BkgndMinEditField             matlab.ui.control.NumericEditField
        BkgndEditFieldLabel           matlab.ui.control.Label
        PhotonsMinEditField           matlab.ui.control.NumericEditField
        PhotonsEditFieldLabel         matlab.ui.control.Label
        netDoLPMinEditField           matlab.ui.control.NumericEditField
        netDolpEditFieldLabel         matlab.ui.control.Label
        avgDoLPMinEditField           matlab.ui.control.NumericEditField
        avgDoLPEditFieldLabel         matlab.ui.control.Label
        ThetaMinEditField             matlab.ui.control.NumericEditField
        ThetaEditFieldLabel           matlab.ui.control.Label
        PhiMinEditField               matlab.ui.control.NumericEditField
        PhiEditFieldLabel             matlab.ui.control.Label
        yMinEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        xMinEditField                 matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        FrameMinEditField             matlab.ui.control.NumericEditField
        FrameEditFieldLabel           matlab.ui.control.Label
        SavefilteredlocalisationsButton  matlab.ui.control.Button
        PlotcolourcodedscatterplotPanel  matlab.ui.container.Panel
        PlotscatterplotButton         matlab.ui.control.Button
        ParameterScatterPlotDropDown  matlab.ui.control.DropDown
        ParameterDropDown_2Label      matlab.ui.control.Label
        Plot2DhistogramPanel          matlab.ui.container.Panel
        Plothistogram2DButton         matlab.ui.control.Button
        Parameter2Hist2DDropDown      matlab.ui.control.DropDown
        Parameter2DropDownLabel       matlab.ui.control.Label
        Parameter1Hist2DDropDown      matlab.ui.control.DropDown
        Parameter1DropDownLabel       matlab.ui.control.Label
        Plot1DhistogramPanel          matlab.ui.container.Panel
        Plothistogram1DButton         matlab.ui.control.Button
        ParameterHist1DDropDown       matlab.ui.control.DropDown
        ParameterLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp % Main app object
        
        paramScatterplot % dropdown option scatter plot
        paramHist1D % dropdown option 1D histogram
        param1Hist2D % dropdown option 1 2D histogram
        param2Hist2D % dropdown option 2 2D histogram
        
        keep % bool 1D array, 1 keep localisation, 0 remove localisation

        filteringSettings % struct to contain all min/max settings from gui

    end
    
    methods (Access = private)

        function [] = updateFilteringSettingsPropertyFromGui(app)
            %UPDATEFILTERINGSETTINGSPROPERTYFROMGUI Reads all values
            %currently displayed in the gui and updates the property
            %app.fitleringSettings accordingly.

            app.filteringSettings = struct;
            
            app.filteringSettings.frame_min = app.FrameMinEditField.Value;
            app.filteringSettings.frame_max = app.FrameMaxEditField.Value;
            app.filteringSettings.x_min = app.xMinEditField.Value;
            app.filteringSettings.x_max = app.xMaxEditField.Value;
            app.filteringSettings.y_min = app.yMinEditField.Value;
            app.filteringSettings.y_max = app.yMaxEditField.Value;
            app.filteringSettings.phi_min = app.PhiMinEditField.Value;
            app.filteringSettings.phi_max = app.PhiMaxEditField.Value;
            app.filteringSettings.theta_min = app.ThetaMinEditField.Value;
            app.filteringSettings.theta_max = app.ThetaMaxEditField.Value;
            app.filteringSettings.avgDoLP_min = app.avgDoLPMinEditField.Value;
            app.filteringSettings.avgDoLP_max = app.avgDoLPMaxEditField.Value;
            app.filteringSettings.netDoLP_min = app.netDoLPMinEditField.Value;
            app.filteringSettings.netDoLP_max = app.netDoLPMaxEditField.Value;
            app.filteringSettings.photons_min = app.PhotonsMinEditField.Value;
            app.filteringSettings.photons_max = app.PhotonsMaxEditField.Value;
            app.filteringSettings.bkgnd_min = app.BkgndMinEditField.Value;
            app.filteringSettings.bkgnd_max = app.BkgndMaxEditField.Value;
            app.filteringSettings.sigmax_min = app.SigmaxMinEditField.Value;
            app.filteringSettings.sigmax_max = app.SigmaxMaxEditField.Value;
            app.filteringSettings.sigmay_min = app.SigmayMinEditField.Value;
            app.filteringSettings.sigmay_max = app.SigmayMaxEditField.Value;
            app.filteringSettings.uncertainty_xy_min = app.UncertaintyxyMinEditField.Value;
            app.filteringSettings.uncertainty_xy_max = app.UncertaintyxyMaxEditField.Value;
            app.filteringSettings.framesBound_min = app.FramesBoundMinEditField.Value;
            app.filteringSettings.framesBound_max = app.FramesBoundMaxEditField.Value;
            app.filteringSettings.clusterId_min = app.ClusterIdMinEditField.Value;
            app.filteringSettings.clusterId_max = app.ClusterIdMaxEditField.Value;
        end

        function [] = updateKeep(app)
            %UPDATEKEEP

            % first update using parameters that all algorithms have in common
            keepBase = (app.CallingApp.locData.frameGrouped > app.filteringSettings.frame_min).*...
                       (app.CallingApp.locData.frameGrouped < app.filteringSettings.frame_max).*...
                       (app.CallingApp.locData.x > app.filteringSettings.x_min).*...
                       (app.CallingApp.locData.x < app.filteringSettings.x_max).*...
                       (app.CallingApp.locData.y > app.filteringSettings.y_min).*...
                       (app.CallingApp.locData.y < app.filteringSettings.y_max).*...
                       (app.CallingApp.locData.phi > app.filteringSettings.phi_min*pi/180).*...
                       (app.CallingApp.locData.phi < app.filteringSettings.phi_max*pi/180).*...
                       (app.CallingApp.locData.theta > app.filteringSettings.theta_min*pi/180).*...
                       (app.CallingApp.locData.theta < app.filteringSettings.theta_max*pi/180).*...
                       (app.CallingApp.locData.avgDoLP > app.filteringSettings.avgDoLP_min).*...
                       (app.CallingApp.locData.avgDoLP < app.filteringSettings.avgDoLP_max).*...
                       (app.CallingApp.locData.netDoLP > app.filteringSettings.netDoLP_min).*...
                       (app.CallingApp.locData.netDoLP < app.filteringSettings.netDoLP_max);
            
            % update further using algorithm-specific parameter
            switch app.CallingApp.localisationAlgorithm
                case 'centroid'                        
                    keepSpecific = (app.CallingApp.locData.photons > app.filteringSettings.photons_min).*...
                                   (app.CallingApp.locData.photons < app.filteringSettings.photons_max);
                case 'symmetric gaussian'
                    keepSpecific = (app.CallingApp.locData.photons > app.filteringSettings.photons_min).*...
                                   (app.CallingApp.locData.photons < app.filteringSettings.photons_max).*...
                                   (app.CallingApp.locData.bkgnd   > app.filteringSettings.bkgnd_min).*...
                                   (app.CallingApp.locData.bkgnd   < app.filteringSettings.bkgnd_max).*...
                                   (app.CallingApp.locData.sigma   > app.filteringSettings.sigmax_min).*...
                                   (app.CallingApp.locData.sigma   < app.filteringSettings.sigmax_max).*...
                                   (app.CallingApp.locData.uncertainty_xy > app.filteringSettings.uncertainty_xy_min).*...
                                   (app.CallingApp.locData.uncertainty_xy < app.filteringSettings.uncertainty_xy_max);
                case 'asymmetric gaussian'
                    keepSpecific = (app.CallingApp.locData.photons > app.filteringSettings.photons_min).*...
                                   (app.CallingApp.locData.photons < app.filteringSettings.photons_max).*...
                                   (app.CallingApp.locData.bkgnd   > app.filteringSettings.bkgnd_min).*...
                                   (app.CallingApp.locData.bkgnd   < app.filteringSettings.bkgnd_max).*...
                                   (app.CallingApp.locData.sigmax  > app.filteringSettings.sigmax_min).*...
                                   (app.CallingApp.locData.sigmax  < app.filteringSettings.sigmax_max).*...
                                   (app.CallingApp.locData.sigmay  > app.filteringSettings.sigmay_min).*...
                                   (app.CallingApp.locData.sigmay  < app.filteringSettings.sigmay_max).*...
                                   (app.CallingApp.locData.uncertainty_xy > app.filteringSettings.uncertainty_xy_min).*...
                                   (app.CallingApp.locData.uncertainty_xy < app.filteringSettings.uncertainty_xy_max);
                case 'rotated asymmetric gaussian'
                    keepSpecific = (app.CallingApp.locData.photons > app.filteringSettings.photons_min).*...
                                   (app.CallingApp.locData.photons < app.filteringSettings.photons_max).*...
                                   (app.CallingApp.locData.bkgnd   > app.filteringSettings.bkgnd_min).*...
                                   (app.CallingApp.locData.bkgnd   < app.filteringSettings.bkgnd_max).*...
                                   (app.CallingApp.locData.sigmax  > app.filteringSettings.sigmax_min).*...
                                   (app.CallingApp.locData.sigmax  < app.filteringSettings.sigmax_max).*...
                                   (app.CallingApp.locData.sigmay  > app.filteringSettings.sigmay_min).*...
                                   (app.CallingApp.locData.sigmay  < app.filteringSettings.sigmay_max).*...
                                   (app.CallingApp.locData.uncertainty_xy > app.filteringSettings.uncertainty_xy_min).*...
                                   (app.CallingApp.locData.uncertainty_xy < app.filteringSettings.uncertainty_xy_max);
                otherwise
                    % do nothing
            end
            
            keepTotal = keepBase.*keepSpecific; % combine

            % if temporal grouping was performed
            if ismember('len',app.CallingApp.locData.Properties.VariableNames)
                keepTemp = (app.CallingApp.locData.len > app.filteringSettings.framesBound_min).*...
                           (app.CallingApp.locData.len < app.filteringSettings.framesBound_max);
                keepTotal = keepTotal.*keepTemp;
            end

            % if clustering was performed
            if ismember('cluster_id',app.CallingApp.locData.Properties.VariableNames)
                keepCluster = (app.CallingApp.locData.cluster_id > app.filteringSettings.clusterId_min).*...
                              (app.CallingApp.locData.cluster_id < app.filteringSettings.clusterId_max);
                keepTotal = keepTotal.*keepCluster;
            end

            keepTotal = logical(keepTotal);
            app.CallingApp.locData.filter = keepTotal;
        end
        
        function [] = deleteLocalisationsExcludedByCurrentFilteringSettings(app)
            %DELETELOCALISATIONSEXCLUDEDBYCURRENTFILTERINGSETTINGS
            app.CallingApp.locData(app.CallingApp.locData.filter == 0,:) = [];
        end
        
        function [] = updateLocalisationNumbersDisplay(app)
            %UPDATELOCALISATIONNUMBERSDISPLAY
            app.NumberoflocalisationsbeforefilteringEditField.Value = height(app.CallingApp.locData);
            app.NumberoflocalisationsafterfilteringEditField.Value = sum(app.CallingApp.locData.filter);
        end
        
        function [] = enableCorrectFields(app)
            %ENABLECORRECTFIELDS
            enableAllFilteringFields(app);
            switch app.CallingApp.localisationAlgorithm
                case 'centroid'
                    set(app.BkgndMinEditField,'Enable','off')
                    set(app.BkgndMaxEditField,'Enable','off')
                    set(app.SigmaxMinEditField,'Enable','off')
                    set(app.SigmaxMaxEditField,'Enable','off')
                    set(app.SigmayMinEditField,'Enable','off')
                    set(app.SigmayMaxEditField,'Enable','off')
                    set(app.UncertaintyxyMinEditField,'Enable','off')
                    set(app.UncertaintyxyMaxEditField,'Enable','off')
                    set(app.UncertaintyxyMaxEditField,'Enable','off')
                case 'symmetric gaussian'
                    set(app.SigmayMinEditField,'Enable','off')
                    set(app.SigmayMaxEditField,'Enable','off')
            end
            
            if ~ismember('len',app.CallingApp.locData.Properties.VariableNames)
                % if temporal grouping was not performed
                set(app.FramesBoundMinEditField,'Enable','off')
                set(app.FramesBoundMaxEditField,'Enable','off')
            end
            
            if ~ismember('cluster_id',app.CallingApp.locData.Properties.VariableNames)
                % if clustering was not performed
                set(app.ClusterIdMinEditField,'Enable','off')
                set(app.ClusterIdMaxEditField,'Enable','off')
            end
        end
        
        function [] = enableAllFilteringFields(app)
            %ENABLEALLFILTERINGFIELDS
            set(app.FrameMinEditField,'Enable','on')
            set(app.FrameMaxEditField,'Enable','on')
            set(app.xMinEditField,'Enable','on')
            set(app.xMaxEditField,'Enable','on')
            set(app.yMinEditField,'Enable','on')
            set(app.yMaxEditField,'Enable','on')
            set(app.PhiMinEditField,'Enable','on')
            set(app.PhiMaxEditField,'Enable','on')
            set(app.ThetaMinEditField,'Enable','on')
            set(app.ThetaMaxEditField,'Enable','on')
            set(app.avgDoLPMinEditField,'Enable','on')
            set(app.avgDoLPMaxEditField,'Enable','on')
            set(app.netDoLPMinEditField,'Enable','on')
            set(app.netDoLPMaxEditField,'Enable','on')
            set(app.ThetaMinEditField,'Enable','on')
            set(app.ThetaMaxEditField,'Enable','on')
            set(app.PhotonsMinEditField,'Enable','on')
            set(app.PhotonsMaxEditField,'Enable','on')
            set(app.BkgndMinEditField,'Enable','on')
            set(app.BkgndMaxEditField,'Enable','on')
            set(app.SigmaxMinEditField,'Enable','on')
            set(app.SigmaxMaxEditField,'Enable','on')
            set(app.SigmayMinEditField,'Enable','on')
            set(app.SigmayMaxEditField,'Enable','on')
            set(app.UncertaintyxyMinEditField,'Enable','on')
            set(app.UncertaintyxyMaxEditField,'Enable','on')
            set(app.FramesBoundMinEditField,'Enable','on')
            set(app.FramesBoundMaxEditField,'Enable','on')
            set(app.ClusterIdMinEditField,'Enable','on')
            set(app.ClusterIdMaxEditField,'Enable','on')
        end

        function [] = updateFilteringSettingsFromInternalDefaults(app)
            %UPDATEFILTERINGSETTINGSFROMINTERNALDEFAULTS
            app.filteringSettings.frame_min = 0;
            app.filteringSettings.frame_max = Inf;
            app.filteringSettings.x_min = 0;
            app.filteringSettings.x_max = Inf;
            app.filteringSettings.y_min = 0;
            app.filteringSettings.y_max = Inf;
            app.filteringSettings.phi_min = -90;
            app.filteringSettings.phi_max = 90;
            app.filteringSettings.theta_min = 0;
            app.filteringSettings.theta_max = 90;
            app.filteringSettings.avgDoLP_min = 0;
            app.filteringSettings.avgDoLP_max = 1;
            app.filteringSettings.netDoLP_min = 0;
            app.filteringSettings.netDoLP_max = 1;
            app.filteringSettings.photons_min = 500;
            app.filteringSettings.photons_max = Inf;
            app.filteringSettings.bkgnd_min = -Inf;
            app.filteringSettings.bkgnd_max = Inf;
            app.filteringSettings.sigmax_min = 0;
            app.filteringSettings.sigmax_max = Inf;
            app.filteringSettings.sigmay_min = 0;
            app.filteringSettings.sigmay_max = Inf;
            app.filteringSettings.uncertainty_xy_min = 0;
            app.filteringSettings.uncertainty_xy_max = Inf;
            app.filteringSettings.framesBound_min = 1;
            app.filteringSettings.framesBound_max = Inf;
            app.filteringSettings.clusterId_min = -Inf;
            app.filteringSettings.clusterId_max = Inf;

            % update the fields to display the new limits
            updateFilteringSettingsFromStruct(app,app.filteringSettings);
        end
        
    end
    
    methods (Access = public)
    
        function [] = updateFilteringSettingsFromStruct(app,myStruct)
            %UPDATEFILTERINGSETTINGSFROMSTRUCT Takes in a structure that
            %contains new filtering settings. Each value in the gui will
            %be updated to match the value in myStruct. The property
            %app.filteringSettings is also updated to match the new
            %values displayed in the gui.
            app.FrameMinEditField.Value = myStruct.frame_min;
            app.FrameMaxEditField.Value = myStruct.frame_max;
            app.xMinEditField.Value = myStruct.x_min;
            app.xMaxEditField.Value = myStruct.x_max;
            app.yMinEditField.Value = myStruct.y_min;
            app.yMaxEditField.Value = myStruct.y_max;
            app.PhiMinEditField.Value = myStruct.phi_min;
            app.PhiMaxEditField.Value = myStruct.phi_max;
            app.ThetaMinEditField.Value = myStruct.theta_min;
            app.ThetaMaxEditField.Value = myStruct.theta_max;
            app.avgDoLPMinEditField.Value = myStruct.avgDoLP_min;
            app.avgDoLPMaxEditField.Value = myStruct.avgDoLP_max;
            app.netDoLPMinEditField.Value = myStruct.netDoLP_min;
            app.netDoLPMaxEditField.Value = myStruct.netDoLP_max;
            app.PhotonsMinEditField.Value = myStruct.photons_min;
            app.PhotonsMaxEditField.Value = myStruct.photons_max;
            app.BkgndMinEditField.Value = myStruct.bkgnd_min;
            app.BkgndMaxEditField.Value = myStruct.bkgnd_max;
            app.SigmaxMinEditField.Value = myStruct.sigmax_min;
            app.SigmaxMaxEditField.Value = myStruct.sigmax_max;
            app.SigmayMinEditField.Value = myStruct.sigmay_min;
            app.SigmayMaxEditField.Value = myStruct.sigmay_max;
            app.UncertaintyxyMinEditField.Value = myStruct.uncertainty_xy_min;
            app.UncertaintyxyMaxEditField.Value = myStruct.uncertainty_xy_max;
            app.FramesBoundMinEditField.Value = myStruct.framesBound_min;
            app.FramesBoundMaxEditField.Value = myStruct.framesBound_max;
            app.ClusterIdMinEditField.Value = myStruct.clusterId_min;
            app.ClusterIdMaxEditField.Value = myStruct.clusterId_max;

            % now update the property app.filteringSettings of this app
            % to match the new values that are now displayed in the gui
            updateFilteringSettingsPropertyFromGui(app);
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        function [] = updateFilteringGuiSettingsFromDefaultSettingsFile(app)
            %UPDATEFILTERINGGUISETTINGSFROMDEFAULTSETTINGSFILE
            try % try to read custom default values from file
                readDefaultSettings(app.CallingApp);
                defaultSettings = app.CallingApp.defaultSettings.filteringSettings;
                defaultSettings = app.replaceStringInfinStructByNumInf(defaultSettings);
                updateFilteringSettingsFromStruct(app,defaultSettings);
            catch
                % if failed, write a new defaultSettings file using
                % hardcoded default values instead
                updateFilteringSettingsFromInternalDefaults(app);
                structToWrite = app.replaceInfinStructByString(app.filteringSettings);
                app.CallingApp.defaultSettings.filteringSettings = structToWrite;
                writeDefaultSettings(app.CallingApp);
                newMessage = sprintf('\nCould not load custom default filtering settings. Using standard default settings instead.\n');
                writeToLog(app.CallingApp,newMessage)

                % read in values from the new file
                readDefaultSettings(app.CallingApp);
                defaultSettings = app.CallingApp.defaultSettings.filteringSettings;
                defaultSettings = app.replaceStringInfinStructByNumInf(defaultSettings);
                updateFilteringSettingsFromStruct(app,defaultSettings);
            end
        end

        function [] = startFiltering_batch(app)
            % STARTFILTERING_BATCH Used by batch processing plugin to
            % trigger a callback.
            SavefilteredlocalisationsButtonPushed(app,true);
        end

    end

    methods (Static)

        function myStruct = replaceInfinStructByString(myStruct)
            fieldNames = fieldnames(myStruct);
            for i=1:numel(fieldNames)
                value = getfield(myStruct,fieldNames{i});
                if isinf(value)
                    if value < 0
                        value = '-Inf';
                    else
                        value = 'Inf';
                    end
                end
                myStruct = setfield(myStruct,fieldNames{i},value);
            end
        end

        function myStruct = replaceStringInfinStructByNumInf(myStruct)
            fieldNames = fieldnames(myStruct);
            for i=1:numel(fieldNames)
                value = getfield(myStruct,fieldNames{i});
                if ~isfloat(value)
                    value = str2num(value);
                end
                myStruct = setfield(myStruct,fieldNames{i},value);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            
            app.paramScatterplot = app.ParameterScatterPlotDropDown.Value;
            app.paramHist1D = app.ParameterHist1DDropDown.Value;
            app.param1Hist2D = app.Parameter1Hist2DDropDown.Value;
            app.param2Hist2D = app.Parameter2Hist2DDropDown.Value;

            guessLocalisationAlgorithm(app.CallingApp);
            enableCorrectFields(app);

            updateFilteringGuiSettingsFromDefaultSettingsFile(app);

        end

        % Value changed function: ParameterScatterPlotDropDown
        function ParameterScatterPlotDropDownValueChanged(app, event)
            app.paramScatterplot = app.ParameterScatterPlotDropDown.Value;
        end

        % Value changed function: ParameterHist1DDropDown
        function ParameterHist1DDropDownValueChanged(app, event)
            app.paramHist1D = app.ParameterHist1DDropDown.Value;
        end

        % Value changed function: Parameter1Hist2DDropDown
        function Parameter1Hist2DDropDownValueChanged(app, event)
            app.param1Hist2D = app.Parameter1Hist2DDropDown.Value;
        end

        % Value changed function: Parameter2Hist2DDropDown
        function Parameter2Hist2DDropDownValueChanged(app, event)
            app.param2Hist2D = app.Parameter2Hist2DDropDown.Value;
        end

        % Button pushed function: PlotscatterplotButton
        function PlotscatterplotButtonPushed(app, event)
            % generate scatter plot
            Plot.FilteringApp.plotScatterPlot(app.CallingApp.locData,app.paramScatterplot,app.CallingApp.figureParams);
        end

        % Button pushed function: Plothistogram1DButton
        function Plothistogram1DButtonPushed(app, event)
            % generate 1D histogram
            Plot.FilteringApp.plot1DHistogram(app.CallingApp.locData,app.paramHist1D,app.CallingApp.figureParams);
        end

        % Button pushed function: Plothistogram2DButton
        function Plothistogram2DButtonPushed(app, event)
            % generate 2D histogram
            Plot.FilteringApp.plot2DHistogram(app.CallingApp.locData,app.param1Hist2D,app.param2Hist2D,app.CallingApp.figureParams);
        end

        % Value changed function: FrameMinEditField
        function FrameMinEditFieldValueChanged(app, event)
            app.filteringSettings.frame_min = app.FrameMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: FrameMaxEditField
        function FrameMaxEditFieldValueChanged(app, event)
            app.filteringSettings.frame_max = app.FrameMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: xMinEditField
        function xMinEditFieldValueChanged(app, event)
            app.filteringSettings.x_min = app.xMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: xMaxEditField
        function xMaxEditFieldValueChanged(app, event)
            app.filteringSettings.x_max = app.xMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: yMinEditField
        function yMinEditFieldValueChanged(app, event)
            app.filteringSettings.y_min = app.yMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: yMaxEditField
        function yMaxEditFieldValueChanged(app, event)
            app.filteringSettings.y_max = app.yMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: PhiMinEditField
        function PhiMinEditFieldValueChanged(app, event)
            app.filteringSettings.phi_min = app.PhiMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: PhiMaxEditField
        function PhiMaxEditFieldValueChanged(app, event)
            app.filteringSettings.phi_max = app.PhiMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: ThetaMinEditField
        function ThetaMinEditFieldValueChanged(app, event)
            app.filteringSettings.theta_min = app.ThetaMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: ThetaMaxEditField
        function ThetaMaxEditFieldValueChanged(app, event)
            app.filteringSettings.theta_max = app.ThetaMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: avgDoLPMinEditField
        function avgDoLPMinEditFieldValueChanged(app, event)
            app.filteringSettings.avgDoLP_min = app.avgDoLPMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: avgDoLPMaxEditField
        function avgDoLPMaxEditFieldValueChanged(app, event)
            app.filteringSettings.avgDoLP_max = app.avgDoLPMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: netDoLPMinEditField
        function netDoLPMinEditFieldValueChanged(app, event)
            app.filteringSettings.netDoLP_min = app.netDoLPMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: netDoLPMaxEditField
        function netDoLPMaxEditFieldValueChanged(app, event)
            app.filteringSettings.netDoLP_max = app.netDoLPMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: PhotonsMinEditField
        function PhotonsMinEditFieldValueChanged(app, event)
            app.filteringSettings.photons_min = app.PhotonsMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: PhotonsMaxEditField
        function PhotonsMaxEditFieldValueChanged(app, event)
            app.filteringSettings.photons_max = app.PhotonsMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: BkgndMinEditField
        function BkgndMinEditFieldValueChanged(app, event)
            app.filteringSettings.bkgnd_min = app.BkgndMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: BkgndMaxEditField
        function BkgndMaxEditFieldValueChanged(app, event)
            app.filteringSettings.bkgnd_max = app.BkgndMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: SigmaxMinEditField
        function SigmaxMinEditFieldValueChanged(app, event)
            app.filteringSettings.sigmax_min = app.SigmaxMinEditField.Value;
            if strcmp(app.CallingApp.localisationAlgorithm,'symmetric gaussian')
                % set sigmay to the same value
                app.filteringSettings.sigmay_min = app.filteringSettings.sigmax_min;
                app.SigmayMinEditField.Value = app.filteringSettings.sigmay_min;
            end
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: SigmaxMaxEditField
        function SigmaxMaxEditFieldValueChanged(app, event)
            app.filteringSettings.sigmax_max = app.SigmaxMaxEditField.Value;
            if strcmp(app.CallingApp.localisationAlgorithm,'symmetric gaussian')
                % set sigmay to the same value
                app.filteringSettings.sigmay_max = app.filteringSettings.sigmax_max;
                app.SigmayMaxEditField.Value = app.filteringSettings.sigmay_max;
            end
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: SigmayMinEditField
        function SigmayMinEditFieldValueChanged(app, event)
            app.filteringSettings.sigmay_min = app.SigmayMinEditField.Value;
            if strcmp(app.CallingApp.localisationAlgorithm,'symmetric gaussian')
                % set sigmay to the same value
                app.filteringSettings.sigmax_min = app.filteringSettings.sigmay_min;
                app.SigmaxMinEditField.Value = app.filteringSettings.sigmax_min;
            end
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: SigmayMaxEditField
        function SigmayMaxEditFieldValueChanged(app, event)
            app.filteringSettings.sigmay_max = app.SigmayMaxEditField.Value;
            if strcmp(app.CallingApp.localisationAlgorithm,'symmetric gaussian')
                % set sigmay to the same value
                app.filteringSettings.sigmax_max = app.filteringSettings.sigmay_max;
                app.SigmaxMaxEditField.Value = app.filteringSettings.sigmax_max;
            end
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: UncertaintyxyMinEditField
        function UncertaintyxyMinEditFieldValueChanged(app, event)
            app.filteringSettings.uncertainty_xy_min = app.UncertaintyxyMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: UncertaintyxyMaxEditField
        function UncertaintyxyMaxEditFieldValueChanged(app, event)
            app.filteringSettings.uncertainty_xy_max = app.UncertaintyxyMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: FramesBoundMinEditField
        function FramesBoundMinEditFieldValueChanged(app, event)
            app.filteringSettings.framesBound_min = app.FramesBoundMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: FramesBoundMaxEditField
        function FramesBoundMaxEditFieldValueChanged(app, event)
            app.filteringSettings.framesBound_max = app.FramesBoundMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: ClusterIdMinEditField
        function ClusterIdMinEditFieldValueChanged(app, event)
            app.filteringSettings.clusterId_min = app.ClusterIdMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: ClusterIdMaxEditField
        function ClusterIdMaxEditFieldValueChanged(app, event)
            app.filteringSettings.clusterId_max = app.ClusterIdMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Button pushed function: ClearallfieldsButton
        function ClearallfieldsButtonPushed(app, event)
            app.filteringSettings.frame_min = 0;
            app.filteringSettings.frame_max = Inf;
            app.filteringSettings.x_min = 0;
            app.filteringSettings.x_max = Inf;
            app.filteringSettings.y_min = 0;
            app.filteringSettings.y_max = Inf;
            app.filteringSettings.phi_min = -90;
            app.filteringSettings.phi_max = 90;
            app.filteringSettings.theta_min = 0;
            app.filteringSettings.theta_max = 90;
            app.filteringSettings.avgDoLP_min = 0;
            app.filteringSettings.avgDoLP_max = 1;
            app.filteringSettings.netDoLP_min = 0;
            app.filteringSettings.netDoLP_max = 1;
            app.filteringSettings.photons_min = -Inf;
            app.filteringSettings.photons_max = Inf;
            app.filteringSettings.bkgnd_min = -Inf;
            app.filteringSettings.bkgnd_max = Inf;
            app.filteringSettings.sigmax_min = 0;
            app.filteringSettings.sigmax_max = Inf;
            app.filteringSettings.sigmay_min = 0;
            app.filteringSettings.sigmay_max = Inf;
            app.filteringSettings.uncertainty_xy_min = 0;
            app.filteringSettings.uncertainty_xy_max = Inf;
            app.filteringSettings.framesBound_min = 1;
            app.filteringSettings.framesBound_max = Inf;
            app.filteringSettings.clusterId_min = -Inf;
            app.filteringSettings.clusterId_max = Inf;

            % update the fields to display the new limits
            updateFilteringSettingsFromStruct(app,app.filteringSettings);
        end

        % Button pushed function: RestoredefaultsettingsButton
        function RestoredefaultsettingsButtonPushed(app, event)
            app.filteringSettings.frame_min = 0;
            app.filteringSettings.frame_max = Inf;
            app.filteringSettings.x_min = 0;
            app.filteringSettings.x_max = Inf;
            app.filteringSettings.y_min = 0;
            app.filteringSettings.y_max = Inf;
            app.filteringSettings.phi_min = -90;
            app.filteringSettings.phi_max = 90;
            app.filteringSettings.theta_min = 0;
            app.filteringSettings.theta_max = 90;
            app.filteringSettings.avgDoLP_min = 0;
            app.filteringSettings.avgDoLP_max = 1;
            app.filteringSettings.netDoLP_min = 0;
            app.filteringSettings.netDoLP_max = 1;
            app.filteringSettings.photons_min = 500;
            app.filteringSettings.photons_max = Inf;
            app.filteringSettings.bkgnd_min = -Inf;
            app.filteringSettings.bkgnd_max = Inf;
            app.filteringSettings.sigmax_min = 0;
            app.filteringSettings.sigmax_max = Inf;
            app.filteringSettings.sigmay_min = 0;
            app.filteringSettings.sigmay_max = Inf;
            app.filteringSettings.uncertainty_xy_min = 0;
            app.filteringSettings.uncertainty_xy_max = Inf;
            app.filteringSettings.framesBound_min = 1;
            app.filteringSettings.framesBound_max = Inf;
            app.filteringSettings.clusterId_min = -Inf;
            app.filteringSettings.clusterId_max = Inf;
            % update the fields to display the new limits
            updateFilteringSettingsFromStruct(app,app.filteringSettings);
        end

        % Button pushed function: SavefilteredlocalisationsButton
        function SavefilteredlocalisationsButtonPushed(app, event)
            if app.CallingApp.folderLocData

                % check if the 'save default settings' checkbox was checked
                if app.MakecurrentsettingsdefaultCheckBox.Value
                    % update the defaultSettings property of the main app
                    app.CallingApp.defaultSettings.filteringSettings = app.filteringSettings;
                    % update the default settings file: (takes app.CallingApp.defaultSettings
                    % and writes it away in '..\docs\default settings folder\defaultSettings.json')
                    writeDefaultSettings(app.CallingApp);
                end

                deleteLocalisationsExcludedByCurrentFilteringSettings(app);

                % summarise and save results
                results.locData = app.CallingApp.locData;
                app.CallingApp.settingsData.filteringSettings = app.filteringSettings;
                results.settings = app.CallingApp.settingsData;
                results.filelist = app.CallingApp.filelist;
                newFileName = strcat(app.CallingApp.filenameLocData,'_filtered');
                app.CallingApp.filenameLocData = newFileName;
                filepathResults = fullfile(app.CallingApp.folderLocData,strcat(newFileName,'.mat'));
                save(filepathResults,'results');
                writeToLog(app.CallingApp,sprintf("\nFiltered localisations were save in:\n'%s'",filepathResults));
                clearErrorLog(app.CallingApp); writeToErrorLog(app.CallingApp,'...');
            else
                writeToErrorLog(app.CallingApp,sprintf("ERROR: Failed to save filtered localisations."));
            end
        end

        % Button pushed function: ImportsettingsButton
        function ImportsettingsButtonPushed(app, event)
            % get path to file from user input
            app.CallingApp.readLastVisitedDirectories;
            [filename,folder] = uigetfile(fullfile(app.CallingApp.lastVisitedDirectories.pathLastImg,'*.json'),'Select settings .json file.');
            if ~filename % user clicked cancel (uigetfile returned 0)
                return
            else
                try
                    % read json file
                    filepath = fullfile(folder,filename);
                    fid = fopen(filepath,'r');
                    A = fscanf(fid,'%c'); % format specifications c for char (not s for string, as that ignores spaces)
                    fclose(fid);
                    % decode json format to struct
                    newSettings = jsondecode(A);
                    newFilteringSettings = newSettings.filteringSettings;
                    newFilteringSettings = app.replaceStringInfinStructByNumInf(newFilteringSettings);
                    % update values displayed in gui, and then updated the
                    % property app.localisationSettings to match the new gui values
                    updateFilteringSettingsFromStruct(app,newFilteringSettings);
                catch
                    newMessage = sprintf('ERROR: Could not find filtering settings in selected file.');
                    writeToErrorLog(app.CallingApp,newMessage);
                end
            end
        end

        % Button pushed function: ExportsettingsButton
        function ExportsettingsButtonPushed(app, event)
            % make sure the values currently displayed in gui are exported
            updateFilteringSettingsPropertyFromGui(app);
            % encode struct as json format
            structToWrite = app.replaceInfinStructByString(app.filteringSettings);
            myStruct.filteringSettings = structToWrite;
            jsonFormat = jsonencode(myStruct,'PrettyPrint',true);
            % ask user to give a directory and filename
            app.CallingApp.readLastVisitedDirectories;
            directory = uigetdir(app.CallingApp.lastVisitedDirectories.pathLastImg,'Select output folder');
            if ~directory % user clicked cancel (uigetdir returned 0)
                return
            else
                filename = 'filteringSettings.json';
                filepath = fullfile(directory,filename);
                % write json file
                fid = fopen(filepath,'w');
                fprintf(fid,'%s',jsonFormat);
                fclose(fid);
            end
            writeToErrorLog(app.CallingApp,'No error.');
        end

        % Button pushed function: ExporttoexistingsettingsfileButton
        function ExporttoexistingsettingsfileButtonPushed(app, event)
            % get path to file from user input
            app.CallingApp.readLastVisitedDirectories;
            [filename,folder] = uigetfile(fullfile(app.CallingApp.lastVisitedDirectories.pathLastImg,'*.json'),'Select settings .json file.');
            if ~filename % user clicked cancel (uigetfile returned 0)
                return
            else
                % try reading the file and adding filtering settings
                try
                    % read json file
                    filepath = fullfile(folder,filename);
                    fid = fopen(filepath,'r');
                    A = fscanf(fid,'%c'); % format specifications c for char (not s for string, as that ignores spaces)
                    fclose(fid);
                    % decode json format to struct
                    newSettings = jsondecode(A);
                    % add (or replace) filtering settings in file
                    structToWrite = app.replaceInfinStructByString(app.filteringSettings);
                    newSettings.filteringSettings = structToWrite;
                catch
                    newMessage = sprintf('ERROR: Could not add filtering settings to selected file.');
                    writeToErrorLog(app.CallingApp,newMessage);
                    return
                end
                
                % write the new file away under the same name
                jsonFormat = jsonencode(newSettings,'PrettyPrint',true);
                % write json file
                fid = fopen(filepath,'w');
                fprintf(fid,'%s',jsonFormat);
                fclose(fid);

                writeToErrorLog(app.CallingApp,'No error.');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web('https://ezrabru.github.io/polCAM/documentation.html')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plot1DhistogramPanel = uipanel(app.UIFigure);
            app.Plot1DhistogramPanel.Title = 'Plot 1D histogram';
            app.ParameterLabel = uilabel(app.Plot1DhistogramPanel);
            app.ParameterLabel.Text = 'Parameter:';
            app.ParameterHist1DDropDown = uidropdown(app.Plot1DhistogramPanel);
            app.ParameterHist1DDropDown.Items = {'Frame', 'x', 'y', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound'};
            app.ParameterHist1DDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterHist1DDropDownValueChanged, true);
            app.ParameterHist1DDropDown.Value = 'Photons';
            app.Plothistogram1DButton = uibutton(app.Plot1DhistogramPanel, 'push');
            app.Plothistogram1DButton.ButtonPushedFcn = createCallbackFcn(app, @Plothistogram1DButtonPushed, true);
            app.Plothistogram1DButton.Text = 'Plot histogram';
            app.Plot2DhistogramPanel = uipanel(app.UIFigure);
            app.Plot2DhistogramPanel.Title = 'Plot 2D histogram';
            app.Parameter1DropDownLabel = uilabel(app.Plot2DhistogramPanel);
            app.Parameter1DropDownLabel.Text = 'Parameter 1:';
            app.Parameter1Hist2DDropDown = uidropdown(app.Plot2DhistogramPanel);
            app.Parameter1Hist2DDropDown.Items = {'Frame', 'x', 'y', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound'};
            app.Parameter1Hist2DDropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter1Hist2DDropDownValueChanged, true);
            app.Parameter1Hist2DDropDown.Value = 'avgDoLP';
            app.Parameter2DropDownLabel = uilabel(app.Plot2DhistogramPanel);
            app.Parameter2DropDownLabel.Text = 'Parameter 2:';
            app.Parameter2Hist2DDropDown = uidropdown(app.Plot2DhistogramPanel);
            app.Parameter2Hist2DDropDown.Items = {'Frame', 'x', 'y', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound'};
            app.Parameter2Hist2DDropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter2Hist2DDropDownValueChanged, true);
            app.Parameter2Hist2DDropDown.Value = 'netDoLP';
            app.Plothistogram2DButton = uibutton(app.Plot2DhistogramPanel, 'push');
            app.Plothistogram2DButton.ButtonPushedFcn = createCallbackFcn(app, @Plothistogram2DButtonPushed, true);
            app.Plothistogram2DButton.Text = 'Plot histogram';
            app.PlotcolourcodedscatterplotPanel = uipanel(app.UIFigure);
            app.PlotcolourcodedscatterplotPanel.Title = 'Plot colour-coded scatter plot';
            app.ParameterDropDown_2Label = uilabel(app.PlotcolourcodedscatterplotPanel);
            app.ParameterDropDown_2Label.Text = 'Parameter:';
            app.ParameterScatterPlotDropDown = uidropdown(app.PlotcolourcodedscatterplotPanel);
            app.ParameterScatterPlotDropDown.Items = {'Frame', 'x', 'y', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound', 'Cluster id'};
            app.ParameterScatterPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterScatterPlotDropDownValueChanged, true);
            app.ParameterScatterPlotDropDown.Value = 'Phi';
            app.PlotscatterplotButton = uibutton(app.PlotcolourcodedscatterplotPanel, 'push');
            app.PlotscatterplotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotscatterplotButtonPushed, true);
            app.PlotscatterplotButton.Text = 'Plot scatter plot';
            app.SavefilteredlocalisationsButton = uibutton(app.UIFigure, 'push');
            app.SavefilteredlocalisationsButton.ButtonPushedFcn = createCallbackFcn(app, @SavefilteredlocalisationsButtonPushed, true);
            app.SavefilteredlocalisationsButton.Text = 'Save filtered localisations';
            app.FilteringparametersPanel = uipanel(app.UIFigure);
            app.FilteringparametersPanel.Title = 'Filtering parameters';
            app.FrameEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.FrameMinEditField.ValueChangedFcn = createCallbackFcn(app, @FrameMinEditFieldValueChanged, true);
            app.xEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.xMinEditField.ValueChangedFcn = createCallbackFcn(app, @xMinEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.yMinEditField.ValueChangedFcn = createCallbackFcn(app, @yMinEditFieldValueChanged, true);
            app.PhiEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.PhiMinEditField.ValueChangedFcn = createCallbackFcn(app, @PhiMinEditFieldValueChanged, true);
            app.PhiMinEditField.Value = -90;
            app.ThetaEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.ThetaMinEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaMinEditFieldValueChanged, true);
            app.avgDoLPEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.avgDoLPEditFieldLabel.Text = 'avgDoLP';
            app.avgDoLPMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.avgDoLPMinEditField.ValueChangedFcn = createCallbackFcn(app, @avgDoLPMinEditFieldValueChanged, true);
            app.netDolpEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.netDolpEditFieldLabel.Text = 'netDolp';
            app.netDoLPMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.netDoLPMinEditField.ValueChangedFcn = createCallbackFcn(app, @netDoLPMinEditFieldValueChanged, true);
            app.PhotonsEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.PhotonsEditFieldLabel.Text = 'Photons';
            app.PhotonsMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.PhotonsMinEditField.ValueChangedFcn = createCallbackFcn(app, @PhotonsMinEditFieldValueChanged, true);
            app.PhotonsMinEditField.Value = 500;
            app.BkgndEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.BkgndEditFieldLabel.Text = 'Bkgnd';
            app.BkgndMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.BkgndMinEditField.ValueChangedFcn = createCallbackFcn(app, @BkgndMinEditFieldValueChanged, true);
            app.BkgndMinEditField.Value = -Inf;
            app.SigmaxEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.SigmaxEditFieldLabel.Text = 'Sigma x';
            app.SigmaxMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.SigmaxMinEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaxMinEditFieldValueChanged, true);
            app.SigmayEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.SigmayEditFieldLabel.Text = 'Sigma y';
            app.SigmayMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.SigmayMinEditField.ValueChangedFcn = createCallbackFcn(app, @SigmayMinEditFieldValueChanged, true);
            app.UncertaintyxyEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.UncertaintyxyEditFieldLabel.Text = 'Uncertainty xy';
            app.UncertaintyxyMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.UncertaintyxyMinEditField.ValueChangedFcn = createCallbackFcn(app, @UncertaintyxyMinEditFieldValueChanged, true);
            app.xMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.xMaxEditField.ValueChangedFcn = createCallbackFcn(app, @xMaxEditFieldValueChanged, true);
            app.xMaxEditField.Value = Inf;
            app.yMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.yMaxEditField.ValueChangedFcn = createCallbackFcn(app, @yMaxEditFieldValueChanged, true);
            app.yMaxEditField.Value = Inf;
            app.PhiMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.PhiMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PhiMaxEditFieldValueChanged, true);
            app.PhiMaxEditField.Value = 90;
            app.FrameMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.FrameMaxEditField.ValueChangedFcn = createCallbackFcn(app, @FrameMaxEditFieldValueChanged, true);
            app.FrameMaxEditField.Value = Inf;
            app.ThetaMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.ThetaMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaMaxEditFieldValueChanged, true);
            app.ThetaMaxEditField.Value = 90;
            app.netDoLPMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.netDoLPMaxEditField.ValueChangedFcn = createCallbackFcn(app, @netDoLPMaxEditFieldValueChanged, true);
            app.netDoLPMaxEditField.Value = 1;
            app.PhotonsMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.PhotonsMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PhotonsMaxEditFieldValueChanged, true);
            app.PhotonsMaxEditField.Value = Inf;
            app.BkgndMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.BkgndMaxEditField.ValueChangedFcn = createCallbackFcn(app, @BkgndMaxEditFieldValueChanged, true);
            app.BkgndMaxEditField.Value = Inf;
            app.SigmaxMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.SigmaxMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaxMaxEditFieldValueChanged, true);
            app.SigmaxMaxEditField.Value = Inf;
            app.SigmayMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.SigmayMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SigmayMaxEditFieldValueChanged, true);
            app.SigmayMaxEditField.Value = Inf;
            app.avgDoLPMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.avgDoLPMaxEditField.ValueChangedFcn = createCallbackFcn(app, @avgDoLPMaxEditFieldValueChanged, true);
            app.avgDoLPMaxEditField.Value = 1;
            app.UncertaintyxyMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.UncertaintyxyMaxEditField.ValueChangedFcn = createCallbackFcn(app, @UncertaintyxyMaxEditFieldValueChanged, true);
            app.UncertaintyxyMaxEditField.Value = 100;
            app.MinLabel = uilabel(app.FilteringparametersPanel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.FilteringparametersPanel);
            app.MaxLabel.Text = 'Max';
            app.FramesBoundMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.FramesBoundMaxEditField.ValueChangedFcn = createCallbackFcn(app, @FramesBoundMaxEditFieldValueChanged, true);
            app.FramesBoundMaxEditField.Value = Inf;
            app.FramesboundLabel = uilabel(app.FilteringparametersPanel);
            app.FramesboundLabel.Text = 'Frames bound';
            app.FramesBoundMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.FramesBoundMinEditField.ValueChangedFcn = createCallbackFcn(app, @FramesBoundMinEditFieldValueChanged, true);
            app.ClusterIdMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.ClusterIdMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterIdMaxEditFieldValueChanged, true);
            app.ClusterIdMaxEditField.Value = Inf;
            app.FramesboundLabel_2 = uilabel(app.FilteringparametersPanel);
            app.FramesboundLabel_2.Text = 'Cluster id';
            app.ClusterIdMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.ClusterIdMinEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterIdMinEditFieldValueChanged, true);
            app.ClusterIdMinEditField.Value = -Inf;
            app.ClearallfieldsButton = uibutton(app.FilteringparametersPanel, 'push');
            app.ClearallfieldsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallfieldsButtonPushed, true);
            app.ClearallfieldsButton.Text = 'Clear all fields';
            app.RestoredefaultsettingsButton = uibutton(app.FilteringparametersPanel, 'push');
            app.RestoredefaultsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultsettingsButtonPushed, true);
            app.RestoredefaultsettingsButton.Text = 'Restore default settings';
            app.ImportsettingsButton = uibutton(app.FilteringparametersPanel, 'push');
            app.ImportsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsettingsButtonPushed, true);
            app.ImportsettingsButton.Text = 'Import settings';
            app.ExportsettingsButton = uibutton(app.FilteringparametersPanel, 'push');
            app.ExportsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsettingsButtonPushed, true);
            app.ExportsettingsButton.Text = 'Export settings';
            app.ExporttoexistingsettingsfileButton = uibutton(app.FilteringparametersPanel, 'push');
            app.ExporttoexistingsettingsfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoexistingsettingsfileButtonPushed, true);
            app.ExporttoexistingsettingsfileButton.Text = 'Export to existing settings file';
            app.NumberoflocalisationsbeforefilteringEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflocalisationsbeforefilteringEditFieldLabel.Text = 'Number of localisations before filtering:';
            app.NumberoflocalisationsbeforefilteringEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflocalisationsbeforefilteringEditField.Editable = 'off';
            app.NumberoflocalisationsafterfilteringEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflocalisationsafterfilteringEditFieldLabel.Text = 'Number of localisations after filtering:';
            app.NumberoflocalisationsafterfilteringEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflocalisationsafterfilteringEditField.Editable = 'off';
            app.MakecurrentsettingsdefaultCheckBox = uicheckbox(app.UIFigure);
            app.MakecurrentsettingsdefaultCheckBox.Text = 'Make current settings default';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppFiltering(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end