classdef DialogAppLocalisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button                         matlab.ui.control.Button
        RestoredefaultsettingsButton   matlab.ui.control.Button
        MakecurrentsettingsdefaultCheckBox  matlab.ui.control.CheckBox
        StartlocalisationButton        matlab.ui.control.Button
        CameracalibrationPanel         matlab.ui.container.Panel
        PixeldependentPanel            matlab.ui.container.Panel
        ButtonLoadGainFile             matlab.ui.control.Button
        ButtonLoadOffsetFile           matlab.ui.control.Button
        GainFilepathEditField          matlab.ui.control.EditField
        GainEditField_2Label           matlab.ui.control.Label
        OffsetFilepathEditField        matlab.ui.control.EditField
        OffsetEditField_2Label         matlab.ui.control.Label
        NotpixeldependentPanel         matlab.ui.container.Panel
        GainEditField                  matlab.ui.control.NumericEditField
        GainLabel                      matlab.ui.control.Label
        OffsetEditField                matlab.ui.control.NumericEditField
        OffsetLabel                    matlab.ui.control.Label
        UsepixeldependentcameraoffsetandgainCheckBox  matlab.ui.control.CheckBox
        LocalisationparametersPanel    matlab.ui.container.Panel
        FittingcandidatesPanel         matlab.ui.container.Panel
        UseparallelprocessingCheckBox  matlab.ui.control.CheckBox
        HalfroiwidththetaEditField     matlab.ui.control.NumericEditField
        HalfroiwidthpixEditField_2Label  matlab.ui.control.Label
        HalfroiwidthphiEditField       matlab.ui.control.NumericEditField
        HalfroiwidthpixLabel           matlab.ui.control.Label
        HalfroiwidthxyEditField        matlab.ui.control.NumericEditField
        HalfroiwidthxypixEditFieldLabel  matlab.ui.control.Label
        FittingalgorithmDropDown       matlab.ui.control.DropDown
        FittingalgorithmLabel          matlab.ui.control.Label
        FindlocalisationcandidatesPanel  matlab.ui.container.Panel
        LivepreviewSwitch              matlab.ui.control.Switch
        LivepreviewLabel               matlab.ui.control.Label
        DoG1smallpixEditField          matlab.ui.control.NumericEditField
        DoG1smallpixEditFieldLabel     matlab.ui.control.Label
        DoG2largepixEditField          matlab.ui.control.NumericEditField
        DoG2largepixEditFieldLabel     matlab.ui.control.Label
        minEditFieldLabel              matlab.ui.control.Label
        minEditField                   matlab.ui.control.NumericEditField
        DoGthresholdSlider             matlab.ui.control.Slider
        maxEditFieldLabel              matlab.ui.control.Label
        maxEditField                   matlab.ui.control.NumericEditField
        DoGthresholdLabel              matlab.ui.control.Label
        ChannelinterpolationPanel      matlab.ui.container.Panel
        InterpolationmethodDropDown    matlab.ui.control.DropDown
        InterpolationmethodLabel       matlab.ui.control.Label
        PolarisermosaicunitDropDown    matlab.ui.control.DropDown
        PolarisermosaicunitLabel       matlab.ui.control.Label
        ImportsettingsButton           matlab.ui.control.Button
        ExportsettingsButton           matlab.ui.control.Button
        SetupparametersPanel           matlab.ui.container.Panel
        RIobjectiveImmMediumEditField  matlab.ui.control.NumericEditField
        RefractiveindexobjectiveimmersionmediumEditFieldLabel  matlab.ui.control.Label
        TotalmagnificationEditField    matlab.ui.control.NumericEditField
        TotalmagnificationEditFieldLabel  matlab.ui.control.Label
        QuantumefficienycameraQEEditField  matlab.ui.control.NumericEditField
        QuantumefficienycameraQELabel  matlab.ui.control.Label
        CamerapixelsizemEditField      matlab.ui.control.NumericEditField
        CamerapixelsizemLabel          matlab.ui.control.Label
        ObjectiveNAEditField           matlab.ui.control.NumericEditField
        ObjectiveNALabel               matlab.ui.control.Label
        BackgroundestimationPanel      matlab.ui.container.Panel
        KernelsizespatialmedianfilterEditField  matlab.ui.control.NumericEditField
        KernelsizespatialmedianfilterLabel  matlab.ui.control.Label
        PreviewButtonBkgndEst          matlab.ui.control.Button
        DropDownBkgndEst               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        CallingApp % Main app object
        
        Cam % instance of the Camera class
        Scope % instance of the Microscope class
        Proc % instance of the Process class

        tr % Tiff object for reading stack
        currentFrame
        baseName

        camOffset % camera offset (double or array)
        camGain % camera gain (double or array)
        camVariance = 1; % camera variance (NOT YET USED)
        camOffsetLoaded = 0; % boolean, check if offset file was loaded
        camGainLoaded = 0; % boolean, check if gain file was loaded
        offsetSize % dimensions of the offset image
        gainSize % dimensions of the gain image
        
        methodBkgndEst % str, method used for background estimation
        bkgndEstimationParams % struct containing bkgnd estimation parameters
        
        NA % objective numerical aperture
        RI % refractive index immersion medium
        QE % camera quantunm efficiency
        camPixSize % physical camera pixel size in um
        magnification % total setup magnification (objective and relays)

        mosaicUnit % str, arrangement of polarisers in top left corner of image
        mosaicUnitArray % array, arrangement of polarisers in top left corner of image
        methodInterpolation % channel interpolation method
        
        DoGSigma1small % small sigma DoG filter for finding localisation candidates
        DoGSigma2large % large sigma DoG filter for finding localisation candidates
        DoGthreshold % DoG filter lower threshold for finding localisation candidates
        methodLocalisation % algorithm used for localisation
        w_xy % half width roi for localisation
        w_phi % half width roi for phi estimation
        w_theta % half width roi for theta estimation
        parallelProcessing % bool, use parallel processing to speed up analysis
        localisationParams % struct containing localisation parameters
        
        localisationSettings % struct containing all the parameters set in the localisation dialog application

        bkgnd % the current calculated background
        
    end
    
    methods (Access = private)
        
        function [] = updateLocalisationSettingsPropertyFromGui(app)
            %UPDATELOCALISATIONSETTINGSPROPERTYFROMGUI Reads all values
            %currently displayed in the gui and updates the property
            %app.localisationSettings accordingly.

            app.localisationSettings = struct;

            app.localisationSettings.Calibration.pixDependent = app.UsepixeldependentcameraoffsetandgainCheckBox.Value;
            if app.localisationSettings.Calibration.pixDependent
                app.localisationSettings.Calibration.offset = app.OffsetFilepathEditField.Value;
                app.localisationSettings.Calibration.gain = app.GainFilepathEditField.Value;
            else
                app.localisationSettings.Calibration.offset = app.OffsetEditField.Value;
                app.localisationSettings.Calibration.gain = app.GainEditField.Value;
            end

            app.localisationSettings.BkgndEstimation.method = app.DropDownBkgndEst.Value;
            app.localisationSettings.BkgndEstimation.kernel = app.KernelsizespatialmedianfilterEditField.Value;

            app.localisationSettings.SetupParams.NA = app.ObjectiveNAEditField.Value;
            app.localisationSettings.SetupParams.RI = app.RIobjectiveImmMediumEditField.Value;
            app.localisationSettings.SetupParams.QE = app.QuantumefficienycameraQEEditField.Value;
            app.localisationSettings.SetupParams.camPixSize_um = app.CamerapixelsizemEditField.Value;
            app.localisationSettings.SetupParams.totalMagnification = app.TotalmagnificationEditField.Value;
            
            app.localisationSettings.ChannelInterp.mosaicUnit = app.PolarisermosaicunitDropDown.Value;
            app.localisationSettings.ChannelInterp.method = app.InterpolationmethodDropDown.Value;

            app.localisationSettings.Localisation.DoGsigma1 = app.DoG1smallpixEditField.Value;
            app.localisationSettings.Localisation.DoGsigma2 = app.DoG2largepixEditField.Value;
            app.localisationSettings.Localisation.DoGthresh = app.DoGthresholdSlider.Value;
            app.localisationSettings.Localisation.fittingAlgorithm = app.FittingalgorithmDropDown.Value;
            app.localisationSettings.Localisation.w_xy = app.HalfroiwidthxyEditField.Value;
            app.localisationSettings.Localisation.w_phi = app.HalfroiwidthphiEditField.Value;
            app.localisationSettings.Localisation.w_theta = app.HalfroiwidththetaEditField.Value;
            app.localisationSettings.Localisation.parallelProc = app.UseparallelprocessingCheckBox.Value;
        end

        function [] = writeLocalisationParamsToSettingsFile(app)
            %WRITELOCALISATIONPARAMSTOSETTINGSFILE Takes the property
            % app.localisationSettings (a struct) and encodes it in json
            % format. The result is written away in the output folder of
            % the data being processed as 'settings.json'.     
            settings = struct;
            settings.localisationSettings = app.localisationSettings;
            jsonFormat = jsonencode(settings,'PrettyPrint',true);
            filepath = fullfile(app.CallingApp.outputDir,strcat(app.baseName,'_settings.json'));
            fid = fopen(filepath,'w');
            fprintf(fid,'%s',jsonFormat);
            fclose(fid);
        end

        function [] = enablePixelDependentCameraCalib(app)
            %ENABLEPIXELDEPENDENTCAMERACALIB
            % enable the pixel-dependent camera calibration panel
            children = get(app.PixeldependentPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
            % dissable the pixel-independent camera calibration panel
            children = get(app.NotpixeldependentPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = dissablePixelDependentCameraCalib(app)
            %DISSABLEPIXELDEPENDENTCAMERACALIB
            % disable the pixel-dependent camera calibration panel
            children = get(app.PixeldependentPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
            % enable the pixel-independent camera calibration panel
            children = get(app.NotpixeldependentPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end
        
        function [] = enableAllPanels(app)
            %ENABLEALLPANELS
            enablePanelCameraCalibration(app);
            enablePanelBkgndEst(app);
            enablePanelSetupParams(app);
            enablePanelChannelInterpolation(app);
            enablePanelLocalisationParams(app);
        end

        function [] = dissableAllPanels(app)
            %DISSABLEALLPANELS
            dissablePanelCameraCalibration(app);
            dissablePanelBkgndEst(app);
            dissablePanelSetupParams(app);
            dissablePanelChannelInterpolation(app);
            dissablePanelLocalisationParams(app);
        end

        function [] = enablePanelCameraCalibration(app)
            %ENABLEPANELCAMERACALIBRATION
            children = get(app.CameracalibrationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelCameraCalibration(app)
            %DISSABLEPANELCAMERACALIBRATION
            children = get(app.CameracalibrationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enablePanelBkgndEst(app)
            %ENABLEPANELBKGNDEST
            children = get(app.BackgroundestimationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelBkgndEst(app)
            %DISSABLEPANELBKGNDEST
            children = get(app.BackgroundestimationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enablePanelSetupParams(app)
            %ENABLEPANELSETUPPARAMS
            children = get(app.SetupparametersPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelSetupParams(app)
            %DISSABLEPANELSSETUPPARAMS
            children = get(app.SetupparametersPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enablePanelChannelInterpolation(app)
            %ENABLEPANELCHANNELINTERPOLATION
            children = get(app.ChannelinterpolationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelChannelInterpolation(app)
            %DISSABLEPANELCHANNELINTERPOLATION
            children = get(app.ChannelinterpolationPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enablePanelLocalisationParams(app)
            %ENABLEPANELLOCALISATIONPARAMS
            children = get(app.LocalisationparametersPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','on')
        end

        function [] = dissablePanelLocalisationParams(app)
            %DISSABLEPANELLOCALISATIONPARAMS
            children = get(app.LocalisationparametersPanel,'Children');
            set(children(isprop(children,'Enable')),'Enable','off')
        end

        function [] = enableLoadSettingsButton(app)
            %ENABLELOADSETTINGSBUTTON
            set(app.ImportsettingsButton,'Enable','on')
        end

        function [] = dissableLoadSettingsButton(app)
            %DISSABLELOADSETTINGSBUTTON
            set(app.ImportsettingsButton,'Enable','off')
        end

        function [] = enableStartLocalisationButton(app)
            %ENABLESTARTLOCALISATIONBUTTON
            set(app.StartlocalisationButton,'Enable','on')
        end

        function [] = dissableStartlocalisationButton(app)
            %DISSABLESTARTLOCALISATIONBUTTON
            set(app.StartlocalisationButton,'Enable','off')
        end

        function [] = enableSaveSettingsButton(app)
            %ENABLESAVESETTINGSBUTTON
            set(app.ExportsettingsButton,'Enable','on')
        end

        function [] = dissableSaveSettingsButton(app)
            %DISSABLESAVESETTINGSBUTTON
            set(app.ExportsettingsButton,'Enable','off')
        end

        function [] = enableMakeCurrentSettingsDefaultButton(app)
            %ENABLEMAKECURRENTSETTINGSDEFAULTBUTTON
            set(app.MakecurrentsettingsdefaultCheckBox,'Enable','on')
        end

        function [] = dissableMakeCurrentSettingsDefaultButton(app)
            %DISSABLEMAKECURRENTSETTINGSDEFAULTBUTTON
            set(app.MakecurrentsettingsdefaultCheckBox,'Enable','off')
        end

        function [] = enableRestoreDefaultSettingsButton(app)
            %ENABLERESTOREDEFAULTSETTINGSBUTTON
            set(app.RestoredefaultsettingsButton,'Enable','on')
        end

        function [] = dissableRestoreDefaultSettingsButton(app)
            %DISSABLERESTOREDEFAULTSETTINGSBUTTON
            set(app.RestoredefaultsettingsButton,'Enable','off')
        end

        function [] = enableKernelSizeBkgndEst(app)
            %ENABLEKERNELSIZEBKGNDEST
            set(app.KernelsizespatialmedianfilterEditField,'Enable','on')
        end

        function [] = dissableKernelSizeBkgndEst(app)
            %DISSABLEKERNELSIZEBKGNDEST
            set(app.KernelsizespatialmedianfilterEditField,'Enable','off')
        end

        function [] = mosaicUnitStringToArray(app)
            %MOSAICUNITSTRINGTOARRAY
            switch app.mosaicUnit
                case '[-45 0; 90 45]'
                    app.mosaicUnitArray(1,1) = -45;
                    app.mosaicUnitArray(1,2) =   0;
                    app.mosaicUnitArray(2,1) =  90;
                    app.mosaicUnitArray(2,2) =  45;
                case '[0 -45; 45 90]'
                    app.mosaicUnitArray(1,1) =   0;
                    app.mosaicUnitArray(1,2) = -45;
                    app.mosaicUnitArray(2,1) =  45;
                    app.mosaicUnitArray(2,2) =  90;
                case '[90 45; -45 0]'
                    app.mosaicUnitArray(1,1) =  90;
                    app.mosaicUnitArray(1,2) =  45;
                    app.mosaicUnitArray(2,1) = -45;
                    app.mosaicUnitArray(2,2) =   0;
                case '[45 90; 0 -45]'
                    app.mosaicUnitArray(1,1) =  45;
                    app.mosaicUnitArray(1,2) =  90;
                    app.mosaicUnitArray(2,1) =   0;
                    app.mosaicUnitArray(2,2) = -45;
                otherwise
                    newMessage = sprintf("Value of app.mosaicUnit not recognised by function mosaicUnitStringToArray in DialogAppLocalisation.mlapp..");
                    writeToLog(app.CallingApp,newMessage);
            end
        end
        
        function [] = bkgndEstLabelToCase(app)
            %BKGNDESTLABELTOCASE
            switch app.bkgndEstimationParams.method
                case 'None'
                    app.bkgndEstimationParams.method = 'none';
                case 'Temporal minimum intensity projection + spatial median filter'
                    app.bkgndEstimationParams.method = 'medfilt mip';
                case 'Temporal average intensity projection + spatial median filter'
                    app.bkgndEstimationParams.method = 'medfilt avg';
                case 'Temporal median intensity projection + spatial median filter'
                    app.bkgndEstimationParams.method = 'medfilt med';
                otherwise
                    newMessage = sprintf("Value of app.bkgndEstimationParams.method not recognised by function bkgndEstLabelToCase in DialogAppLocalisation.mlapp.");
                    writeToLog(app.CallingApp,newMessage);
            end
        end
        
        function [] = checkStatusKernelSizeBkgndEst(app)
            %CHECKSTATUSKERNELSIZEBKGNDEST
            if strcmp(app.bkgndEstimationParams.method,'none')
                dissableKernelSizeBkgndEst(app);
            else
                enableKernelSizeBkgndEst(app);
                app.bkgndEstimationParams.params.k = app.KernelsizespatialmedianfilterEditField.Value;
            end
        end
        
        function [] = methodInterpolationLabelToCase(app)
            %METHODINTERPOLATIONLABELTOCASE
            switch app.methodInterpolation
                case 'Interpolation (linear)'
                    app.methodInterpolation = 'interpolation linear';
                case 'Interpolation (cubic)'
                    app.methodInterpolation = 'interpolation cubic';
                case 'Interpolation (cubic spline, recommended)'
                    app.methodInterpolation = 'interpolation spline';
                case 'Fourier (Tyo et al, Opt Lett, 2009)'
                    app.methodInterpolation = 'fourier';
                otherwise
                    newMessage = sprintf("Value of app.methodInterpolation not recognised by function methodInterpolationLabelToCase in DialogAppLocalisation.mlapp.");
                    writeToLog(app.CallingApp,newMessage);
            end
        end
        
        function [] = methodLocalisationLabelToCase(app)
            %METHODLOCALISATIONLABELTOCASE
            switch app.methodLocalisation
                case 'Centroid (fast)'
                    app.methodLocalisation = 'centroid';
                case 'Symmetric Gaussian'
                    app.methodLocalisation = 'symmetric gaussian';
                case 'Asymmetric Gaussian'
                    app.methodLocalisation = 'asymmetric gaussian';
                case 'Rotated asymmetric Gaussian (recommended)'
                    app.methodLocalisation = 'rotated asymmetric gaussian';
                otherwise
                    newMessage = sprintf("Value of app.methodLocalisation not recognised by function methodLocalisationLabelToCase in DialogAppLocalisation.mlapp.");
                    writeToLog(app.CallingApp,newMessage);
            end
        end
        
        function [] = estimateCurrentBackground(app)
            %ESTIMATECURRENTBACKGROUND
            % create instances of the Camera, Microscope and Process classes
            updateInstancesCamScopeProcClasses(app);
            background = app.Proc.estimateBackground(app.CallingApp.filelist(1).filepath);
            app.bkgnd = background;
        end
        
        function [] = processDataset(app)
            %PROCESSDATASET 
            tic % start timer
            
            % update the instances of Microscope, Camera and Process classes
            updateInstancesCamScopeProcClasses(app);
            ProcObject = app.Proc; % parfor can't load app objects

            % initialise table for localisations
            localisations = table;
            % loop over all files in filelist and process them
            for id_stack = 1:app.CallingApp.numFiles
                
                % write message to log window
                newMessage = sprintf('Processing stack %d/%d\n',id_stack,app.CallingApp.numFiles);
                writeToLog(app.CallingApp,newMessage); pause(0.0001)
                
                % get path to current file
                filepath = app.CallingApp.filelist(id_stack).filepath;
                file_id  = app.CallingApp.filelist(id_stack).id;
                [~,filenameWithouthExtension,~] = fileparts(filepath);
                app.baseName = filenameWithouthExtension;

                % estimate background
                newMessage = sprintf('     Estimating background...');
                writeToLog(app.CallingApp,newMessage); t1 = toc; pause(0.0001)
                bkgnd_i = app.Proc.estimateBackground(filepath); t2 = toc;
                newMessage = sprintf(' done! (%.4f seconds)\n',t2-t1);
                appendToLastLineLog(app.CallingApp,newMessage); pause(0.0001)

                % read stack
                newMessage = sprintf('     Reading stack...........'); t1 = toc;
                writeToLog(app.CallingApp,newMessage); pause(0.0001)
                numFrames = numel(imfinfo(filepath));
                stack = Utils.readTiffStack(filepath); t2 = toc;
                newMessage = sprintf(' done! (%.4f seconds)\n',t2-t1);
                appendToLastLineLog(app.CallingApp,newMessage); pause(0.0001)

                % process all frames
                newMessage = sprintf('     Localising..............'); t1 = toc;
                writeToLog(app.CallingApp,newMessage); pause(0.0001)
                if app.parallelProcessing % use parallel processing
                    parfor idx_frame=1:numFrames % parfor loop = parallel
                        img = double(stack(:,:,idx_frame));
                        locs = ProcObject.processFrame(img,bkgnd_i);
                        if isstruct(locs) % if there were localisations in this frame
                            % add columns with file_id and frame number
                            for i=1:numel([locs.x])
                                locs(i).file_id = file_id;
                                locs(i).frame = idx_frame;
                            end
                            % append localisations from current frame to locData
                            localisations = [localisations; struct2table(locs)];
                        end
                    end
                else  % don't use parallel processing
                    for idx_frame=1:numFrames % for loop = not parallel
                        img = double(stack(:,:,idx_frame));
                        locs = app.Proc.processFrame(img,bkgnd_i);
                        if isstruct(locs) % if there were localisations in this frame
                            % add columns with file_id and frame number
                            for i=1:numel([locs.x])
                                locs(i).file_id = file_id;
                                locs(i).frame = idx_frame;
                            end
                            % append localisations from current frame to locData
                            localisations = [localisations; struct2table(locs)];
                        end
                    end
                end
                t2 = toc; 
                newMessage = sprintf(' done! (%.4f seconds)\n',t2-t1);
                appendToLastLineLog(app.CallingApp,newMessage); pause(0.0001)
            end
            processingTime = toc;
            
            newMessage = sprintf('Formatting/saving results.... ');
            writeToLog(app.CallingApp,newMessage); t1 = toc; pause(0.0001)

            % group frames
            localisations.frameGrouped = localisations.frame;
            unique_file_id = unique(localisations.file_id);
            for i=2:numel(unique_file_id)
                id = find([app.CallingApp.filelist.id] == unique_file_id(i));
                numFrames = length(imfinfo(app.CallingApp.filelist(id).filepath));
                localisations.frameGrouped(localisations.file_id >= unique_file_id(i),:) = ...
                    localisations.frameGrouped(localisations.file_id >= unique_file_id(i),:) + numFrames;
            end
            % move the 'file_id' and 'frame' columns to the front and sort rows by frame number
            localisations = [localisations(:,end-2:end) localisations(:,1:end-3)];
            localisations = sortrows(localisations,'file_id');
            
            % if filter column containing only ones
            localisations.filter = ones(height(localisations),1);

            % save the localisations in the app
            app.CallingApp.locData = localisations;
            fig = showResultLocalisations(app);
            fig.Name = app.baseName;
            
            % summarise and save results
            results.locData = localisations;
            app.CallingApp.settingsData.localisationSettings = app.localisationSettings;
            results.settings = app.CallingApp.settingsData;
            results.processingTimeSec = processingTime;
            results.filelist = app.CallingApp.filelist;
            filepathResults = fullfile(app.CallingApp.outputDir,strcat(app.baseName,'_results.mat'));
            save(filepathResults,'results');
            
            % write away localisations as txt file
            filepathLocData = fullfile(app.CallingApp.outputDir,strcat(app.baseName,'_localisations.txt'));
            writetable(localisations,filepathLocData,'Delimiter',',')
            app.CallingApp.folderLocData = app.CallingApp.outputDir;
            app.CallingApp.filenameLocData = strcat(app.baseName,'_results');

            t2 = toc; 
            newMessage = sprintf(' done! (%.4f seconds)\n',t2-t1);
            appendToLastLineLog(app.CallingApp,newMessage); pause(0.0001)

            % write processing time and location output files to command window
            processingTime_hhmmss = seconds(processingTime);
            processingTime_hhmmss.Format = 'hh:mm:ss';
            newMessage = sprintf('\nTotal processing time: %s (hh:mm:ss)\n',processingTime_hhmmss);
            writeToLog(app.CallingApp,newMessage); pause(0.0001)
            newMessage = sprintf("\nResults saved in: '%s'\n",app.CallingApp.outputDir);
            writeToLog(app.CallingApp,newMessage); pause(0.0001)

        end
        
        function [] = updateLocalisationSettingsFromInternalDefaults(app)
            %UPDATELOCALISATIONSETTINGSFROMINTERNALDEFAULTS
            app.localisationSettings = struct;

            app.localisationSettings.Calibration.pixDependent = 0;
            app.localisationSettings.Calibration.offset = 100;
            app.localisationSettings.Calibration.gain = 0.42;

            app.localisationSettings.BkgndEstimation.method = 'None';
            app.localisationSettings.BkgndEstimation.kernel = 1;

            app.localisationSettings.SetupParams.NA = 1.42;
            app.localisationSettings.SetupParams.RI = 1.518;
            app.localisationSettings.SetupParams.QE = 0.7;
            app.localisationSettings.SetupParams.camPixSize_um = 3.45;
            app.localisationSettings.SetupParams.totalMagnification = 60;
            
            app.localisationSettings.ChannelInterp.mosaicUnit = '[-45 0; 90 45]';
            app.localisationSettings.ChannelInterp.method = 'Interpolation (cubic spline, recommended)';

            app.localisationSettings.Localisation.DoGsigma1 = 2;
            app.localisationSettings.Localisation.DoGsigma2 = 5;
            app.localisationSettings.Localisation.DoGthresh = 5;
            app.localisationSettings.Localisation.fittingAlgorithm = 'Rotated asymmetric Gaussian (recommended)';
            app.localisationSettings.Localisation.w_xy = 7;
            app.localisationSettings.Localisation.w_phi = 3;
            app.localisationSettings.Localisation.w_theta = 7;
            app.localisationSettings.Localisation.parallelProc = 1;

            % update the fields to display the new limits
            updateLocalisationSettingsFromStruct(app,app.localisationSettings);
        end

    end
    
    methods (Access = public)
        
        function [] = updateLocalisationSettingsFromStruct(app,myStruct)
            %UPDATELOCALISATIONSETTINGSFROMSTRUCT Takes in a structure that
            %contains new localisation settings. Each value in the gui will
            %be updated to match the value in myStruct. The property
            %app.localisationSettings is also updated to match the new
            %values displayed in the gui.

            app.UsepixeldependentcameraoffsetandgainCheckBox.Value = myStruct.Calibration.pixDependent;
            if app.UsepixeldependentcameraoffsetandgainCheckBox.Value
                app.OffsetFilepathEditField.Value = myStruct.Calibration.offset;
                app.GainFilepathEditField.Value = myStruct.Calibration.gain;
            else
                app.OffsetEditField.Value = myStruct.Calibration.offset;
                app.GainEditField.Value = myStruct.Calibration.gain;
            end

            app.DropDownBkgndEst.Value = myStruct.BkgndEstimation.method;
            app.KernelsizespatialmedianfilterEditField.Value = myStruct.BkgndEstimation.kernel;

            app.ObjectiveNAEditField.Value = myStruct.SetupParams.NA;
            app.RIobjectiveImmMediumEditField.Value = myStruct.SetupParams.RI;
            app.QuantumefficienycameraQEEditField.Value = myStruct.SetupParams.QE;
            app.CamerapixelsizemEditField.Value = myStruct.SetupParams.camPixSize_um;
            app.TotalmagnificationEditField.Value = myStruct.SetupParams.totalMagnification;
            
            app.PolarisermosaicunitDropDown.Value = myStruct.ChannelInterp.mosaicUnit;
            app.InterpolationmethodDropDown.Value = myStruct.ChannelInterp.method;

            app.DoG1smallpixEditField.Value = myStruct.Localisation.DoGsigma1;
            app.DoG2largepixEditField.Value = myStruct.Localisation.DoGsigma2;
            app.DoGthresholdSlider.Value = myStruct.Localisation.DoGthresh;
            app.FittingalgorithmDropDown.Value = myStruct.Localisation.fittingAlgorithm;
            app.HalfroiwidthxyEditField.Value = myStruct.Localisation.w_xy;
            app.HalfroiwidthphiEditField.Value = myStruct.Localisation.w_phi;
            app.HalfroiwidththetaEditField.Value = myStruct.Localisation.w_theta;
            app.UseparallelprocessingCheckBox.Value = myStruct.Localisation.parallelProc;
        
            % now update the property app.localisationSettings of this app
            % to match the new values that are now displayed in the gui
            updateLocalisationSettingsPropertyFromGui(app);
        end

        function [] = updateLocalisationGuiSettingsFromDefaultSettingsFile(app)
            try % try to read custom default values from file
                readDefaultSettings(app.CallingApp);
                defaultSettings = app.CallingApp.defaultSettings.localisationSettings;
                updateLocalisationSettingsFromStruct(app,defaultSettings);
            catch
                % if failed, write a new defaultSettings file using
                % hardcoded gui default values instead
                updateLocalisationSettingsFromInternalDefaults(app);
                app.CallingApp.defaultSettings.localisationSettings = app.localisationSettings;
                writeDefaultSettings(app.CallingApp);
                newMessage = sprintf('\nCould not load custom default localisation settings. Using standard default settings instead.\n');
                writeToLog(app.CallingApp,newMessage);
                
                % read in values from the new file
                readDefaultSettings(app.CallingApp);
                defaultSettings = app.CallingApp.defaultSettings.localisationSettings;
                updateLocalisationSettingsFromStruct(app,defaultSettings);
            end
        end

        function [] = updateInstancesCamScopeProcClasses(app)
            %UPDATEINSTANCESCAMSCOPEPROCCLASSES
            app.Cam = Camera(app.camPixSize,app.CallingApp.imgSize,app.mosaicUnitArray,...
                         app.camOffset,app.camGain,app.camVariance,app.QE);
            app.Scope = Microscope(app.Cam,app.NA,app.magnification,app.RI);
            app.Proc = Process(app.Scope,app.Cam,app.bkgndEstimationParams,...
                         app.methodInterpolation,app.localisationParams);
        end

        function [] = updatePreviewLocalisations(app)
            %UPDATEPREVIEWLOCALISATIONS
            % update instances of the Camera, Microscope and Process classes
            updateInstancesCamScopeProcClasses(app);

            img = app.CallingApp.DialogImgDataSM.currentImg;
            img = img(1:app.Cam.imgSizeEven(1),1:app.Cam.imgSizeEven(2));
            img = (img - app.bkgnd - app.camOffset)./(app.camGain*app.QE); % convert to photons
            [S0,~,~,~,~] = app.Proc.convertUnprocessed(img);
            [x,y] = app.Proc.find2DlocalisationCandidates(S0);
            % clear plot and replot
            cla(app.CallingApp.DialogImgDataSM.UIAxes)
            imagesc(app.CallingApp.DialogImgDataSM.UIAxes,app.CallingApp.DialogImgDataSM.currentImg);
            hold(app.CallingApp.DialogImgDataSM.UIAxes,"on")
            scatter(app.CallingApp.DialogImgDataSM.UIAxes,y,x,500,'sg','LineWidth',2)
        end
        
        function [] = turnOffPreviewLocalisations(app)
            %TURNOFFPREVIEWLOCALISATIONS
            app.LivepreviewSwitch.Value = 'Off';
            cla(app.CallingApp.DialogImgDataSM.UIAxes)
            imagesc(app.CallingApp.DialogImgDataSM.UIAxes,app.CallingApp.DialogImgDataSM.currentImg);
        end

        function fig = showResultLocalisations(app)
            %SHOWRESULTLOCALISATIONS
            fontsize = app.CallingApp.figureParams.FontSize;
            theme = app.CallingApp.figureParams.Theme;
            markersize = 1;

            fig = figure('Name','Results localisation','Position',[100 100 500 500]);
            x = app.CallingApp.locData.x/app.Scope.virtualPixelSize;
            y = app.CallingApp.locData.y/app.Scope.virtualPixelSize;
            phi = app.CallingApp.locData.phi*180/pi;
            mcol = hsv(180+1); mcol = mcol(ceil(90+phi),:);
            scatter(y,x,markersize,mcol,'.'); colormap(hsv)

            % communal figure settings
            c = colorbar;
            c.Label.String = '\phi (deg.)';
            c.Label.FontSize = fontsize;
            c.TickDirection = 'out';
            c.Ticks = -90:30:90;
            caxis([-90 90])
            xlabel('x (pix)'); ylabel('y (pix)')
            axis equal, axis tight; grid on;
            set(gca,'fontsize',fontsize,'YDir','reverse')
            % set to black background with white grid
            set(gca,'Color','k')
            set(gca,'GridColor','w')
            switch theme
                case 'light'
                    set(gcf,'Color','w')    
                case 'dark'
                    c.Color = 'w';
                    set(gca,'Color','k','XColor','w','YColor','w')
                    set(gcf,'Color','k')    
            end

        end

        function [] = startLocalisation_batch(app)
            % STARTLOCALISATION_BATCH Used by batch processing plugin to
            % trigger a callback.
            StartlocalisationButtonPushed(app,true);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            
            updateLocalisationGuiSettingsFromDefaultSettingsFile(app);

            % disable the pixel-dependent camera calibration panel
            dissablePixelDependentCameraCalib(app);

            % load values from gui
            app.camOffset = app.OffsetEditField.Value;
            app.camGain = app.GainEditField.Value;
            
            app.methodBkgndEst = app.DropDownBkgndEst.Value;
            
            app.NA = app.ObjectiveNAEditField.Value;
            app.RI = app.RIobjectiveImmMediumEditField.Value;
            app.QE = app.QuantumefficienycameraQEEditField.Value;
            app.camPixSize = app.CamerapixelsizemEditField.Value;
            app.magnification = app.TotalmagnificationEditField.Value;
            
            app.mosaicUnit = app.PolarisermosaicunitDropDown.Value;
            mosaicUnitStringToArray(app);
            app.methodInterpolation = app.InterpolationmethodDropDown.Value;
            
            app.DoGSigma1small = app.DoG1smallpixEditField.Value;
            app.DoGSigma2large = app.DoG2largepixEditField.Value;
            app.DoGthreshold = app.DoGthresholdSlider.Value;
            app.methodLocalisation = app.FittingalgorithmDropDown.Value;
            methodLocalisationLabelToCase(app);
            app.w_xy    = app.HalfroiwidthxyEditField.Value;
            app.w_phi   = app.HalfroiwidthphiEditField.Value;
            app.w_theta = app.HalfroiwidththetaEditField.Value;
            app.parallelProcessing = app.UseparallelprocessingCheckBox.Value;
            
            % group all localisation parameters in a struct
            app.localisationParams.DoGsigmaSmall   = app.DoGSigma1small;
            app.localisationParams.DoGsigmaLarge   = app.DoGSigma2large;
            app.localisationParams.DoGminThreshold = app.DoGthreshold;
            app.localisationParams.method = app.methodLocalisation;
            app.localisationParams.w.xy    = app.w_xy;
            app.localisationParams.w.phi   = app.w_phi;
            app.localisationParams.w.theta = app.w_theta;
            
            app.bkgndEstimationParams.method = app.methodBkgndEst;
            bkgndEstLabelToCase(app);
            checkStatusKernelSizeBkgndEst(app);
            app.bkgnd = 0; % initialize bkgnd

            methodInterpolationLabelToCase(app);
            
            updateInstancesCamScopeProcClasses(app);

        end

        % Value changed function: 
        % UsepixeldependentcameraoffsetandgainCheckBox
        function UsepixeldependentcameraoffsetandgainCheckBoxValueChanged(app, event)
            value = app.UsepixeldependentcameraoffsetandgainCheckBox.Value;
            if value % use pixel-dependent camera calibration files
                enablePixelDependentCameraCalib(app);
                dissablePanelBkgndEst(app);
                dissablePanelSetupParams(app);
                dissablePanelChannelInterpolation(app);
                dissablePanelLocalisationParams(app);
                dissableSaveSettingsButton(app);
                % turn live preview localisations off if that was on
                if app.CallingApp.livePreviewStatus
                    turnOffPreviewLocalisations(app);
                end
            else % don't use pixel-dependent camera calibration files
                dissablePixelDependentCameraCalib(app);
                app.camOffset = app.OffsetEditField.Value;
                app.camGain = app.GainEditField.Value;
                enablePanelBkgndEst(app);
                enablePanelSetupParams(app);
                enablePanelChannelInterpolation(app);
                enablePanelLocalisationParams(app);
                checkStatusKernelSizeBkgndEst(app);
                enableSaveSettingsButton(app);
            end
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            app.camOffset = app.OffsetEditField.Value;
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            app.camGain = app.GainEditField.Value;
        end

        % Button pushed function: ButtonLoadOffsetFile
        function ButtonLoadOffsetFilePushed(app, event)
            app.camOffsetLoaded = 0;
            [filenameOffset,folderOffset] = uigetfile('*.tif','Select camera calibration offset file');
            pathOffset = fullfile(folderOffset,filenameOffset);
            try % try loading the offset file
                offset = double(imread(pathOffset));
                app.camOffset = offset(1:app.Cam.imgSizeEven(1),1:app.Cam.imgSizeEven(2));
                app.OffsetFilepathEditField.Value = pathOffset; % display the paths in the text boxes in the gui
                app.camOffsetLoaded = 1;
            catch
                newMessage = sprintf("Could not read the offset file '%s'",pathOffset);
                writeToLog(app.CallingApp,newMessage);
            end
            % check if both offset and gain files have been loaded
            % successfully and dimensions match the images
            if app.camOffsetLoaded*app.camGainLoaded == 1
                newMessage = sprintf('Camera calibration files were loaded successfully.');
                writeToLog(app.CallingApp,newMessage);
                enablePanelBkgndEst(app);
                enablePanelSetupParams(app);
                enablePanelChannelInterpolation(app);
                enablePanelLocalisationParams(app);
                checkStatusKernelSizeBkgndEst(app);
                enableSaveSettingsButton(app);
            end
        end

        % Button pushed function: ButtonLoadGainFile
        function ButtonLoadGainFilePushed(app, event)
                [filenameGain,folderGain] = uigetfile('*.tif','Select camera calibration gain file');
                pathGain = fullfile(folderGain,filenameGain);
                try % try loading the offset file
                    gain = double(imread(pathGain));
                    app.camGain = gain(1:app.Cam.imgSizeEven(1),1:app.Cam.imgSizeEven(2));
                    app.GainFilepathEditField.Value = pathGain; % display the paths in the text boxes in the gui
                    app.camGainLoaded = 1;
                catch
                    newMessage = sprintf("Could not read the offset file '%s'",pathGain);
                    writeToLog(app.CallingApp,newMessage);
                end
                % check if both offset and gain files have been loaded
                % successfully and dimensions match the images
                if app.camOffsetLoaded*app.camGainLoaded == 1
                    newMessage = sprintf('Camera calibration files were loaded successfully.');
                    writeToLog(app.CallingApp,newMessage);
                    enablePanelBkgndEst(app);
                    enablePanelSetupParams(app);
                    enablePanelChannelInterpolation(app);
                    enablePanelLocalisationParams(app);
                    checkStatusKernelSizeBkgndEst(app);
                    enableSaveSettingsButton(app);
                end
        end

        % Value changed function: DropDownBkgndEst
        function DropDownBkgndEstValueChanged(app, event)
            app.methodBkgndEst = app.DropDownBkgndEst.Value;
            app.bkgndEstimationParams.method = app.methodBkgndEst;
            bkgndEstLabelToCase(app);
            checkStatusKernelSizeBkgndEst(app);
        end

        % Button pushed function: PreviewButtonBkgndEst
        function PreviewButtonBkgndEstPushed(app, event)
            
            estimateCurrentBackground(app);
            
            % reopen dialog app if it was closed
            if (isempty(app.CallingApp.DialogImgDataSM) || ~isvalid(app.CallingApp.DialogImgDataSM))
                app.CallingApp.DialogImgDataSM = DialogAppImgDataSingleMolecule(app.CallingApp);
            end

            img = app.CallingApp.DialogImgDataSM.currentImg;
            img = img(1:app.Cam.imgSizeEven(1),1:app.Cam.imgSizeEven(2));
            previewImg = img - app.camOffset;
            previewImgMinBkgnd = img - app.camOffset - app.bkgnd;
            if strcmp(app.bkgndEstimationParams.method,'none')
                previewBkgnd = app.bkgnd*ones(size(previewImg,1),size(previewImg,2));
            else
                previewBkgnd = app.bkgnd;
            end

            UIFigure1 = uifigure('Name','Background','Position',[120 240 400 300]);
            UIAxesBkgnd1 = uiaxes(UIFigure1);
            imagesc(UIAxesBkgnd1,previewBkgnd);
            c = colorbar(UIAxesBkgnd1); c.Label.String = 'Counts (ADU)';
            UIAxesBkgnd1.DataAspectRatio = [1 1 1];
            UIAxesBkgnd1.XLimitMethod = 'tight';
            UIAxesBkgnd1.YLimitMethod = 'tight';
            UIAxesBkgnd1.ZLimitMethod = 'tight';

            UIFigure2 = uifigure('Name','Frame','Position',[140 220 400 300]);
            UIAxesBkgnd2 = uiaxes(UIFigure2);
            imagesc(UIAxesBkgnd2,previewImg);
            c = colorbar(UIAxesBkgnd2); c.Label.String = 'Counts (ADU)';
            UIAxesBkgnd2.DataAspectRatio = [1 1 1];
            UIAxesBkgnd2.XLimitMethod = 'tight';
            UIAxesBkgnd2.YLimitMethod = 'tight';
            UIAxesBkgnd2.ZLimitMethod = 'tight';

            UIFigure3 = uifigure('Name','Frame - background','Position',[160 200 400 300]);
            UIAxesBkgnd3 = uiaxes(UIFigure3);
            imagesc(UIAxesBkgnd3,previewImgMinBkgnd);
            c = colorbar(UIAxesBkgnd3); c.Label.String = 'Counts (ADU)';
            UIAxesBkgnd3.DataAspectRatio = [1 1 1];
            UIAxesBkgnd3.XLimitMethod = 'tight';
            UIAxesBkgnd3.YLimitMethod = 'tight';
            UIAxesBkgnd3.ZLimitMethod = 'tight';
        end

        % Value changed function: ObjectiveNAEditField
        function ObjectiveNAEditFieldValueChanged(app, event)
            app.NA = app.ObjectiveNAEditField.Value;
        end

        % Value changed function: RIobjectiveImmMediumEditField
        function RIobjectiveImmMediumEditFieldValueChanged(app, event)
            app.RI = app.RIobjectiveImmMediumEditField.Value;
        end

        % Value changed function: QuantumefficienycameraQEEditField
        function QuantumefficienycameraQEEditFieldValueChanged(app, event)
            app.QE = app.QuantumefficienycameraQEEditField.Value;
        end

        % Value changed function: CamerapixelsizemEditField
        function CamerapixelsizemEditFieldValueChanged(app, event)
            app.camPixSize = app.CamerapixelsizemEditField.Value;
        end

        % Callback function
        function TotalmagnificationEditFieldValueChanged(app, event)
            app.magnification = app.TotalmagnificationEditField.Value;
        end

        % Value changed function: PolarisermosaicunitDropDown
        function PolarisermosaicunitDropDownValueChanged(app, event)
            app.mosaicUnit = app.PolarisermosaicunitDropDown.Value;
            mosaicUnitStringToArray(app);
        end

        % Value changed function: InterpolationmethodDropDown
        function InterpolationmethodDropDownValueChanged(app, event)
            app.methodInterpolation = app.InterpolationmethodDropDown.Value;
            methodInterpolationLabelToCase(app);
        end

        % Value changed function: DoG1smallpixEditField
        function DoG1smallpixEditFieldValueChanged(app, event)
            app.DoGSigma1small = app.DoG1smallpixEditField.Value;
            app.localisationParams.DoGsigmaSmall = app.DoGSigma1small;
            if app.CallingApp.livePreviewStatus
                % ask DialogAppLocalisation to update localisation
                updatePreviewLocalisations(app);
            end
        end

        % Value changed function: DoG2largepixEditField
        function DoG2largepixEditFieldValueChanged(app, event)
            app.DoGSigma2large = app.DoG2largepixEditField.Value;
            app.localisationParams.DoGsigmaLarge = app.DoGSigma2large;
            if app.CallingApp.livePreviewStatus
                % ask DialogAppLocalisation to update localisation
                updatePreviewLocalisations(app);
            end
        end

        % Value changed function: DoGthresholdSlider
        function DoGthresholdSliderValueChanged(app, event)
            app.DoGthreshold = app.DoGthresholdSlider.Value;
            app.localisationParams.DoGminThreshold = app.DoGthreshold;
            % check if the live preview in DialogAppLocalisation is on
            if app.CallingApp.livePreviewStatus
                % ask DialogAppLocalisation to update localisation
                updatePreviewLocalisations(app);
            end
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            app.DoGthresholdSlider.Limits(1) = app.minEditField.Value;
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
            app.DoGthresholdSlider.Limits(2) = app.maxEditField.Value;
        end

        % Value changed function: FittingalgorithmDropDown
        function FittingalgorithmDropDownValueChanged(app, event)
            app.methodLocalisation = app.FittingalgorithmDropDown.Value;
            methodLocalisationLabelToCase(app);
            app.localisationParams.method = app.methodLocalisation;
        end

        % Value changed function: HalfroiwidthxyEditField
        function HalfroiwidthxyEditFieldValueChanged(app, event)
            app.w_xy = app.HalfroiwidthxyEditField.Value;
            app.localisationParams.w.xy = app.w_xy;
        end

        % Value changed function: HalfroiwidthphiEditField
        function HalfroiwidthphiEditFieldValueChanged(app, event)
            app.w_phi = app.HalfroiwidthphiEditField.Value;
            app.localisationParams.w.phi = app.w_phi;
        end

        % Value changed function: HalfroiwidththetaEditField
        function HalfroiwidththetaEditFieldValueChanged(app, event)
            app.w_theta = app.HalfroiwidththetaEditField.Value;
            app.localisationParams.w.theta = app.w_theta;
        end

        % Value changed function: KernelsizespatialmedianfilterEditField
        function KernelsizespatialmedianfilterEditFieldValueChanged(app, event)
            app.bkgndEstimationParams.params.k = app.KernelsizespatialmedianfilterEditField.Value;
        end

        % Value changed function: UseparallelprocessingCheckBox
        function UseparallelprocessingCheckBoxValueChanged(app, event)
            app.parallelProcessing = app.UseparallelprocessingCheckBox.Value;
        end

        % Value changed function: LivepreviewSwitch
        function LivepreviewSwitchValueChanged(app, event)
            switch app.LivepreviewSwitch.Value
                case 'On' % start live preview
                    % reopen the image data dialog if it was closed by the user
                    if (isempty(app.CallingApp.DialogImgDataSM) || ~isvalid(app.CallingApp.DialogImgDataSM))
                        app.CallingApp.DialogImgDataSM = DialogAppImgDataSingleMolecule(app.CallingApp);
                    end
                    app.CallingApp.livePreviewStatus = 1;
                    updatePreviewLocalisations(app);
                    app.CallingApp.bringWindowToForeground(app.CallingApp.DialogImgDataSM);
                    writeToErrorLog(app.CallingApp,'...');
                case 'Off' % remove any localisations that are drawn on the current frame
                    app.CallingApp.livePreviewStatus = 0;
                    turnOffPreviewLocalisations(app);
            end
        end

        % Button pushed function: StartlocalisationButton
        function StartlocalisationButtonPushed(app, event)
            %STARTLOCALISATIONBUTTONPUSHED
            dissableAllPanels(app); % dissable all panels while processing
            dissableLoadSettingsButton(app);
            dissableSaveSettingsButton(app);
            dissableMakeCurrentSettingsDefaultButton(app);
            dissableRestoreDefaultSettingsButton(app);
            dissableStartlocalisationButton(app);

            % make sure the current values displayed in gui are used
            updateLocalisationSettingsPropertyFromGui(app);
            
            % check if the 'save default settings' checkbox was checked
            if app.MakecurrentsettingsdefaultCheckBox.Value
                % update the defaultSettings property of the main app
                app.CallingApp.defaultSettings.localisationSettings = app.localisationSettings;
                % update the default settings file: (takes app.CallingApp.defaultSettings
                % and writes it away in '..\docs\default settings folder\defaultSettings.json')
                writeDefaultSettings(app.CallingApp);
            end
            
            newMessage = sprintf('\nStarted localisation of stack(s):');
            writeToLog(app.CallingApp,newMessage);
            processDataset(app);

            writeToStatusLog(app.CallingApp,'Status: Images and localisations loaded.');

            %writeLocalisationParamsToSettingsFile(app); % write away parameter file for documentation

            enableAllPanels(app); % re-enable all panels when processing is finished
            enableLoadSettingsButton(app);
            enableSaveSettingsButton(app);
            enableMakeCurrentSettingsDefaultButton(app);
            enableRestoreDefaultSettingsButton(app);
            enableStartLocalisationButton(app);
        end

        % Button pushed function: RestoredefaultsettingsButton
        function RestoredefaultsettingsButtonPushed(app, event)
            updateLocalisationGuiSettingsFromDefaultSettingsFile(app);
        end

        % Button pushed function: ExportsettingsButton
        function ExportsettingsButtonPushed(app, event)
            % make sure the values currently displayed in gui are exported
            updateLocalisationSettingsPropertyFromGui(app);
            % encode struct as json format
            newSettings = {};
            newSettings.localisationSettings = app.localisationSettings;
            jsonFormat = jsonencode(newSettings,'PrettyPrint',true);
            % ask user to give a directory and filename
            app.CallingApp.readLastVisitedDirectories;
            directory = uigetdir(app.CallingApp.lastVisitedDirectories.pathLastImg,'Select output folder');
            if ~directory % user clicked cancel (uigetdir returned 0)
                return
            else
                filename = 'localisationSettings.json';
                filepath = fullfile(directory,filename);
                % write json file
                fid = fopen(filepath,'w');
                fprintf(fid,'%s',jsonFormat);
                fclose(fid);
            end
        end

        % Button pushed function: ImportsettingsButton
        function ImportsettingsButtonPushed(app, event)
            % get path to file from user input
            app.CallingApp.readLastVisitedDirectories;
            [filename,folder] = uigetfile(fullfile(app.CallingApp.lastVisitedDirectories.pathLastImg,'*.json'),'Select localisation settings .json file.');
            if ~filename % user clicked cancel (uigetfile returned 0)
                return
            else
                % read json file
                filepath = fullfile(folder,filename);
                fid = fopen(filepath,'r');
                A = fscanf(fid,'%c'); % format specifications c for char (not s for string, as that ignores spaces)
                fclose(fid);
                % decode json format to struct
                newSettings = jsondecode(A);
                newSettings
                newLocalisationSettings = newSettings.localisationSettings;
                % update values displayed in gui, and then updated the
                % property app.localisationSettings to match the new gui values
                updateLocalisationSettingsFromStruct(app,newLocalisationSettings);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web('https://ezrabru.github.io/polCAM/documentation.html')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackgroundestimationPanel = uipanel(app.UIFigure);
            app.BackgroundestimationPanel.Title = '2. Background estimation';
            app.DropDownBkgndEst = uidropdown(app.BackgroundestimationPanel);
            app.DropDownBkgndEst.Items = {'None', 'Temporal minimum intensity projection + spatial median filter'};
            app.DropDownBkgndEst.ValueChangedFcn = createCallbackFcn(app, @DropDownBkgndEstValueChanged, true);
            app.DropDownBkgndEst.Value = 'None';
            app.PreviewButtonBkgndEst = uibutton(app.BackgroundestimationPanel, 'push');
            app.PreviewButtonBkgndEst.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonBkgndEstPushed, true);
            app.PreviewButtonBkgndEst.Text = 'Preview';
            app.KernelsizespatialmedianfilterLabel = uilabel(app.BackgroundestimationPanel);
            app.KernelsizespatialmedianfilterLabel.Text = 'Kernel size spatial median filter:';
            app.KernelsizespatialmedianfilterEditField = uieditfield(app.BackgroundestimationPanel, 'numeric');
            app.KernelsizespatialmedianfilterEditField.ValueChangedFcn = createCallbackFcn(app, @KernelsizespatialmedianfilterEditFieldValueChanged, true);
            app.KernelsizespatialmedianfilterEditField.Value = 1;
            app.SetupparametersPanel = uipanel(app.UIFigure);
            app.SetupparametersPanel.Title = '3. Setup parameters';
            app.ObjectiveNALabel = uilabel(app.SetupparametersPanel);
            app.ObjectiveNALabel.Text = 'Objective NA:';
            app.ObjectiveNAEditField = uieditfield(app.SetupparametersPanel, 'numeric');
            app.ObjectiveNAEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveNAEditFieldValueChanged, true);
            app.ObjectiveNAEditField.Value = 1.4;
            app.CamerapixelsizemLabel = uilabel(app.SetupparametersPanel);
            app.CamerapixelsizemLabel.Text = 'Camera pixel size (μm):';
            app.CamerapixelsizemEditField = uieditfield(app.SetupparametersPanel, 'numeric');
            app.CamerapixelsizemEditField.ValueChangedFcn = createCallbackFcn(app, @CamerapixelsizemEditFieldValueChanged, true);
            app.CamerapixelsizemEditField.Value = 3.45;
            app.QuantumefficienycameraQELabel = uilabel(app.SetupparametersPanel);
            app.QuantumefficienycameraQELabel.Text = 'Quantum efficieny camera (QE):';
            app.QuantumefficienycameraQEEditField = uieditfield(app.SetupparametersPanel, 'numeric');
            app.QuantumefficienycameraQEEditField.ValueChangedFcn = createCallbackFcn(app, @QuantumefficienycameraQEEditFieldValueChanged, true);
            app.QuantumefficienycameraQEEditField.Value = 0.7;
            app.TotalmagnificationEditFieldLabel = uilabel(app.SetupparametersPanel);
            app.TotalmagnificationEditFieldLabel.Text = 'Total magnification:';
            app.TotalmagnificationEditField = uieditfield(app.SetupparametersPanel, 'numeric');
            app.TotalmagnificationEditField.Value = 60;
            app.RefractiveindexobjectiveimmersionmediumEditFieldLabel = uilabel(app.SetupparametersPanel);
            app.RefractiveindexobjectiveimmersionmediumEditFieldLabel.Text = 'Refractive index objective immersion medium:';
            app.RIobjectiveImmMediumEditField = uieditfield(app.SetupparametersPanel, 'numeric');
            app.RIobjectiveImmMediumEditField.ValueChangedFcn = createCallbackFcn(app, @RIobjectiveImmMediumEditFieldValueChanged, true);
            app.RIobjectiveImmMediumEditField.Value = 1.518;
            app.ExportsettingsButton = uibutton(app.UIFigure, 'push');
            app.ExportsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsettingsButtonPushed, true);
            app.ExportsettingsButton.Text = 'Export settings';
            app.ImportsettingsButton = uibutton(app.UIFigure, 'push');
            app.ImportsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsettingsButtonPushed, true);
            app.ImportsettingsButton.Text = 'Import settings';
            app.ChannelinterpolationPanel = uipanel(app.UIFigure);
            app.ChannelinterpolationPanel.Title = '4. Channel interpolation';
            app.PolarisermosaicunitLabel = uilabel(app.ChannelinterpolationPanel);
            app.PolarisermosaicunitLabel.Text = 'Polariser mosaic unit:';
            app.PolarisermosaicunitDropDown = uidropdown(app.ChannelinterpolationPanel);
            app.PolarisermosaicunitDropDown.Items = {'[-45 0; 90 45]', '[0 -45; 45 90]', '[90 45; -45 0]', '[45 90; 0 -45]'};
            app.PolarisermosaicunitDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarisermosaicunitDropDownValueChanged, true);
            app.PolarisermosaicunitDropDown.Value = '[-45 0; 90 45]';
            app.InterpolationmethodLabel = uilabel(app.ChannelinterpolationPanel);
            app.InterpolationmethodLabel.Text = 'Interpolation method:';
            app.InterpolationmethodDropDown = uidropdown(app.ChannelinterpolationPanel);
            app.InterpolationmethodDropDown.Items = {'Interpolation (cubic spline, recommended)'};
            app.InterpolationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationmethodDropDownValueChanged, true);
            app.InterpolationmethodDropDown.Value = 'Interpolation (cubic spline, recommended)';
            app.LocalisationparametersPanel = uipanel(app.UIFigure);
            app.LocalisationparametersPanel.Title = '5. Localisation parameters';
            app.FindlocalisationcandidatesPanel = uipanel(app.LocalisationparametersPanel);
            app.FindlocalisationcandidatesPanel.Title = '5.1. Find localisation candidates';
            app.DoGthresholdLabel = uilabel(app.FindlocalisationcandidatesPanel);
            app.DoGthresholdLabel.Text = 'DoG threshold';
            app.maxEditField = uieditfield(app.FindlocalisationcandidatesPanel, 'numeric');
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.maxEditField.Value = 10;
            app.maxEditFieldLabel = uilabel(app.FindlocalisationcandidatesPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.DoGthresholdSlider = uislider(app.FindlocalisationcandidatesPanel);
            app.DoGthresholdSlider.Limits = [0 10];
            app.DoGthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @DoGthresholdSliderValueChanged, true);
            app.DoGthresholdSlider.Value = 5;
            app.minEditField = uieditfield(app.FindlocalisationcandidatesPanel, 'numeric');
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditFieldLabel = uilabel(app.FindlocalisationcandidatesPanel);
            app.minEditFieldLabel.Text = 'min';
            app.DoG2largepixEditFieldLabel = uilabel(app.FindlocalisationcandidatesPanel);
            app.DoG2largepixEditFieldLabel.Text = 'DoG σ2, large (pix):';
            app.DoG2largepixEditField = uieditfield(app.FindlocalisationcandidatesPanel, 'numeric');
            app.DoG2largepixEditField.ValueChangedFcn = createCallbackFcn(app, @DoG2largepixEditFieldValueChanged, true);
            app.DoG2largepixEditField.Value = 5;
            app.DoG1smallpixEditFieldLabel = uilabel(app.FindlocalisationcandidatesPanel);
            app.DoG1smallpixEditFieldLabel.Text = 'DoG σ1, small (pix):';
            app.DoG1smallpixEditField = uieditfield(app.FindlocalisationcandidatesPanel, 'numeric');
            app.DoG1smallpixEditField.ValueChangedFcn = createCallbackFcn(app, @DoG1smallpixEditFieldValueChanged, true);
            app.DoG1smallpixEditField.Value = 2;
            app.LivepreviewLabel = uilabel(app.FindlocalisationcandidatesPanel);
            app.LivepreviewLabel.Text = 'Live preview:';
            app.LivepreviewSwitch = uiswitch(app.FindlocalisationcandidatesPanel, 'slider');
            app.LivepreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @LivepreviewSwitchValueChanged, true);
            app.FittingcandidatesPanel = uipanel(app.LocalisationparametersPanel);
            app.FittingcandidatesPanel.Title = '5.2. Fitting candidates';
            app.FittingalgorithmLabel = uilabel(app.FittingcandidatesPanel);
            app.FittingalgorithmLabel.Text = 'Fitting algorithm:';
            app.FittingalgorithmDropDown = uidropdown(app.FittingcandidatesPanel);
            app.FittingalgorithmDropDown.Items = {'Centroid (fast)', 'Symmetric Gaussian', 'Asymmetric Gaussian', 'Rotated asymmetric Gaussian (recommended)'};
            app.FittingalgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @FittingalgorithmDropDownValueChanged, true);
            app.FittingalgorithmDropDown.Value = 'Rotated asymmetric Gaussian (recommended)';
            app.HalfroiwidthxypixEditFieldLabel = uilabel(app.FittingcandidatesPanel);
            app.HalfroiwidthxypixEditFieldLabel.Text = 'Half roi width, xy (pix):';
            app.HalfroiwidthxyEditField = uieditfield(app.FittingcandidatesPanel, 'numeric');
            app.HalfroiwidthxyEditField.ValueChangedFcn = createCallbackFcn(app, @HalfroiwidthxyEditFieldValueChanged, true);
            app.HalfroiwidthxyEditField.Value = 7;
            app.HalfroiwidthpixLabel = uilabel(app.FittingcandidatesPanel);
            app.HalfroiwidthpixLabel.Text = 'Half roi width, φ (pix):';
            app.HalfroiwidthphiEditField = uieditfield(app.FittingcandidatesPanel, 'numeric');
            app.HalfroiwidthphiEditField.ValueChangedFcn = createCallbackFcn(app, @HalfroiwidthphiEditFieldValueChanged, true);
            app.HalfroiwidthphiEditField.Value = 3;
            app.HalfroiwidthpixEditField_2Label = uilabel(app.FittingcandidatesPanel);
            app.HalfroiwidthpixEditField_2Label.Text = 'Half roi width, θ (pix):';
            app.HalfroiwidththetaEditField = uieditfield(app.FittingcandidatesPanel, 'numeric');
            app.HalfroiwidththetaEditField.ValueChangedFcn = createCallbackFcn(app, @HalfroiwidththetaEditFieldValueChanged, true);
            app.HalfroiwidththetaEditField.Value = 7;
            app.UseparallelprocessingCheckBox = uicheckbox(app.FittingcandidatesPanel);
            app.UseparallelprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseparallelprocessingCheckBoxValueChanged, true);
            app.UseparallelprocessingCheckBox.Text = 'Use parallel processing';
            app.UseparallelprocessingCheckBox.Value = true;
            app.CameracalibrationPanel = uipanel(app.UIFigure);
            app.CameracalibrationPanel.Title = '1. Camera calibration';
            app.UsepixeldependentcameraoffsetandgainCheckBox = uicheckbox(app.CameracalibrationPanel);
            app.UsepixeldependentcameraoffsetandgainCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsepixeldependentcameraoffsetandgainCheckBoxValueChanged, true);
            app.UsepixeldependentcameraoffsetandgainCheckBox.Text = 'Use pixel-dependent camera offset and gain';
            app.NotpixeldependentPanel = uipanel(app.CameracalibrationPanel);
            app.NotpixeldependentPanel.Title = 'Not pixel-dependent:';
            app.OffsetLabel = uilabel(app.NotpixeldependentPanel);
            app.OffsetLabel.Text = 'Offset:';
            app.OffsetEditField = uieditfield(app.NotpixeldependentPanel, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Value = 100;
            app.GainLabel = uilabel(app.NotpixeldependentPanel);
            app.GainLabel.Text = 'Gain:';
            app.GainEditField = uieditfield(app.NotpixeldependentPanel, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 0.42;
            app.PixeldependentPanel = uipanel(app.CameracalibrationPanel);
            app.PixeldependentPanel.Title = 'Pixel-dependent:';
            app.OffsetEditField_2Label = uilabel(app.PixeldependentPanel);
            app.OffsetEditField_2Label.Text = 'Offset:';
            app.OffsetFilepathEditField = uieditfield(app.PixeldependentPanel, 'text');
            app.OffsetFilepathEditField.Value = 'Folder/subfolder/offset.tif';
            app.GainEditField_2Label = uilabel(app.PixeldependentPanel);
            app.GainEditField_2Label.Text = 'Gain:';
            app.GainFilepathEditField = uieditfield(app.PixeldependentPanel, 'text');
            app.GainFilepathEditField.Value = 'Folder/subfolder/gain.tif';
            app.ButtonLoadOffsetFile = uibutton(app.PixeldependentPanel, 'push');
            app.ButtonLoadOffsetFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadOffsetFilePushed, true);
            app.ButtonLoadOffsetFile.Text = '...';
            app.ButtonLoadGainFile = uibutton(app.PixeldependentPanel, 'push');
            app.ButtonLoadGainFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadGainFilePushed, true);
            app.ButtonLoadGainFile.Text = '...';
            app.StartlocalisationButton = uibutton(app.UIFigure, 'push');
            app.StartlocalisationButton.ButtonPushedFcn = createCallbackFcn(app, @StartlocalisationButtonPushed, true);
            app.StartlocalisationButton.Text = 'Start localisation';
            app.MakecurrentsettingsdefaultCheckBox = uicheckbox(app.UIFigure);
            app.MakecurrentsettingsdefaultCheckBox.Text = 'Make current settings default';
            app.RestoredefaultsettingsButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultsettingsButtonPushed, true);
            app.RestoredefaultsettingsButton.Text = 'Restore default settings';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppLocalisation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end