classdef DialogAppScatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        SettingsPanel              matlab.ui.container.Panel
        CLimResetButton            matlab.ui.control.Button
        YLimResetButton            matlab.ui.control.Button
        XLimResetButton            matlab.ui.control.Button
        maxLabel                   matlab.ui.control.Label
        minLabel                   matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        CLimitsMaxEditField        matlab.ui.control.NumericEditField
        YLimitsMaxEditField        matlab.ui.control.NumericEditField
        XLimitsMaxEditField        matlab.ui.control.NumericEditField
        CLimitsMinEditField        matlab.ui.control.NumericEditField
        CLimitsLabel               matlab.ui.control.Label
        YLimitsMinEditField        matlab.ui.control.NumericEditField
        YLimitsLabel               matlab.ui.control.Label
        XLimitsMinEditField        matlab.ui.control.NumericEditField
        XLimitsLabel               matlab.ui.control.Label
        WhitebackgroundCheckBox    matlab.ui.control.CheckBox
        AddgridCheckBox            matlab.ui.control.CheckBox
        ThemeSwitch                matlab.ui.control.Switch
        ThemeSwitchLabel           matlab.ui.control.Label
        MarkersizeSpinner          matlab.ui.control.Spinner
        MarkersizeSpinnerLabel     matlab.ui.control.Label
        FontsizeSpinner            matlab.ui.control.Spinner
        FontsizeLabel              matlab.ui.control.Label
        ParameterDropDown          matlab.ui.control.DropDown
        ParameterLabel             matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        ContextMenu                matlab.ui.container.ContextMenu
        OpenfigureinnewwindowMenu  matlab.ui.container.Menu
    end


    properties (Access = private)

        CallingApp % Main app object

    end

    methods (Access = private)

        function [] = plotScatter(app,keepClim)
            %PLOTSCATTER

            locData = app.CallingApp.locData;
            ms = app.MarkersizeSpinner.Value;

            keep = logical(locData.filter);

            x = locData.x; x = x(keep);
            y = locData.y; y = y(keep);

            switch app.ParameterDropDown.Value
                case 'Frame'
                    data = locData.frameGrouped; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'Frame';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end


                case 'x'
                    data = locData.x; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'x (nm)';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'y'
                    data = locData.y; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'y (nm)';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'Phi'
                    data = locData.phi*180/pi; data = data(keep);
                    mcol = hsv(180+1);
                    mcol = mcol(ceil(90+data),:);
                    scatter(app.UIAxes,y,x,ms,mcol,'.');
                    c = colorbar(app.UIAxes); colormap(app.UIAxes,"hsv");
                    c.Label.String = '\phi (deg.)';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,[-90 90]); c.Ticks = -90:30:90; end

                case 'Theta'
                    data = locData.theta*180/pi; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = '\theta (deg.)';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'avgDoLP'
                    data = locData.avgDoLP; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'avgDoLP';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'netDoLP'
                    data = locData.netDoLP; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'netDoLP';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'Photons'
                    data = locData.photons; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'Signal photons';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'Bkgnd'
                    data = locData.bkgnd; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'Background photons';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'Sigma x'
                    data = locData.sigmax; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = '\sigma_x (pix)';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'Sigma y'
                    data = locData.sigmay; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = '\sigma_y (pix)';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end

                case 'Uncertainty xy'
                    data = locData.uncertainty_xy; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'Thompson uncertainty xy (nm)';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end   

                case 'Angle gaussian'
                    data = locData.angleGaus*180/pi; data = data(keep);
                    mcol = hsv(180+1);
                    mcol = mcol(ceil(90+data),:);
                    scatter(app.UIAxes,y,x,ms,mcol,'.');
                    c = colorbar(app.UIAxes); colormap(app.UIAxes,"hsv");
                    c.Label.String = 'Angle rotated asym gaussian (deg.)';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,[-90 90]); c.Ticks = -90:30:90; end

                case 'Frames bound'
                    data = locData.len; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"parula");
                    c = colorbar(app.UIAxes); c.Label.String = 'Number of frames bound';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end  

                case 'Cluster id'
                    data = locData.cluster_id; data = data(keep);
                    scatter(app.UIAxes,y,x,ms,data,'.'); colormap(app.UIAxes,"lines");
                    c = colorbar(app.UIAxes); c.Label.String = 'Cluster id';
                    % set colourmap limits
                    if keepClim; caxis(app.UIAxes,[app.CLimitsMinEditField.Value app.CLimitsMaxEditField.Value])
                    else; caxis(app.UIAxes,'auto'); end  
            end

            % communal figure settings
            c.Label.FontSize = app.FontsizeSpinner.Value;
            c.TickDirection = 'out';
            xlabel(app.UIAxes,'x (nm)'); ylabel(app.UIAxes,'y (nm)')
            set(app.UIAxes,'fontsize',app.FontsizeSpinner.Value,'YDir','reverse')
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';

            % set to black background with white grid
            set(app.UIAxes,'Color','k')
            set(app.UIAxes,'GridColor','w')

            switch app.ThemeSwitch.Value
                case 'Light'
                    set(app.UIAxes,'XColor','k','YColor','k')
                    set(app.UIFigure,'Color','w');
                    c.Color = 'k';
                case 'Dark'
                    c.Color = 'w';
                    set(app.UIAxes,'XColor','w','YColor','w')
                    set(app.UIFigure,'Color','k')
            end

            if app.WhitebackgroundCheckBox.Value
                set(app.UIAxes,'Color','w')
                if app.AddgridCheckBox.Value
                    set(app.UIAxes,'XGrid','on','YGrid','on','GridColor','k')
                else; set(app.UIAxes,'XGrid','off','YGrid','off')
                end
            else
                set(app.UIAxes,'Color','k')
                if app.AddgridCheckBox.Value
                    set(app.UIAxes,'XGrid','on','YGrid','on','GridColor','w')
                else; set(app.UIAxes,'XGrid','off','YGrid','off')
                end
            end

        end

        
        function [] = updateAxesLimitsFields(app)
            app.XLimitsMinEditField.Value = app.UIAxes.XLim(1);
            app.XLimitsMaxEditField.Value = app.UIAxes.XLim(2);
            app.YLimitsMinEditField.Value = app.UIAxes.YLim(1);
            app.YLimitsMaxEditField.Value = app.UIAxes.YLim(2);
            app.CLimitsMinEditField.Value = app.UIAxes.CLim(1);
            app.CLimitsMaxEditField.Value = app.UIAxes.CLim(2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % initialise gui values
            app.FontsizeSpinner.Value = app.CallingApp.figureParams.FontSize;
            switch app.CallingApp.figureParams.Theme
                case 'light'
                    app.ThemeSwitch.Value = 'Light';
                case 'dark'
                    app.ThemeSwitch.Value = 'Dark';
            end

            if isempty(app.CallingApp.locData) % if no localisations are loaded
                writeToErrorLog(app,sprintf("ERROR: No localisations are loaded."));
            else
                plotScatter(app,0);
            end
            
            % Limit fields
            updateAxesLimitsFields(app);

        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            cla(app.UIAxes); % clear plot
            plotScatter(app,0);
            updateAxesLimitsFields(app);
        end

        % Value changed function: FontsizeSpinner
        function FontsizeSpinnerValueChanged(app, event)
            cla(app.UIAxes); % clear plot
            plotScatter(app,1);
        end

        % Value changed function: MarkersizeSpinner
        function MarkersizeSpinnerValueChanged(app, event)
            cla(app.UIAxes); % clear plot
            plotScatter(app,1);
        end

        % Value changed function: ThemeSwitch
        function ThemeSwitchValueChanged(app, event)
            cla(app.UIAxes); % clear plot
            plotScatter(app,1);
        end

        % Value changed function: WhitebackgroundCheckBox
        function WhitebackgroundCheckBoxValueChanged(app, event)
            cla(app.UIAxes); % clear plot
            plotScatter(app,1);
        end

        % Value changed function: AddgridCheckBox
        function AddgridCheckBoxValueChanged(app, event)
            cla(app.UIAxes); % clear plot
            plotScatter(app,1);
        end

        % Callback function
        function pngMenuSelected(app, event)
%             figCopy = figure('visible','off');
%             copyobj(app.UIAxes,figCopy); % get invisible copy of image
%             
%             figName = sprintf('scatter_%s.png',erase(app.ParameterDropDown.Value,' '));
%             figPath = fullfile(app.CallingApp.outputDir,figName);
%             switch app.ThemeSwitch.Value
%                 case 'Light'; exportgraphics(figCopy,figPath,'Resolution',400,'BackgroundColor','none')
%                 case 'Dark';  exportgraphics(figCopy,figPath,'Resolution',400,'BackgroundColor','k')
%             end
%             close(figCopy) % close invisible copy of image
%             writeToLog(app.CallingApp,sprintf("\nFigure saved in: '%s'",figPath));
%             clearErrorLog(app.CallingApp); writeToErrorLog(app.CallingApp,'...');
        end

        % Callback function
        function epsMenuSelected(app, event)
%             figCopy = figure('visible','off');
%             copyobj(app.UIAxes,figCopy); % get invisible copy of image (necessary because can't set Painters mode in a uifigure)
%             
%             figName = sprintf('scatter_%s.eps',erase(app.ParameterDropDown.Value,' '));
%             figPath = fullfile(app.CallingApp.outputDir,figName);
%             set(figCopy,'renderer','Painters') % always go to painters mode before writing vector graphics
%             switch app.ThemeSwitch.Value
%                 case 'Light'; exportgraphics(figCopy,figPath,'BackgroundColor','none')
%                 case 'Dark';  exportgraphics(figCopy,figPath,'BackgroundColor','k')
%             end
%             close(figCopy) % close invisible copy of image
%             writeToLog(app.CallingApp,sprintf("\nFigure saved in: '%s'",figPath));
%             clearErrorLog(app.CallingApp); writeToErrorLog(app.CallingApp,'...');
        end

        % Callback function
        function figMenuSelected(app, event)
%             figCopy = figure('visible','on');
%             copyobj(app.UIAxes,figCopy); % get invisible copy of image
%             
%             figName = sprintf('scatter_%s.fig',erase(app.ParameterDropDown.Value,' '));
%             figPath = fullfile(app.CallingApp.outputDir,figName);
%             saveas(figCopy,figPath)
%             close(figCopy) % close invisible copy of image
%             writeToLog(app.CallingApp,sprintf("\nFigure saved in: '%s'",figPath));
%             clearErrorLog(app.CallingApp); writeToErrorLog(app.CallingApp,'...');
        end

        % Menu selected function: OpenfigureinnewwindowMenu
        function OpenfigureinnewwindowMenuSelected(app, event)
            % Create a temporary figure with axes.
            
            fig = figure;
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            figAxes.Color    = app.UIAxes.Color;
            figAxes.XLabel   = app.UIAxes.XLabel;
            figAxes.YLabel   = app.UIAxes.YLabel;
            figAxes.XColor   = app.UIAxes.XColor;
            figAxes.YColor   = app.UIAxes.YColor;
            figAxes.YDir     = app.UIAxes.YDir;
            figAxes.FontSize = app.UIAxes.FontSize;
            figAxes.CLim     = app.UIAxes.CLim;

            cCopy = findall(app.UIFigure,'type','ColorBar');
            c = colorbar(figAxes);
            figAxes.Colormap = app.UIAxes.Colormap;
            c.Label.String = cCopy.Label.String;
            c.Label.FontSize = app.UIAxes.FontSize;
            c.Color = cCopy.Color;
            c.Ticks = cCopy.Ticks;

            % grid
            figAxes.XGrid = app.UIAxes.XGrid;
            figAxes.YGrid = app.UIAxes.YGrid;
            figAxes.GridColor = app.UIAxes.GridColor;
            
            fig.Color = app.UIFigure.Color;
            
            % replot figure because for some unexplained reason the xlabel
            % and ylabel dissapear in the gui when running this function...
            cla(app.UIAxes); % clear plot
            plotScatter(app,1);
            
        end

        % Value changed function: XLimitsMinEditField
        function XLimitsMinEditFieldValueChanged(app, event)
            app.UIAxes.XLim(1) = app.XLimitsMinEditField.Value;
        end

        % Value changed function: XLimitsMaxEditField
        function XLimitsMaxEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2) = app.XLimitsMaxEditField.Value;
        end

        % Value changed function: YLimitsMinEditField
        function YLimitsMinEditFieldValueChanged(app, event)
            app.UIAxes.YLim(1) = app.YLimitsMinEditField.Value;
        end

        % Value changed function: YLimitsMaxEditField
        function YLimitsMaxEditFieldValueChanged(app, event)
            app.UIAxes.YLim(2) = app.YLimitsMaxEditField.Value;
        end

        % Value changed function: CLimitsMinEditField
        function CLimitsMinEditFieldValueChanged(app, event)
            app.UIAxes.CLim(1) = app.CLimitsMinEditField.Value;
        end

        % Value changed function: CLimitsMaxEditField
        function CLimitsMaxEditFieldValueChanged(app, event)
            app.UIAxes.CLim(2) = app.CLimitsMaxEditField.Value;
        end

        % Button pushed function: XLimResetButton
        function XLimResetButtonPushed(app, event)
            xlim(app.UIAxes,'auto')
            app.XLimitsMinEditField.Value = app.UIAxes.XLim(1);
            app.XLimitsMaxEditField.Value = app.UIAxes.XLim(2);
        end

        % Button pushed function: YLimResetButton
        function YLimResetButtonPushed(app, event)
            ylim(app.UIAxes,'auto')
            app.YLimitsMinEditField.Value = app.UIAxes.YLim(1);
            app.YLimitsMaxEditField.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: CLimResetButton
        function CLimResetButtonPushed(app, event)
            caxis(app.UIAxes,'auto')
            app.CLimitsMinEditField.Value = app.UIAxes.CLim(1);
            app.CLimitsMaxEditField.Value = app.UIAxes.CLim(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.SettingsPanel = uipanel(app.Panel);
            app.SettingsPanel.Title = 'Settings';
            app.ParameterLabel = uilabel(app.SettingsPanel);
            app.ParameterLabel.Text = 'Parameter:';
            app.ParameterDropDown = uidropdown(app.SettingsPanel);
            app.ParameterDropDown.Items = {'Frame', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound', 'Cluster id'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Value = 'Frame';
            app.FontsizeLabel = uilabel(app.SettingsPanel);
            app.FontsizeLabel.Text = 'Fontsize:';
            app.FontsizeSpinner = uispinner(app.SettingsPanel);
            app.FontsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FontsizeSpinnerValueChanged, true);
            app.FontsizeSpinner.Value = 10;
            app.MarkersizeSpinnerLabel = uilabel(app.SettingsPanel);
            app.MarkersizeSpinnerLabel.Text = 'Markersize:';
            app.MarkersizeSpinner = uispinner(app.SettingsPanel);
            app.MarkersizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MarkersizeSpinnerValueChanged, true);
            app.MarkersizeSpinner.Value = 1;
            app.ThemeSwitchLabel = uilabel(app.SettingsPanel);
            app.ThemeSwitchLabel.Text = 'Theme';
            app.ThemeSwitch = uiswitch(app.SettingsPanel, 'slider');
            app.ThemeSwitch.Items = {'Light', 'Dark'};
            app.ThemeSwitch.ValueChangedFcn = createCallbackFcn(app, @ThemeSwitchValueChanged, true);
            app.ThemeSwitch.Value = 'Light';
            app.AddgridCheckBox = uicheckbox(app.SettingsPanel);
            app.AddgridCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddgridCheckBoxValueChanged, true);
            app.AddgridCheckBox.Text = 'Add grid';
            app.AddgridCheckBox.Value = true;
            app.WhitebackgroundCheckBox = uicheckbox(app.SettingsPanel);
            app.WhitebackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhitebackgroundCheckBoxValueChanged, true);
            app.WhitebackgroundCheckBox.Text = 'White background';
            app.XLimitsLabel = uilabel(app.SettingsPanel);
            app.XLimitsLabel.Text = 'X Limits:';
            app.XLimitsMinEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.XLimitsMinEditField.ValueChangedFcn = createCallbackFcn(app, @XLimitsMinEditFieldValueChanged, true);
            app.XLimitsMinEditField.Value = -Inf;
            app.YLimitsLabel = uilabel(app.SettingsPanel);
            app.YLimitsLabel.Text = 'Y Limits:';
            app.YLimitsMinEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.YLimitsMinEditField.ValueChangedFcn = createCallbackFcn(app, @YLimitsMinEditFieldValueChanged, true);
            app.YLimitsMinEditField.Value = -Inf;
            app.CLimitsLabel = uilabel(app.SettingsPanel);
            app.CLimitsLabel.Text = 'C Limits:';
            app.CLimitsMinEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.CLimitsMinEditField.ValueChangedFcn = createCallbackFcn(app, @CLimitsMinEditFieldValueChanged, true);
            app.CLimitsMinEditField.Value = -Inf;
            app.XLimitsMaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.XLimitsMaxEditField.ValueChangedFcn = createCallbackFcn(app, @XLimitsMaxEditFieldValueChanged, true);
            app.XLimitsMaxEditField.Value = Inf;
            app.YLimitsMaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.YLimitsMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YLimitsMaxEditFieldValueChanged, true);
            app.YLimitsMaxEditField.Value = Inf;
            app.CLimitsMaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.CLimitsMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CLimitsMaxEditFieldValueChanged, true);
            app.CLimitsMaxEditField.Value = Inf;
            app.Label_2 = uilabel(app.SettingsPanel);
            app.Label_2.Text = ',';
            app.Label_3 = uilabel(app.SettingsPanel);
            app.Label_3.Text = ',';
            app.Label_4 = uilabel(app.SettingsPanel);
            app.Label_4.Text = ',';
            app.minLabel = uilabel(app.SettingsPanel);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.SettingsPanel);
            app.maxLabel.Text = 'max';
            app.XLimResetButton = uibutton(app.SettingsPanel, 'push');
            app.XLimResetButton.ButtonPushedFcn = createCallbackFcn(app, @XLimResetButtonPushed, true);
            app.XLimResetButton.Text = 'Reset';
            app.YLimResetButton = uibutton(app.SettingsPanel, 'push');
            app.YLimResetButton.ButtonPushedFcn = createCallbackFcn(app, @YLimResetButtonPushed, true);
            app.YLimResetButton.Text = 'Reset';
            app.CLimResetButton = uibutton(app.SettingsPanel, 'push');
            app.CLimResetButton.ButtonPushedFcn = createCallbackFcn(app, @CLimResetButtonPushed, true);
            app.CLimResetButton.Text = 'Reset';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'To save a figure, right-click and select ''Open figure in new window''. This figure can be resized and saved.';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.OpenfigureinnewwindowMenu = uimenu(app.ContextMenu);
            app.OpenfigureinnewwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenfigureinnewwindowMenuSelected, true);
            app.OpenfigureinnewwindowMenu.Text = 'Open figure in new window';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppScatter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end