classdef Plugin_batchProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        StacksTab                   matlab.ui.container.Tab
        Button                      matlab.ui.control.Button
        AddsettingsfiletoselectedrowsButton  matlab.ui.control.Button
        DeleteselectedrowsButton    matlab.ui.control.Button
        ImporttableButton           matlab.ui.control.Button
        ExporttabletoprocesslaterButton  matlab.ui.control.Button
        StartbatchprocessingButton  matlab.ui.control.Button
        AddnewfilestotableButton    matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)

        CallingApp % Main app object
        
        selectedCells % indices of the selected cells

    end

    methods (Access = private)
        
        function [] = initialiseTable(app)
            t = app.UITable;
            t.RowName = 'numbered'; % display row numbers
            t.Data = [{},{},{},{}]; % initialise a row of empty cells
        end
        
        function [] = openStack_batch(app,filepath)
            % save path to file and some parameters in appropriate format
            [~,baseName,~] = fileparts(filepath);
            fileList(1).filepath = filepath;
            fileList(1).baseName = baseName;
            fileList(1).id = 0;
            app.CallingApp.filelist = fileList;
            app.CallingApp.numFiles = 1;
            
            % write message to the log window
            writeHeaderToLog(app.CallingApp); % clear log and rewrite header
            writeToErrorLog(app.CallingApp,sprintf('Selected single stack.'));
            newMessage = sprintf('\n%d stack(s) were found:',app.CallingApp.numFiles);
            writeToLog(app.CallingApp,newMessage);
            for i=1:app.CallingApp.numFiles; writeToLog(app.CallingApp,sprintf('     %s',app.CallingApp.filelist(i).baseName)); end
            
            % make app remember that we will be processing only 1 stack
            app.CallingApp.dataFormat = 'stack';
            % get image dimensions
            app.CallingApp.getImageDimensions;
            % get path to folder to write results to
            [app.CallingApp.outputDir,~,~] = fileparts(app.CallingApp.filelist(1).filepath);
            clearErrorLog(app.CallingApp); writeToErrorLog(app.CallingApp,'...');
            writeToStatusLog(app.CallingApp,'Status: Images loaded.');
        end
        
        function [] = loadSettings_batch(app,filepath)
            %LOADSETTINGS_BATCH Reads in a localisationsettings file as a
            %struct and then fills in all the values in the gui
            %accordingly.

            % read json file containing settings
            fid = fopen(filepath,'r');
            A = fscanf(fid,'%c'); % format specifications c for char (not s for string, as that ignores spaces)
            fclose(fid);

            % decode json format to struct
            localisationParameters = jsondecode(A);

            % replace all values displayed in gui with the values from the
            % settings file
            updateLocalisationSettingsFromStruct(app.CallingApp.DialogLocalisation,localisationParameters);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            
            initialiseTable(app);

        end

        % Button pushed function: AddnewfilestotableButton
        function AddnewfilestotableButtonPushed(app, event)
            % get paths to image data
            [filename,folder] = uigetfile('*.tif','MultiSelect','on');
            if ~folder % user clicked cancel (uigetfile returned 0)
                return
            else
                numNewEntries = numel(filename);
                numRowsTable = height(app.UITable);
                if numRowsTable == 1
                    for i=1:numNewEntries
                        app.UITable.Data(numRowsTable+i-1,1) = {folder}; % tables can't have text, needs to be in a cell
                        app.UITable.Data(numRowsTable+i-1,2) = {filename{i}};
                        app.UITable.Data(numRowsTable+i-1,3) = {' '};
                        app.UITable.Data(numRowsTable+i-1,4) = {' '};
                    end
                else
                    for i=1:numNewEntries
                        app.UITable.Data(numRowsTable+i,1) = {folder}; % tables can't have text, needs to be in a cell
                        app.UITable.Data(numRowsTable+i,2) = {filename{i}};
                        app.UITable.Data(numRowsTable+i,3) = {' '};
                        app.UITable.Data(numRowsTable+i,4) = {' '};
                    end
                end
            end
        end

        % Button pushed function: AddsettingsfiletoselectedrowsButton
        function AddsettingsfiletoselectedrowsButtonPushed(app, event)
            % get path to settings file
            [filename,folder] = uigetfile('*.json');
            if ~folder % user clicked cancel (uigetfile returned 0)
                return
            else
                try                    
                    selectedRows = unique(app.selectedCells(:,1));
                    numSelected = numel(selectedRows);
                    for i=1:numSelected
                        app.UITable.Data(selectedRows(i),3) = {folder};
                        app.UITable.Data(selectedRows(i),4) = {filename};
                    end
                catch
                    % do nothing
                end
            end
        end

        % Button pushed function: DeleteselectedrowsButton
        function DeleteselectedrowsButtonPushed(app, event)
            selectedRows = unique(app.selectedCells(:,1));
            numSelected = numel(selectedRows);
            for i=1:numSelected
                app.UITable.Data(selectedRows(i),:) = [];
            end
        end

        % Button pushed function: ExporttabletoprocesslaterButton
        function ExporttabletoprocesslaterButtonPushed(app, event)
            directory = uigetdir('Select output folder');
            if ~directory % user clicked cancel (uigetdir returned 0)
                return
            else
                data = app.UITable.Data; % get data
                variableNames = app.UITable.ColumnName; % get variable names
                T = table(data(:,1),data(:,2),data(:,3),data(:,4),'VariableNames',variableNames);
                writetable(T,fullfile(directory,'batchProcessingTable.txt'),'Delimiter',',');
            end
        end

        % Button pushed function: ImporttableButton
        function ImporttableButtonPushed(app, event)
            % get path to settings file
            [filename,folder] = uigetfile('*.txt');
            if ~filename % user clicked cancel (uigetfile returned 0)
                return
            else
                app.UITable.Data(:,:) = []; % clear all entries
                % replace all entries by read file
                app.UITable.Data = readtable(fullfile(folder,filename),'Format','%s%s%s%s','VariableNamingRule','preserve');
            end
        end

        % Button pushed function: StartbatchprocessingButton
        function StartbatchprocessingButtonPushed(app, event)
            % get number of rows in table to loop over
            data = app.UITable.Data;
            numRows = height(data);
            % for rows in table
            for i=1:numRows                
                filepath_img = fullfile(data{i,1},data{i,2});
                filepath_settings = fullfile(data{i,3},data{i,4});

                % load image data
                openStack_batch(app,filepath_img);

                % start localisation dialogapp
                openLocalisationWindow(app.CallingApp);
                % load settings from the settings file into the gui and
                % update all related properties
                loadSettings_batch(app,filepath_settings);
                % start processing
                startLocalisation_batch(app.CallingApp.DialogLocalisation);
            end
            newMessage = sprintf('\nBatch processing is completed.');
            writeToLog(app.CallingApp,newMessage);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %UITABLECELLSELECTION Get the indices of the cells in the table
            %that are selected by the user. Returns an nx2 array, where n
            %is the number of cells, the first column is the row index and
            %the second column is the column index.
            app.selectedCells = event.Indices;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web('https://ezrabru.github.io/polCAM/documentation.html');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StacksTab = uitab(app.TabGroup);
            app.StacksTab.Title = 'Stacks';
            app.UITable = uitable(app.StacksTab);
            app.UITable.ColumnName = {'Folder img'; 'Filename img'; 'Folder settings'; 'Filename settings'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddnewfilestotableButton = uibutton(app.StacksTab, 'push');
            app.AddnewfilestotableButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewfilestotableButtonPushed, true);
            app.AddnewfilestotableButton.Text = '+ Add new file(s) to table';
            app.StartbatchprocessingButton = uibutton(app.StacksTab, 'push');
            app.StartbatchprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartbatchprocessingButtonPushed, true);
            app.StartbatchprocessingButton.Text = {'Start batch'; 'processing'};
            app.ExporttabletoprocesslaterButton = uibutton(app.StacksTab, 'push');
            app.ExporttabletoprocesslaterButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttabletoprocesslaterButtonPushed, true);
            app.ExporttabletoprocesslaterButton.Text = {'Export table'; '(to process later)'};
            app.ImporttableButton = uibutton(app.StacksTab, 'push');
            app.ImporttableButton.ButtonPushedFcn = createCallbackFcn(app, @ImporttableButtonPushed, true);
            app.ImporttableButton.Text = 'Import table';
            app.DeleteselectedrowsButton = uibutton(app.StacksTab, 'push');
            app.DeleteselectedrowsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedrowsButtonPushed, true);
            app.DeleteselectedrowsButton.Text = '- Delete selected row(s)';
            app.AddsettingsfiletoselectedrowsButton = uibutton(app.StacksTab, 'push');
            app.AddsettingsfiletoselectedrowsButton.ButtonPushedFcn = createCallbackFcn(app, @AddsettingsfiletoselectedrowsButtonPushed, true);
            app.AddsettingsfiletoselectedrowsButton.Text = '+ Add settings file to selected rows';
            app.Button = uibutton(app.StacksTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plugin_batchProcessing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end